classdef ISETBioCSFGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainView                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        statusPanel                     matlab.ui.container.Panel
        statusEditField                 matlab.ui.control.EditField
        retinalpatchlocationinRightEyeVisualFieldREVFPanel  matlab.ui.container.Panel
        radialeccentricitydegsSliderLabel  matlab.ui.control.Label
        radialeccentricitydegsSlider    matlab.ui.control.Slider
        polareccentricitydegsSlider     matlab.ui.control.Knob
        fieldofviewdegsSliderLabel      matlab.ui.control.Label
        fieldofviewdegsSlider           matlab.ui.control.Slider
        eyeSwitch                       matlab.ui.control.Switch
        retinalmagnificationfactorumdegEditFieldLabel  matlab.ui.control.Label
        retinalmagnificationfactorumdegEditField  matlab.ui.control.NumericEditField
        visualFieldView                 matlab.ui.control.UIAxes
        tasksPanel                      matlab.ui.container.Panel
        computeCSFButton                matlab.ui.control.Button
        exportdataButton                matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        responseclassifierpsychometricfunctionestimationPanel  matlab.ui.container.Panel
        slope_minSpinnerLabel           matlab.ui.control.Label
        slope_minSpinner                matlab.ui.control.Spinner
        slope_maxSpinnerLabel           matlab.ui.control.Label
        slope_maxSpinner                matlab.ui.control.Spinner
        slope_deltaSpinnerLabel         matlab.ui.control.Label
        slope_deltaSpinner              matlab.ui.control.Spinner
        log10C_minSpinnerLabel          matlab.ui.control.Label
        log10C_minSpinner               matlab.ui.control.Spinner
        log10C_maxSpinnerLabel          matlab.ui.control.Label
        log10C_maxSpinner               matlab.ui.control.Spinner
        log10C_deltaSpinnerLabel        matlab.ui.control.Label
        log10C_deltaSpinner             matlab.ui.control.Spinner
        examinedcontrastlevelsSpinnerLabel  matlab.ui.control.Label
        examinedcontrastlevelsSpinner   matlab.ui.control.Spinner
        testtrialsSpinnerLabel          matlab.ui.control.Label
        testtrialsSpinner               matlab.ui.control.Spinner
        traintrialsLabel                matlab.ui.control.Label
        traintrialsSpinner              matlab.ui.control.Spinner
        classifierLabel                 matlab.ui.control.Label
        classifierDropDown              matlab.ui.control.DropDown
        psychometricFunctionView        matlab.ui.control.UIAxes
        conemosaicPanel                 matlab.ui.container.Panel
        eccvaryingmacularpigmentCheckBox  matlab.ui.control.CheckBox
        eccvaryingaperturelightgatheringCheckBox  matlab.ui.control.CheckBox
        eccvaryingapertureblurCheckBox  matlab.ui.control.CheckBox
        eccvaryingmacularpigmentdynamicCheckBox  matlab.ui.control.CheckBox
        coneMosaicViewModeKnob          matlab.ui.control.DiscreteKnob
        domainSwitch                    matlab.ui.control.Switch
        LSpinnerLabel                   matlab.ui.control.Label
        LconeDensitySpinner             matlab.ui.control.Spinner
        MSpinnerLabel                   matlab.ui.control.Label
        MconeDensitySpinner             matlab.ui.control.Spinner
        SSpinnerLabel                   matlab.ui.control.Label
        SconeDensitySpinner             matlab.ui.control.Spinner
        coneratiosLabel                 matlab.ui.control.Label
        regenerateButton                matlab.ui.control.Button
        tritanopicradiusdegSpinnerLabel  matlab.ui.control.Label
        tritanopicradiusdegSpinner      matlab.ui.control.Spinner
        meanOrNoisyInstance             matlab.ui.control.Switch
        eccvaryingoutersegmentlightgatheringCheckBox  matlab.ui.control.CheckBox
        retinalOrVisualFieldView        matlab.ui.control.Switch
        mosaicView                      matlab.ui.control.UIAxes
        opticsPanel                     matlab.ui.container.Panel
        PolanssubjectLabel              matlab.ui.control.Label
        PolanssubjectSpinner            matlab.ui.control.Spinner
        eccvaryingopticalblurgridCheckBox  matlab.ui.control.CheckBox
        xSpinnerLabel                   matlab.ui.control.Label
        xSpinner                        matlab.ui.control.Spinner
        ySpinnerLabel                   matlab.ui.control.Label
        ySpinner                        matlab.ui.control.Spinner
        pupildiametermmLabel            matlab.ui.control.Label
        pupildiametermmSpinner          matlab.ui.control.Spinner
        wavelengthnmSpinnerLabel        matlab.ui.control.Label
        visualizedwavelengthnmSpinner   matlab.ui.control.Spinner
        overlayconesCheckBox            matlab.ui.control.CheckBox
        subtractcentralrefractionCheckBox  matlab.ui.control.CheckBox
        actualVisualizedWavelengthField  matlab.ui.control.EditField
        actualLabel                     matlab.ui.control.Label
        psfView                         matlab.ui.control.UIAxes
        stimulusPanel                   matlab.ui.container.Panel
        cLSpinnerLabel                  matlab.ui.control.Label
        cMSpinner                       matlab.ui.control.Spinner
        cSSpinner                       matlab.ui.control.Spinner
        cLSpinner                       matlab.ui.control.Spinner
        McontLabel                      matlab.ui.control.Label
        ScontLabel                      matlab.ui.control.Label
        w_minSpinner                    matlab.ui.control.Spinner
        w_maxSpinner                    matlab.ui.control.Spinner
        w_stepSpinner                   matlab.ui.control.Spinner
        w_minSpinnerLabel               matlab.ui.control.Label
        w_maxSpinnerLabel               matlab.ui.control.Label
        w_stepSpinnerLabel              matlab.ui.control.Label
        flashmsecSpinnerLabel           matlab.ui.control.Label
        flashmsecSpinner                matlab.ui.control.Spinner
        sizedegsSpinnerLabel            matlab.ui.control.Label
        sizedegsSpinner                 matlab.ui.control.Spinner
        updatestimulusButton            matlab.ui.control.Button
        sfcdegLabel                     matlab.ui.control.Label
        spatialFrequencySpinner         matlab.ui.control.Spinner
        stimulusGenerationStatusReport  matlab.ui.control.EditField
        yPosdegsSpinnerLabel            matlab.ui.control.Label
        yPosdegsSpinner                 matlab.ui.control.Spinner
        xPosdegsSpinner_2Label          matlab.ui.control.Label
        xPosdegsSpinner                 matlab.ui.control.Spinner
        envelopeLabel                   matlab.ui.control.Label
        envelopeDropDown                matlab.ui.control.DropDown
        sizecyclesSpinnerLabel          matlab.ui.control.Label
        sizecyclesSpinner               matlab.ui.control.Spinner
        stimCenteredOnMosaicCheckBox    matlab.ui.control.CheckBox
        resolutionpixelsLabel           matlab.ui.control.Label
        resolutionpixelsSpinner         matlab.ui.control.Spinner
        orientationSpinnerLabel         matlab.ui.control.Label
        orientationSpinner              matlab.ui.control.Spinner
        lumcdm2SpinnerLabel             matlab.ui.control.Label
        lumcdm2Spinner                  matlab.ui.control.Spinner
        stimulusView                    matlab.ui.control.UIAxes
        contrastsensitivityfunctionPanel  matlab.ui.container.Panel
        sfSamplesSpinnerLabel           matlab.ui.control.Label
        sfSamplesSpinner                matlab.ui.control.Spinner
        sfMaxLabel                      matlab.ui.control.Label
        sfMaxSpinner                    matlab.ui.control.Spinner
        sfMinLabel                      matlab.ui.control.Label
        sfMinSpinner                    matlab.ui.control.Spinner
        csfView                         matlab.ui.control.UIAxes
        fixationaleyemovementsPanel     matlab.ui.container.Panel
        eyemovementcomponentsDropDownLabel  matlab.ui.control.Label
        eyemovementcomponentsDropDown   matlab.ui.control.DropDown
        integrationtimemsecLabel        matlab.ui.control.Label
        integrationtimemsecSpinner      matlab.ui.control.Spinner
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        % Constants
        maxEcc = 25.0;                                  % Maximum eccentricity
        
        % User settable simulation parameters (mosaic)
        mosaicEye;                                      % Mosaic eye
        mosaicRadialEccentricityDegs = 0;               % Mosaic radial eccentricity in degrees
        mosaicPolarEccentricityDegs = 0;                % Mosaic polar eccentricity in degrees
        mosaicSizeDegs = 0.1;                           % Mosaic size in degrees
        mosaicIntegrationTimeMseconds = 0;              % Mosaic integration time in milliseconds
        mosaicDomain = 'deg';                           % Mosaic domain ('deg' or 'um');
        mosaicConeDensities;                            % Mosaic LMS cone densities
        mosaicTritanopicRadiusDegs;                     % Mosaic S-cone free radius
        mosaicEccVaryingConeAperture;                   % Mosaic ecc-varying aperture light gathering
        mosaicEccVaryingOuterSegmentLength;             % Mosaic ecc-varying outer segment light gathering
        mosaicEccVaryingConeBlur;                       % Mosaic ecc-varying aperture blur       
        mosaicEccVaryingMacularPigmentDensity;          % Mosaic ecc-varying macular pigment density
                
        % User settable simulation parameters (optics)
        opticsPolansSubjectID;                          % Subject ID (Polans database)
        opticsPupilDiameterMM;                          % Pupil diameter in mm
        opticsPolansSubtractCentralRefraction;          % Flag indicating whether to subtract the central refraction
        opticsVisualizedWavelength ;                    % Wavelength at which we visualize the PSF
 
        % User settable stimulus parameters
        wavelengthMinValue;                             % The minimum wavelength
        wavelengthMaxValue;                             % The maximum wavelength
        wavelengthStepSize;                             % The wavelength step size
        
        stimLcontrast;                                  % The stimulus S-cone contrast
        stimMcontrast;                                  % The stimulus M-cone contrast
        stimScontrast;                                  % The stimulus S-cone contrast
        
        stimMeanLuminanceCdPerM2;                       % The stimulus mean luminance
        stimDurationMsec;                               % The stimulus duration in msec
        stimSizeDegs;                                   % The stimulus size in degrees
        stimSpatialFrequencyCyclesPerDeg;               % The stimulus spatial frequency in c/deg
        stimOrientationDegs;                            % The stimulus orientation in degrees
        stimSpatialPhaseDegs = 0;
        stimMinPixelsNumPerCycle = 15;
        stimResolutionPixelsNum;                        % Stimulus pixels
        stimSpatialEnvelope;                            % Stimulus spatial envelope
        stimSpatialEnvelopeRadiusDegs;
        stimIsMosaicCentered                            % Stim position is mosaic-centered
        stimXposDegs                                    % Stim x-pos in degrees
        stimYposDegs                                    % Stim y-pos in degrees
            
        % User settable psychometric estimation parameter
        psychometricFunctionMinSlope;                   % The min slope of the psychometric function
        psychometricFunctionMaxSlope;                   % The min slope of the psychometric function
        psychometricFunctionMinLogThreshold;            % The min log10(threshold) of the psychometric function
        psychometricFunctionMaxLogThreshold;            % The max log10(threshold) of the psychometric function
        psychometricFunctionDeltaSlope;
        psychometricFunctionDeltaLogThreshold;
        psychometricFunctionPerformanceTrialsNum;       % How many trials to use for testing the classifier performance
        psychometricFunctionContrastLevelsNum;          % How many contrast levels to test
       
            
        % User settable CSF spatial frequency support
        csfMinSpatialFrequency;                         % The min spatial frequency examined
        csfMaxSpatialFrequency;                         % The max spatial frequency examined
        csfSamplesSpatialFrequency;                     % The number of spatial frequency samples examined
            
        % Switches
        mosaicDisplayMode;                              % Whether to display cone types or cone activations
        mosaicVisualizationView;                        % Whether to display the mosaic in REFV or retinal coordinates
        visualizedResponseType                          % Whether to display mean responses or instances
        overlayConesOnPSF;                              % Whether to overlay cones on the PSF
        
        % Computed properties
        mosaicEccentricityDegs;                         % Eccentricity of the cone mosaic, computed from radial&polar eccentricity
        demoStimulusScene;                              % Demo stimulus scene
        nullStimulusScene;                              % Null stimulus scene
        demoMosaicActivation;                           % Mosaic activation to the demo stimulus scene
        demoNoisyMosaicActivations;                     % Noisy mosaic activations to the demo stimulus scene
        csfColorMap;                                    % Colormap for different spatial frequency CSF data
        
        % Struct with all simulation components
        components = struct();                          % Struct containing simulation components, eg. coneMosaic
        
        % Flags indicating whether we need to recompute certain components
        % before conducting any response computations
        recomputeOptics = false;
        opticalImageIsStale = true;
        
        % Plot handles
        mosaicOnVisualFieldPlotHandle;                  % The mosaic on visual field plot handle
        odOnVisualFieldPlotHandle;                      % The od on visual field plot handle
        
        % Current state of zoom-in in the mosaicView
        visualizedMosaicViewXLimDegs;
        visualizedMosaicViewYLimDegs;
        visualizedMosaicViewXLimMicrons;
        visualizedMosaicViewYLimMicrons;
        
        
        % Current state of zoom-in in the psfView
        visualizedPSFViewLimsArcMin;
        
        % Last mean activation
        lastMeanActivation = [];
        
        % Export info
        exportDirectoryName;                            % Where to export data
        exportedDataSelection;                          % What data to export
        
        % Export structs
        exportedStimData;                               % Struct with stimulus info
        exportedCSFData;                                % Struct with computed CSF data
        exportedOpticsParams;                           % Struct with employed optics params
        exportedPipelineData;                           % Struct with employed pipeline
        
        % Progress bar showing
        showsProgressBar;                               % Boolean controlling whether we will show progress bar.
    end
    
     
    methods (Access = private)
        
        % Method called from the startup method as soon as the GUI is
        % presented to initialize all simulation params
        function initializeSimulationParams(app) 
            
            app.statusEditField.Value = 'Initializing simulation components';
            
            % Initialize visual field params
            app.decodeRadialEccentricityDegsSlider(app.radialeccentricitydegsSlider.Value); 
            app.decodePolarEccentricityDegsSlider(app.polareccentricitydegsSlider.Value);          
            app.decodeSizeDegsSlider(app.fieldofviewdegsSlider.Value);
            
            % Initialize mosaic params
            app.mosaicEye = app.eyeSwitch.Value;
            app.mosaicIntegrationTimeMseconds = app.integrationtimemsecSpinner.Value;
            app.mosaicConeDensities = [app.LconeDensitySpinner.Value app.MconeDensitySpinner.Value app.SconeDensitySpinner.Value];
            app.mosaicTritanopicRadiusDegs = app.tritanopicradiusdegSpinner.Value;
            app.mosaicEccVaryingConeAperture = app.eccvaryingaperturelightgatheringCheckBox.Value;
            app.mosaicEccVaryingMacularPigmentDensity = app.eccvaryingmacularpigmentCheckBox.Value;
            app.mosaicEccVaryingConeBlur = app.eccvaryingapertureblurCheckBox.Value;
            app.mosaicEccVaryingOuterSegmentLength = app.eccvaryingoutersegmentlightgatheringCheckBox.Value;
            
            
            % Initialize optics params
            app.opticsPolansSubjectID = app.PolanssubjectSpinner.Value;
            app.opticsPolansSubtractCentralRefraction = app.subtractcentralrefractionCheckBox.Value;
            app.opticsPupilDiameterMM = app.pupildiametermmSpinner.Value;
            
            
            % Initialize stimulus params
            app.wavelengthMinValue = app.w_minSpinner.Value;                             
            app.wavelengthMaxValue = app.w_maxSpinner.Value;                        
            app.wavelengthStepSize = app.w_stepSpinner.Value; 
            
            app.stimLcontrast = app.cLSpinner.Value;
            app.stimMcontrast = app.cMSpinner.Value;
            app.stimScontrast = app.cSSpinner.Value;
            
            app.stimMeanLuminanceCdPerM2 = app.lumcdm2Spinner.Value;
            app.stimDurationMsec = app.flashmsecSpinner.Value;
            app.stimSizeDegs  = app.sizedegsSpinner.Value;
            app.stimSpatialFrequencyCyclesPerDeg = app.spatialFrequencySpinner.Value;
            app.stimOrientationDegs = app.orientationSpinner.Value;
            app.stimSpatialEnvelope = app.envelopeDropDown.Value;
            app.stimResolutionPixelsNum = app.resolutionpixelsSpinner.Value;  
            
            app.stimIsMosaicCentered = app.stimCenteredOnMosaicCheckBox.Value;
            app.stimXposDegs = app.xPosdegsSpinner.Value;
            app.stimYposDegs = app.yPosdegsSpinner.Value;
            
            % Initialize psychometric function params
            app.psychometricFunctionMinSlope = app.slope_minSpinner.Value;
            app.psychometricFunctionMaxSlope = app.slope_maxSpinner.Value;
            app.psychometricFunctionMinLogThreshold = app.log10C_minSpinner.Value;
            app.psychometricFunctionMaxLogThreshold = app.log10C_maxSpinner.Value;
            app.psychometricFunctionDeltaLogThreshold = app.log10C_deltaSpinner.Value;
            app.psychometricFunctionDeltaSlope = app.slope_deltaSpinner.Value;
            app.psychometricFunctionPerformanceTrialsNum = app.testtrialsSpinner.Value;
            app.psychometricFunctionContrastLevelsNum = app.examinedcontrastlevelsSpinner.Value;
            
            % Initialize CSF spatial frequency support params
            app.csfMinSpatialFrequency = app.sfMinSpinner.Value;
            app.csfMaxSpatialFrequency = app.sfMaxSpinner.Value;
            app.csfSamplesSpatialFrequency = app.sfSamplesSpinner.Value;

            % Initialize switches
            app.mosaicDomain = app.domainSwitch.Value;
            app.mosaicVisualizationView = app.retinalOrVisualFieldView.Value;
            app.mosaicDisplayMode = app.coneMosaicViewModeKnob.Value;
            app.overlayConesOnPSF = app.overlayconesCheckBox.Value;
            app.opticsVisualizedWavelength = app.visualizedwavelengthnmSpinner.Value;
            app.visualizedResponseType = app.meanOrNoisyInstance.Value;
   
            % Exporting
            app.exportDirectoryName = fullfile(strrep(sprintf('%s', isetRootPath), 'isettools', ''), 'demoApps/CSFGenerator/appSupport/exportedPipelines');
        end
        
        % Method called from the startup method as soon as the GUI is
        % presented. Used to set slider values etc.
        function initializeGUIcomponents(app)
            app.updateRadialEccentricitySliderText();
            app.updateMosaicSizeSliderText();
            app.initializePolarEccentricitySliderTicks();
            drawnow;
        end
        
        % Method called from the startup method as soon as the GUI is
        % presented. Used to initialize all views.
        function initializeViews(app)
            app.statusEditField.Value = 'Initializing views ...';
            app.initializeMosaicView();
            app.initializeVisualFieldView();
            app.initializePSFView();
            app.initializeStimulusView();
            app.initializePsychometricFunctionView();
            app.initializeCSFView();
            app.updateVisualFieldView();
            drawnow;
        end
        
        function initializeMosaicView(app)
            plot(app.mosaicView, 0.01*cosd(0:10:360), 0.01*sind(0:10:360), 'k-');
            box(app.mosaicView, 'on');
            set(app.mosaicView, 'XLim', [-0.1 0.1], 'YLim', [-0.1 0.1], ...
                'XTick', [-0.1 0 0.1], 'YTick', [-0.1 0 0.1]);
            xlabel(app.mosaicView, 'degrees');
            
            % Do not show the interactions toolbax
            app.mosaicView.Toolbar.Visible = 'off';
            
            % Add listener to zoom-events
            addlistener(app.mosaicView, {'XLim', 'YLim'}, 'PostSet', @app.handleMosaicViewZoomEvent);
            drawnow;
            pause(0.1);
        end
        
        function handleMosaicViewZoomEvent(app,src,evnt)
            switch src.Name
                case 'XLim'
                    visualizedMosaicViewXLim = evnt.AffectedObject.XLim;
                    switch (app.mosaicDomain)
                        case 'deg'
                            app.visualizedMosaicViewXLimDegs = visualizedMosaicViewXLim;
                        case 'um'
                            app.visualizedMosaicViewXLimMicrons = visualizedMosaicViewXLim;
                    end
                case 'YLim'
                    visualizedMosaicViewYLim = evnt.AffectedObject.YLim;
                    switch (app.mosaicDomain)
                        case 'deg'
                            app.visualizedMosaicViewYLimDegs = visualizedMosaicViewYLim;
                        case 'um'
                            app.visualizedMosaicViewYLimMicrons = visualizedMosaicViewYLim;
                    end
            end
        end
        
        function initializePSFView(app)
            plot(app.psfView, 0.5*cosd(0:10:360), 0.5*sind(0:10:360), 'k-');
            spatialSupportArcMinRange = 18;
            set(app.psfView, 'XLim', spatialSupportArcMinRange*[-0.5 0.5], 'YLim', spatialSupportArcMinRange*[-0.5 0.5], ...
                'XTick', -20:2:20, 'YTick', -20:2:20, 'FontSize', 14);
            box(app.psfView, 'on');
            grid(app.psfView, 'on');
            xlabel(app.psfView, 'arc min.');
            ylabel(app.psfView, 'arc min.');
            
            % Do not show the interactions toolbax
            app.psfView.Toolbar.Visible = 'off';
            
            % Only allow zooming
            app.psfView.Interactions = [panInteraction zoomInteraction];
            
            % Add listener to zoom-events
            addlistener(app.psfView, {'XLim', 'YLim'}, 'PostSet', @app.handlePSFViewZoomEvent);
            
            drawnow;
            pause(0.1);
        end
        
        
        function handlePSFViewZoomEvent(app,src,evnt)
            switch src.Name
                case 'XLim'
                    app.visualizedPSFViewLimsArcMin = evnt.AffectedObject.XLim;
                    
                case 'YLim'
                    app.visualizedPSFViewLimsArcMin = evnt.AffectedObject.YLim;
            end
        end
        
        
        function initializeStimulusView(app)
            set(app.stimulusView, 'XColor', 'none', 'YColor', 'none');
            axis(app.stimulusView, 'square');
            
            % Do not show the interactions toolbax
            app.stimulusView.Toolbar.Visible = 'off';
            
            % No user-interactions
            app.stimulusView.Interactions = [];
        end
        
        
        function initializePsychometricFunctionView(app)
            set(app.psychometricFunctionView, 'XLim', [-4.5 0], 'YLim', [0.49 1.01], 'XScale', 'linear');
            set(app.psychometricFunctionView, 'XTick', [-5 -4 -3 -2 -1 0]);
            xlabel(app.psychometricFunctionView, 'log10(contrast)');
            ylabel(app.psychometricFunctionView, 'pCorrect');
            grid(app.psychometricFunctionView, 'on');
            box(app.psychometricFunctionView, 'on');
            
            % Do not show the interactions toolbax
            app.psychometricFunctionView.Toolbar.Visible = 'off';
            
            % Only allow paning
            app.psychometricFunctionView.Interactions = [panInteraction];
        end
        
        function initializeCSFView(app)
            set(app.csfView, 'XLim', [0.5 100], 'YLim', [1 1000], ...
                'XTick', [1 3 10 30 60 100], 'YTick', [1 3 10 30 100 300 1000 3000 10000], ...
                'XScale', 'log', 'YScale', 'log', 'FontSize', 14);
            box(app.csfView, 'on');
            grid(app.csfView, 'on');
            xlabel(app.csfView, 'spatial frequency (c/deg)');
            ylabel(app.csfView, 'contrast sensitivity');
            
            % Do not show the interactions toolbax
            app.csfView.Toolbar.Visible = 'off';
            
            % Only allow paning
            app.csfView.Interactions = [panInteraction];
            
        end
        
        function initializeVisualFieldView(app)
            plot(app.visualFieldView, [-32 32], [0 0], 'k-', 'LineWidth', 1.5, 'Color', [0.5 0.5 0.5]);
            hold(app.visualFieldView, 'on');
            plot(app.visualFieldView, [0 0], [-32 32], 'k-', 'LineWidth', 1.5, 'Color', [0.5 0.5 0.5]);
            % Plot the eccentricity rings
            for radius = [0.15 0.3 0.6 1.2 2.5 5 10 20]
                logRadius = app.maxEcc*(1+log10(radius))/(1+log10(app.maxEcc));
                plot(app.visualFieldView, logRadius*cosd(0:5:360), logRadius*sind(0:5:360), 'k-', 'Color', [0.5 0.5 0.5], 'LineWidth', 1.0);
            end
            
            % Plot the optic disk location
            odOutline.x = nan;
            odOutline.y = nan;
            odColor = [1 0.5 0];
            app.odOnVisualFieldPlotHandle = patch(app.visualFieldView, odOutline.x, odOutline.y, odColor, 'FaceAlpha', 0.5, 'EdgeColor', 'none');


            % Plot the mosaic outline
            mosaicOutline.x = nan;
            mosaicOutline.y = nan;
            app.mosaicOnVisualFieldPlotHandle = patch(app.visualFieldView, mosaicOutline.x, mosaicOutline.y, [0.1 0.95 0.7], 'FaceAlpha', 0.5, 'EdgeColor', [0.1 0.3 0.8]);   % scatter(app.visualFieldView, 0, 0, 14*14, 'ro', 'MarkerEdgeColor', [0.1 0.3 0.8], 'MarkerFaceColor', [0.1 0.95 0.7], 'MarkerFaceAlpha', 0.5);
            
            hold(app.visualFieldView, 'off');
            set(app.visualFieldView, 'XTick', 0, 'YTick', 0, 'XLim', app.maxEcc*[-1 1], 'YLim', app.maxEcc*[-1 1], 'FontSize', 14);
            set(app.visualFieldView, 'Color', 'none', 'XColor', 'none', 'YColor', 'none');
            set(app.visualFieldView, 'XTickLabel', '0');
            set(app.visualFieldView, 'YTickLabel', '0');
            box(app.visualFieldView, 'off');
            xlabel(app.visualFieldView, 'x (degs)');
            ylabel(app.visualFieldView, 'y (degs)');
            
            % Do not show the interactions toolbax
            app.visualFieldView.Toolbar.Visible = 'off';
            
            % No interactions
            app.visualFieldView.Interactions = [];
        end
        
        function initializeSimulationComponents(app)
            
            % Determine whether we will show any more progress bars
            app.showsProgressBar = getpref('ISET','waitbar');
            
            % Start the parpool
            dialogBox = uiprogressdlg(app.mainView,'Title','Please Wait',...
                'Message',sprintf('This app requires MATLAB 2020b or later, and that (i) isetbio, (ii) ISETBioCSFGenerator, and (iii) mQuestPlus, available at: \n\t-https://github.com/isetbio/isetbio\n\t-https://github.com/isetbio/ISETBioCSFGenerator.git\n\t-https://github.com/BrainardLab/mQUESTPlus.git \nare on the user''s path.\n\nWaiting for parallel pool engine to wake up...'));
            dialogBox.Value = 0.2; 
            pause(.01)
            poolobj = gcp('nocreate'); 
            if isempty(poolobj)
                parpool('local');
            end
            
            % Regenerate the cone mosaic
            app.regenerateMosaicAndOptics(dialogBox, 0.9, 1.0);
            
            % Regenerate the demo stimulus scene
            app.generateDemoStimulus();
            
            app.updateStimulusView();
            app.updatePsychometricFunctionView();
            app.updateCSFView();
            
            % Close dialog box
            close(dialogBox);

        end
       
        
        % Method to regenerate the cone mosaic and update the current progress bar
        function regenerateMosaicAndOptics(app, dialogBox, currentProgressValue, nextProgressValue)
            
            % Create progress bar
            if (currentProgressValue == 0) 
                %if (app.showsProgressBar)
                    dialogBox = uiprogressdlg(app.mainView,'Title','Please Wait',...
                    'Message','');
                    %pause(.01)
                %end
            end
            
            %if (app.showsProgressBar)
                % Update progress bar
                deltaProgressValue = (nextProgressValue-currentProgressValue)/3;
                dialogBox.Value = currentProgressValue+deltaProgressValue; 
                dialogBox.Message = 'Generating cone mosaic';
                %pause(.01)
            %end

            
            % Generate new cone mosaic
            app.regenerateMosaic();
            
            %if (app.showsProgressBar)
                % Update progress bar
                dialogBox.Value = currentProgressValue+1.3*deltaProgressValue; 
                dialogBox.Message = 'Generating optics';
                %pause(.01)
            %end
            
            % Generate optics appropriate for the mosaic's eccentricity
            app.regenerateOptics();
            
            % Must recompute optical image
            app.opticalImageIsStale = true;
            
            if (app.showsProgressBar)
                % Update progress bar
                dialogBox.Value = currentProgressValue+1.6*deltaProgressValue; 
                dialogBox.Message = 'Computing mosaic response';
                %pause(.01);
            end
                
            app.generateDemoStimulus();
            
            if strcmp(app.mosaicDisplayMode, 'activation') || strcmp(app.mosaicDisplayMode, 'modulation') || ...
               strcmp(app.mosaicDisplayMode, 'residual, (c-p)/p') || strcmp(app.mosaicDisplayMode, 'cones & retinal image')
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            
            %if (app.showsProgressBar)
                % Update progress bar
                dialogBox.Value = currentProgressValue+2*deltaProgressValue; 
                dialogBox.Message = 'Rendering PSF';
                %pause(.01);
            %end
            
            % Visualized the psf
            app.updatePSFView();
            
            if (strcmp(app.mosaicDisplayMode, 'activation')) && (~strcmp(app.visualizedResponseType, 'mean'))
                % Showing instances so close dialog box
                close(dialogBox);
                shouldCloseDialogBox = false;
            else
                dialogBox.Value = 1.0; 
                dialogBox.Message = 'Rendering Mosaic';
                shouldCloseDialogBox = true;
            end
            
            % Visualize the  cone mosaic
            app.updateMosaicView();
                
            % Update the visual field view
            app.updateVisualFieldView();
            
            % Close progress bar
            if (shouldCloseDialogBox)
                close(dialogBox);
            end
                
        end
        
        function regenerateMosaic(app)
            % Generate cone mosaic
            app.components.coneMosaic = cMosaic(...
                'whichEye', app.mosaicEye, ...
                'coneDensities', app.mosaicConeDensities, ...
                'tritanopicRadiusDegs', app.mosaicTritanopicRadiusDegs, ...
                'sizeDegs', app.mosaicSizeDegs*[1 1], ... 
                'eccentricityDegs', app.mosaicEccentricityDegs, ...
                'wave', app.wavelengthMinValue : app.wavelengthStepSize: app.wavelengthMaxValue, ...
                'integrationTime', app.mosaicIntegrationTimeMseconds/1000, ...
                'eccVaryingConeAperture', app.mosaicEccVaryingConeAperture, ...
                'eccVaryingOuterSegmentLength', app.mosaicEccVaryingOuterSegmentLength, ...
                'eccVaryingConeBlur', app.mosaicEccVaryingConeBlur, ...
                'eccVaryingMacularPigmentDensity', app.mosaicEccVaryingMacularPigmentDensity, ...
                'eccVaryingMacularPigmentDensityDynamic', false ...
            );
            
            % Reset last mean activation
            app.lastMeanActivation = [];
            
            % Update visualized XYlims
            app.visualizedMosaicViewXLimDegs = app.mosaicEccentricityDegs(1) + app.mosaicSizeDegs*0.56*[-1 1];
            app.visualizedMosaicViewYLimDegs = app.mosaicEccentricityDegs(2) + app.mosaicSizeDegs*0.56*[-1 1];
            
            % Convert degs to microns
            % The xyMin
            xyMinLimsDegs = [app.visualizedMosaicViewXLimDegs(1) app.visualizedMosaicViewYLimDegs(1)];
            if (~isempty(app.components.coneMosaic.micronsPerDegreeApproximation))
                xyMinLimsMicrons = xyMinLimsDegs * app.components.coneMosaic.micronsPerDegreeApproximation;  
            else
                xyMinLimsMicrons = 1e3 * RGCmodels.Watson.convert.rhoDegsToMMs(xyMinLimsDegs);
            end
            app.visualizedMosaicViewXLimMicrons(1) = xyMinLimsMicrons(1);
            app.visualizedMosaicViewYLimMicrons(1) = xyMinLimsMicrons(2);
                    
            % The xyMax
            xyMaxLimsDegs = [app.visualizedMosaicViewXLimDegs(2) app.visualizedMosaicViewYLimDegs(2)];
            if (~isempty(app.components.coneMosaic.micronsPerDegreeApproximation))
                xyMaxLimsMicrons = xyMaxLimsDegs * app.components.coneMosaic.micronsPerDegreeApproximation;  
            else
                xyMaxLimsMicrons = 1e3 * RGCmodels.Watson.convert.rhoDegsToMMs(xyMaxLimsDegs);
            end
            app.visualizedMosaicViewXLimMicrons(2) = xyMaxLimsMicrons(1);
            app.visualizedMosaicViewYLimMicrons(2) = xyMaxLimsMicrons(2);
        end
        
        function mosaicOutline = generateMosaicOutlineForRetinalPatchView(app)
            x = app.mosaicEccentricityDegs(1) + 0.5*app.mosaicSizeDegs(1) * [-1 -1 1 1 -1];
            y = app.mosaicEccentricityDegs(2) + 0.5*app.mosaicSizeDegs(1) * [-1 1 1 -1 -1];
            
            r = sqrt(x.^2+y.^2);
            r = app.maxEcc*(1+log10(r))/(1+log10(app.maxEcc));
            theta = atan2(y,x);
            mosaicOutline.x = r .* cos(theta);
            mosaicOutline.y= r .* sin(theta);
        end

        function regenerateOptics(app)
            % Generate optics appropriate for the mosaic's eccentricity
            [oiEnsemble, psfEnsemble] = ...
                app.components.coneMosaic.oiEnsembleGenerate(app.mosaicEccentricityDegs, ...
                'zernikeDataBase', 'Polans2015', ...
                'subjectID', app.opticsPolansSubjectID, ...
                'pupilDiameterMM', app.opticsPupilDiameterMM, ...
                'subtractCentralRefraction', app.opticsPolansSubtractCentralRefraction);
           
            % Exported data
            app.exportedOpticsParams = struct(...
                'eccentricityDegs', app.mosaicEccentricityDegs, ...
                'zernikeDataBase', 'Polans2015', ...
                'subjectID', app.opticsPolansSubjectID, ...
                'pupilDiameterMM', app.opticsPupilDiameterMM, ...
                'subtractCentralRefraction', app.opticsPolansSubtractCentralRefraction ...
                );
            
            % Update all components with new optics
            app.components.psf = psfEnsemble{1};
            app.components.optics = oiEnsemble{1};
        end
        
        function refreshStimulus(app)
            app.generateDemoStimulus();
            
            % Flag indicating whether we will need to recompute optical image
            app.opticalImageIsStale = true;
            
            if (~strcmp(app.mosaicDisplayMode, 'cone types'))
                app.lastMeanActivation = [];
                app.recomputeMosaicResponseToDemoStimulusScene();
                app.updateMosaicView();
            end
        end


        function generateDemoStimulus(app)
            
            % Spatial envelope
            switch (app.stimSpatialEnvelope)
                case 'disk'
                    app.stimSpatialEnvelopeRadiusDegs = app.stimSizeDegs/2;
                case 'rect'
                    app.stimSpatialEnvelopeRadiusDegs = app.stimSizeDegs/(2*sqrt(2.0));
                case 'soft'
                    app.stimSpatialEnvelopeRadiusDegs = app.stimSizeDegs/6;
                    
            end

            % Form sParams struct
            sParams = struct(...
                'contrast', 1.0, ...
                'chromaDir', [app.stimLcontrast app.stimMcontrast app.stimScontrast]/100, ...
                'meanLuminanceCdPerM2', app.stimMeanLuminanceCdPerM2, ...
                'sf', app.stimSpatialFrequencyCyclesPerDeg, ...
                'fovDegs', app.stimSizeDegs, ...
                'orientation', app.stimOrientationDegs, ...
                'spatialEnvelope', app.stimSpatialEnvelope, ...
                'spatialEnvelopeRadiusDegs', app.stimSpatialEnvelopeRadiusDegs, ...
                'spatialPhase', app.stimSpatialPhaseDegs, ...
                'pixelsNum', app.stimResolutionPixelsNum, ...
                'minPixelsNumPerCycle', app.stimMinPixelsNumPerCycle, ...
                'spectralSupport', (app.wavelengthMinValue : app.wavelengthStepSize: app.wavelengthMaxValue), ...
                'presentationMode', 'flashed', ...
                'duration', app.stimDurationMsec/1000, ...
                'warningInsteadOfErrorOnOutOfGamut', true ...
            );
            
            % Call the external CSFgenerator.computeStimulusSceneEngine method to
            % generate the stimulus scene
            [app.demoStimulusScene, app.nullStimulusScene, statusReport] = CSFgenerator.computeStimulusSceneEngine(sParams, []);
            
            % Update the view
            app.updateStimulusView();
            
            % Set the status field to indicate whether the stimulus is out of gamut
            flagNames = fieldnames(statusReport);
            if (~isempty(flagNames))
                app.stimulusGenerationStatusReport.Value = '';
                for k = 1:numel(flagNames)
                    app.stimulusGenerationStatusReport.Value = sprintf('%s - %s',app.stimulusGenerationStatusReport.Value,flagNames{k});
                end
                app.stimulusGenerationStatusReport.Value = sprintf('%s -',app.stimulusGenerationStatusReport.Value);
                app.stimulusGenerationStatusReport.FontColor = [1 1 1];
                app.stimulusGenerationStatusReport.FontWeight = 'Bold';
                app.stimulusGenerationStatusReport.BackgroundColor = [1 0 0];
            else
                app.stimulusGenerationStatusReport.Value = 'stimulus is realizable';
                app.stimulusGenerationStatusReport.FontWeight = 'Normal';
                app.stimulusGenerationStatusReport.FontColor = [0 0 0];
                app.stimulusGenerationStatusReport.BackgroundColor = [1 1 1];
            end
            
            app.lastMeanActivation = [];
            app.recomputeMosaicResponseToDemoStimulusScene();
        end
        
        
        function recomputeMosaicResponseToDemoStimulusScene(app)  
            
            % Compute the optical image to the demo stimulus scene
            if (app.opticalImageIsStale)
                app.components.optics = oiCompute(app.demoStimulusScene, app.components.optics);
                app.opticalImageIsStale = false;
            end
            
            % Check where to position the optical image
            if (app.stimIsMosaicCentered == 1)
                opticalImagePositionDegs = 'mosaic-centered';
            else
                opticalImagePositionDegs = [app.stimXposDegs app.stimYposDegs];
            end
       
            if (strcmp(app.visualizedResponseType, 'mean'))
                % Compute mean response
                testActivation = app.components.coneMosaic.compute(app.components.optics, ...
                    'opticalImagePositionDegs', opticalImagePositionDegs);
                meanActivation = testActivation;
            else
                % Compute noisy response instance
                [meanActivation,testActivation] = app.components.coneMosaic.compute(app.components.optics, ...
                    'opticalImagePositionDegs', opticalImagePositionDegs, 'nTrials',10);
                app.demoNoisyMosaicActivations = testActivation;
                testActivation = testActivation(1,:,:);
            end
            
            if strcmp(app.mosaicDisplayMode, 'modulation')
                % Compute response to null (zero contrast) stimulus
                nullActivation = app.components.coneMosaic.compute(oiCompute(app.nullStimulusScene, app.components.optics), ...
                    'opticalImagePositionDegs', opticalImagePositionDegs);
                app.demoMosaicActivation = testActivation - nullActivation;
            elseif strcmp(app.mosaicDisplayMode, 'residual, (c-p)/p')
                if (~isempty(app.lastMeanActivation))
                    app.demoMosaicActivation = 100.0*(testActivation - app.lastMeanActivation)./app.lastMeanActivation;
                else
                    app.demoMosaicActivation = 0*testActivation;
                end
            else % activation
                % Current activation
                app.demoMosaicActivation = testActivation;
            end
            
            app.lastMeanActivation = meanActivation;
                
        end
        
        function decodeRadialEccentricityDegsSlider(app, value)
            actualValues = round(logspace(log10(0.15),log10(40), 9)*100)/100;
            actualValues = [0 actualValues(1:end-1)];
            app.mosaicRadialEccentricityDegs = interp1(0:(numel(actualValues)-1), actualValues, value);
            app.mosaicEccentricityDegs(1) = app.mosaicRadialEccentricityDegs * cosd(app.mosaicPolarEccentricityDegs);
            app.mosaicEccentricityDegs(2) = app.mosaicRadialEccentricityDegs * sind(app.mosaicPolarEccentricityDegs);
        end
        
        function decodePolarEccentricityDegsSlider(app, value)
            app.mosaicPolarEccentricityDegs =-value;
            app.mosaicEccentricityDegs(1) = app.mosaicRadialEccentricityDegs * cosd(app.mosaicPolarEccentricityDegs);
            app.mosaicEccentricityDegs(2) = app.mosaicRadialEccentricityDegs * sind(app.mosaicPolarEccentricityDegs);
        end
        
        function decodeSizeDegsSlider(app, value)
            actualValues = [0.07 0.15 0.3 0.6 1.2 2.5 5 10];
            app.mosaicSizeDegs  = interp1(1:numel(actualValues), actualValues, value);
        end
        
        function updateRadialEccentricitySliderText(app)
            radialEccentricity = sqrt(sum(app.mosaicEccentricityDegs.^2,2));
            if (app.mosaicEccentricityDegs < 1.0)
                 app.radialeccentricitydegsSliderLabel.Text = sprintf('radial eccentricity (degs): %2.2f', radialEccentricity );
            elseif (app.mosaicEccentricityDegs < 10.0)
                 app.radialeccentricitydegsSliderLabel.Text = sprintf('radial eccentricity (degs): %2.1f', radialEccentricity);
            else
                 app.radialeccentricitydegsSliderLabel.Text = sprintf('radial eccentricity (degs): %2.0f', radialEccentricity);
            end
        end
        
        function updateMosaicSizeSliderText(app)
            if (app.mosaicSizeDegs < 1.0)
                 app.fieldofviewdegsSliderLabel.Text =  sprintf('field of view (degs): %2.2f x %2.2f ', app.mosaicSizeDegs, app.mosaicSizeDegs);
            elseif (app.mosaicSizeDegs < 10.0)
                 app.fieldofviewdegsSliderLabel.Text = sprintf('field of view (degs): %2.1f x %2.1f', app.mosaicSizeDegs, app.mosaicSizeDegs);
            else
                 app.fieldofviewdegsSliderLabel.Text = sprintf('field of view(degs): %2.0f x %2.0f', app.mosaicSizeDegs, app.mosaicSizeDegs);
            end
        end
        
        function initializePolarEccentricitySliderTicks(app)
            app.polareccentricitydegsSlider.MajorTicks = 0:45:360;
            app.polareccentricitydegsSlider.MajorTickLabels = {'360', '315', '270', '225', '180',  '135',  '90',   '45', '0'};
            app.polareccentricitydegsSlider.MinorTicks = [];
        end
        
        function updateVisualFieldView(app)
            app.mosaicEccentricityDegs = app.mosaicRadialEccentricityDegs * [cosd(app.mosaicPolarEccentricityDegs) sind(app.mosaicPolarEccentricityDegs)];
            if (log10(app.mosaicRadialEccentricityDegs)>-1)
                logRadius = app.maxEcc*(1+log10(app.mosaicRadialEccentricityDegs))/(1+log10(app.maxEcc));
            else
                logRadius = 0;
            end
            
            set(app.mosaicOnVisualFieldPlotHandle, ...
                'XData',logRadius*cosd(app.mosaicPolarEccentricityDegs), ...
                'YData',logRadius*sind(app.mosaicPolarEccentricityDegs));
            app.updateRadialEccentricitySliderText();
            
            if (isfield(app.components, 'coneMosaic'))
                [~,odStructDegs] = app.components.coneMosaic.odStruct();
                a = 0.5*odStructDegs.minorAxisDiameter; 
                b = 0.5*odStructDegs.majorAxisDiameter; 
                c = odStructDegs.center;
                cosOutline = cosd(0:10:360);
                sinOutline = sind(0:10:360);
                x = c(1) + a*cosOutline*cosd(odStructDegs.rotation) - b*sinOutline*sind(odStructDegs.rotation);
                y = c(2) + b*sinOutline*cosd(odStructDegs.rotation) + a*cosOutline*sind(odStructDegs.rotation);
                r = sqrt(x.^2+y.^2);
                r = app.maxEcc*(1+log10(r))/(1+log10(app.maxEcc));
                theta = atan2(y,x);
                xx = r .* cos(theta);
                yy = r .* sin(theta);
                set(app.odOnVisualFieldPlotHandle, ...
                    'XData', xx, ...
                    'YData', yy);
            end
            
            mosaicOutline = app.generateMosaicOutlineForRetinalPatchView();
            set(app.mosaicOnVisualFieldPlotHandle, ...
                    'XData', mosaicOutline.x, ...
                    'YData', mosaicOutline.y);
            drawnow
        end

        % Method to update the mosaicView
        function updateMosaicView(app)
            
            switch (app.mosaicDomain)
                case 'deg'
                    domain = 'degrees';
                    % Set the previous visualization limits
                    if (isempty(app.visualizedMosaicViewXLimDegs)) || (isempty(app.visualizedMosaicViewYLimDegs))
                        domainVisualizationLimits = [];
                    else
                        domainVisualizationLimits = [...
                            app.visualizedMosaicViewXLimDegs(1) ...
                            app.visualizedMosaicViewXLimDegs(2) ...
                            app.visualizedMosaicViewYLimDegs(1) ...
                            app.visualizedMosaicViewYLimDegs(2)];
                    end
            
                case 'um'
                    domain = 'microns';
                    % Set the previous visualization limits
                    if (isempty(app.visualizedMosaicViewXLimMicrons)) || (isempty(app.visualizedMosaicViewYLimMicrons))
                        domainVisualizationLimits = [];
                    else
                        domainVisualizationLimits = [...
                            app.visualizedMosaicViewXLimMicrons(1) ...
                            app.visualizedMosaicViewXLimMicrons(2) ...
                            app.visualizedMosaicViewYLimMicrons(1) ...
                            app.visualizedMosaicViewYLimMicrons(2)];
                    end
                    
            end
            
            if (~strcmp(app.mosaicDisplayMode, 'cone types')) && (~strcmp(app.mosaicDisplayMode, 'cones & retinal image'))
            
                if (strcmp(app.mosaicDisplayMode, 'activation')) || (strcmp(app.mosaicDisplayMode, 'residual, (c-p)/p'))
                    addActivationColorBar = true;
                    
                    if (strcmp(app.mosaicDisplayMode, 'activation'))
                        if (strcmp(app.visualizedResponseType, 'mean'))
                            activationRange = [0 max(app.demoMosaicActivation(:))];
                        else
                            activationRange = [0 max(app.demoNoisyMosaicActivations(:))];
                        end
                        cMap = [];
                        colorbarTickPostfix = '';
                    else
                        cMap = brewermap(1024, '*RdBu');
                        colorbarTickPostfix =  '%';
                        activationRange = max(prctile(abs(app.demoMosaicActivation(:)),99))*[-1 1];
                    end
                else
                    addActivationColorBar = false;
                    activationRange = [];
                    cMap = [];
                    colorbarTickPostfix = '';
                end
                
                if (strcmp(app.mosaicDisplayMode, 'activation')) && (~strcmp(app.visualizedResponseType, 'mean'))
                    instancesNum = size(app.demoNoisyMosaicActivations,1);
                    for k = 1:instancesNum
                    app.components.coneMosaic.visualize(...
                        'figureHandle', app.mainView, ...
                        'axesHandle', app.mosaicView, ...
                        'visualizationView', app.mosaicVisualizationView, ...
                        'activation', app.demoNoisyMosaicActivations(k,:,:), ...
                        'activationRange', activationRange, ...
                        'activationColorMap', cMap, ...
                        'colorBarTickLabelPostFix', colorbarTickPostfix, ...
                        'verticalActivationColorBarInside', addActivationColorBar, ...
                        'crossHairsOnFovea', true, ...
                        'visualizedConeAperture', 'geometricArea', ...
                        'domain', domain, ...
                        'domainVisualizationLimits', domainVisualizationLimits, ...
                        'noYLabel', ~true, ...
                        'fontSize', 14, ...
                        'plotTitle',  ' ', ...
                        'textDisplay', sprintf('%d/%d', k, instancesNum));
                        pause(0.01);
                    end
                else
                    app.components.coneMosaic.visualize(...
                        'figureHandle', app.mainView, ...
                        'axesHandle', app.mosaicView, ...
                        'visualizationView', app.mosaicVisualizationView, ...
                        'activation', app.demoMosaicActivation, ...
                        'activationRange', activationRange, ...
                        'activationColorMap', cMap, ...
                        'colorBarTickLabelPostFix', colorbarTickPostfix, ...
                        'verticalActivationColorBarInside', addActivationColorBar, ...
                        'crossHairsOnFovea', true, ...
                        'visualizedConeAperture', 'geometricArea', ...
                        'domain', domain, ...
                        'domainVisualizationLimits', domainVisualizationLimits, ...
                        'noYLabel', ~true, ...
                        'fontSize', 14, ...
                        'plotTitle',  ' ');
                end
            else
                showDensityContours = ~true;
                if (showDensityContours) 
                    app.components.coneMosaic.visualize(...
                    'figureHandle', app.mainView, ...
                    'axesHandle', app.mosaicView, ...
                    'visualizationView', app.mosaicVisualizationView, ...
                    'labelCones', true, ...
                    'densityContourOverlay', true, ...
                    'densityContourLevelLabelsDisplay', true, ...
                    'crossHairsOnFovea', true, ...
                    'visualizedConeAperture', 'geometricArea', ...
                    'domain', domain, ...
                    'domainVisualizationLimits', domainVisualizationLimits, ...
                    'noYLabel', ~true, ...
                    'fontSize', 14, ...
                    'plotTitle',  ' ');
                else
                    app.components.coneMosaic.visualize(...
                    'figureHandle', app.mainView, ...
                    'axesHandle', app.mosaicView, ...
                    'visualizationView', app.mosaicVisualizationView, ...
                    'labelCones', true, ...
                    'crossHairsOnFovea', true, ...
                    'visualizedConeAperture', 'geometricArea', ...
                    'domain', domain, ...
                    'domainVisualizationLimits', domainVisualizationLimits, ...
                    'noYLabel', ~true, ...
                    'fontSize', 14, ...
                    'plotTitle',  ' ');
                end
            
                if (strcmp(app.mosaicDisplayMode, 'cones & retinal image'))
                    hold(app.mosaicView, 'on');
                    [imPlot, ~, ~, mask] = app.renderRetinalImageOnAxes(app.mosaicView, app.mosaicVisualizationView);
                    imPlot.AlphaData = 0.75*mask;
                    hold(app.mosaicView, 'off');
                end
            end
            
            
            % Set the current retinal magnification factor
            app.retinalmagnificationfactorumdegEditField.Value = app.components.coneMosaic.micronsPerDegree;
        end
        
        % Method to update the displayed PSF
        function updatePSFView(app)
            % Find index of visualized wavelength
            [~, wIdx] = min(abs(app.components.psf.supportWavelength-app.opticsVisualizedWavelength));
            
            % Extract PSF at the desired wavelength
            wavePSF = squeeze(app.components.psf.data(:,:,wIdx));
            
            % Normalize to unit amplitude
            wavePSF = wavePSF/max(wavePSF(:));
            zLevels = linspace(0.05, 1.0-0.05, 7);
            %zLevels = logspace(log10(0.05), log10(1), 6);
            if (isempty(app.visualizedPSFViewLimsArcMin))
                xyRangeArcMin = 9*[-1 1];
            else
                xyRangeArcMin = app.visualizedPSFViewLimsArcMin;
            end
            
            % Render it
            cla(app.psfView);
            
            cMap = brewermap(512, '*spectral');
            if (app.overlayConesOnPSF)
                conePos = bsxfun(@minus, app.components.coneMosaic.coneRFpositionsDegs, app.components.coneMosaic.eccentricityDegs);
                coneEccArcMin = 60*sqrt(sum(conePos.^2,2));
                idx = find(coneEccArcMin < 11);
                theConeData.conePositionsArcMin = conePos(idx,:) * 60;
                theConeData.coneAperturesArcMin = app.components.coneMosaic.coneRFspacingsDegs(idx)*60;
                PolansOptics.renderPSF(app.psfView, app.components.psf.supportX, app.components.psf.supportY, wavePSF, ...
                    xyRangeArcMin, zLevels,  cMap, [0 0 0], 'xyTicks', -20:2:20, 'plotTitle',  ' ', ...
                    'withConeData', theConeData);
            else
                PolansOptics.renderPSF(app.psfView, app.components.psf.supportX, app.components.psf.supportY, wavePSF, ...
                    xyRangeArcMin, zLevels,  cMap, [0 0 0], 'xyTicks', -20:2:20, 'plotTitle',  ' ');
            end
            colorbar(app.psfView, 'location','east');
            colormap(app.psfView, cMap);
            app.actualVisualizedWavelengthField.Value = sprintf('%2.0f',app.components.psf.supportWavelength(wIdx));
        end

        
        function [imPlot, xSupport, ySupport, mask] = renderRetinalImageOnAxes(app, axesHandle, mosaicVisualizationView)
            
            rgbImage = flipud(oiGet(app.components.optics, 'rgb'));
            mask = ones(size(rgbImage,1), size(rgbImage,2));
            
            % Obtain spatial support in microns
            spatialSupportMeters = oiGet(app.components.optics, 'spatial support');
            xSupport = squeeze(spatialSupportMeters(1,1:end,1)) * 1e6;
            ySupport = squeeze(spatialSupportMeters(1:end,1,2)) * 1e6;
            
            if (strcmp(app.mosaicDomain, 'deg'))
                 % Convert spatial support in microns to degs
                 %hDegs = oiGet(app.components.optics, 'hangular');
                 %hMicrons = 1e6*oiGet(app.components.optics, 'height');
                 %micronsPerDegree = hMicrons/hDegs;
                 xSupport = xSupport / app.components.coneMosaic.micronsPerDegree;
                 ySupport = ySupport / app.components.coneMosaic.micronsPerDegree;
            end
            
            % Translate to desired position
            if (app.stimIsMosaicCentered == 1)
                % Translate to mosaic center
                if (strcmp(app.mosaicDomain, 'deg'))
                    xSupport = xSupport + app.components.coneMosaic.eccentricityDegs(1);
                    ySupport = ySupport + app.components.coneMosaic.eccentricityDegs(2);
                else
                    xSupport = xSupport + app.components.coneMosaic.eccentricityMicrons(1);
                    ySupport = ySupport + app.components.coneMosaic.eccentricityMicrons(2);
                end
            else
                % Translate to desired position
                if (strcmp(app.mosaicDomain, 'deg'))
                    xSupport = xSupport + app.stimXposDegs;
                    ySupport = ySupport + app.stimYposDegs;
                else
                    % Convert degs to microns
                    if (~isempty(app.components.coneMosaic.micronsPerDegreeApproximation))
                        stimPosMicrons = [app.stimXposDegs app.stimYposDegs] * app.components.coneMosaic.micronsPerDegreeApproximation;
                    else
                        stimPosMicrons = 1e3 * RGCmodels.Watson.convert.rhoDegsToMMs([app.stimXposDegs app.stimYposDegs]);
                    end
                    xSupport = xSupport + stimPosMicrons(1);
                    ySupport = ySupport + stimPosMicrons(2);
                end
            end
            
            % Render demo stimulus on the passed axes
            imPlot = image(axesHandle, xSupport, ySupport, rgbImage);
        end
        
        
        % Method to update the demo stimulus
        function updateStimulusView(app)
            imRGB = sceneGet(app.demoStimulusScene, 'rgb');
            image(app.stimulusView, 1:size(imRGB,2), 1:size(imRGB,1), sceneGet(app.demoStimulusScene, 'rgb'));
            set(app.stimulusView, 'XLim', [1 size(imRGB,2)], 'YLim', [1 size(imRGB,1)], 'XTick', [], 'YTick', []);
        end
        
        % Method to update the psychometric function view
        function updatePsychometricFunctionView(app, varargin)
            p = inputParser;
            p.addParameter('withData',  []);
            p.parse(varargin{:});
            psychometricDataList = p.Results.withData;

            % Compute ranges of psychometric functions
            lapse = 0; guess = 0.5;
            log10Cthreshold = -5:0.01:0;
            pCorrectMaxSlopeMaxThreshold = 1 - (lapse - (guess + lapse - 1)*exp(-10.^(app.psychometricFunctionMaxSlope*(log10Cthreshold - app.psychometricFunctionMaxLogThreshold)/20)));
            pCorrectMinSlopeMaxThreshold = 1 - (lapse - (guess + lapse - 1)*exp(-10.^(app.psychometricFunctionMinSlope*(log10Cthreshold - app.psychometricFunctionMaxLogThreshold)/20)));
            pCorrectMaxSlopeMinThreshold = 1 - (lapse - (guess + lapse - 1)*exp(-10.^(app.psychometricFunctionMaxSlope*(log10Cthreshold - app.psychometricFunctionMinLogThreshold)/20)));
            pCorrectMinSlopeMinThreshold = 1 - (lapse - (guess + lapse - 1)*exp(-10.^(app.psychometricFunctionMinSlope*(log10Cthreshold - app.psychometricFunctionMinLogThreshold)/20)));
            log10CthresholdCoords = [log10Cthreshold, fliplr(log10Cthreshold)];
            inBetweenPCorrectMaxSlopeCoords = [pCorrectMaxSlopeMinThreshold, fliplr(pCorrectMaxSlopeMaxThreshold)];
            inBetweenPCorrectMinSlopeCoords = [pCorrectMinSlopeMinThreshold, fliplr(pCorrectMinSlopeMaxThreshold)];
            
            % Plot ranges of psychometric functions as filled plots
            fill(app.psychometricFunctionView, log10CthresholdCoords, inBetweenPCorrectMaxSlopeCoords, ...
                [1 0.5 0.5], 'FaceAlpha', 0.15, 'EdgeAlpha', 0.35, 'EdgeColor', [1 0 0], 'LineWidth', 0.5);
            hold(app.psychometricFunctionView, 'on');
            fill(app.psychometricFunctionView, log10CthresholdCoords, inBetweenPCorrectMinSlopeCoords, ...
                [0.5 0.5 1], 'FaceAlpha', 0.15, 'EdgeAlpha', 0.35, 'EdgeColor', [0 0 1], 'LineWidth', 0.5);
            
            if (~isempty(psychometricDataList))
           
                for idx = 1:numel(psychometricDataList)
                    psychometricData = psychometricDataList{idx};
                    plot(app.psychometricFunctionView, ...
                        psychometricData.examinedContrastsFit,  psychometricData.pCorrectFit, 'k-', 'Color', [0.3 0.3 0.3], 'LineWidth', 4);
                    plot(app.psychometricFunctionView, ...
                        psychometricData.examinedContrastsFit,  psychometricData.pCorrectFit, 'k-', 'Color', squeeze(app.csfColorMap(idx,:)), 'LineWidth', 2);
                    scatter(app.psychometricFunctionView, ...
                        psychometricData.examinedContrasts, psychometricData.pCorrect, 14*14, ...
                            'Marker', 'o', 'MarkerEdgeColor', [0.3 0.3 0.3], 'MarkerFaceColor', squeeze(app.csfColorMap(idx,:)), ...
                            'MarkerFaceAlpha', 0.7, 'LineWidth', 2);
                end
            end
            hold(app.psychometricFunctionView, 'off');
        end
            
        function sfSupport = decodeCSFsupport(app)
            app.csfColorMap = brewermap(app.csfSamplesSpatialFrequency+2, 'Spectral');
            sfSupport = logspace(log10(app.csfMinSpatialFrequency), log10(app.csfMaxSpatialFrequency), app.csfSamplesSpatialFrequency);
        end
        

        % Method to update the CSF view
        function updateCSFView(app, varargin)
            p = inputParser;
            p.addParameter('withData',  []);
            p.parse(varargin{:});
            csfDataList = p.Results.withData;
            
            cla(app.csfView); hold(app.csfView, 'on');
            sfSupport = app.decodeCSFsupport(); 
            
            if (isempty(csfDataList))
                % Plot triangles to indicate the spatial frequencies to be examined
                for idx = 1:numel(sfSupport)
                    scatter(app.csfView, sfSupport(idx), 1.2, 14*14, 'v', ...
                        'LineWidth', 1.5, 'MarkerFaceColor', squeeze(app.csfColorMap(idx,:)), 'MarkerFaceAlpha', 0.3, 'MarkerEdgeColor', [0.2 0.2 0.2], 'MarkerEdgeAlpha', 0.3);
                end
            else
                for idx = 1:numel(csfDataList)
                    csfData = csfDataList{idx};
                    x(idx) = csfData.spatialFrequency;
                    y(idx) = csfData.sensitivity;
                end
                plot(app.csfView, x, y, 'k-', 'Color', [0.3 0.3 0.3], 'LineWidth', 2);
                for idx = 1:numel(csfDataList)
                    csfData = csfDataList{idx};
                    scatter(app.csfView, ...
                        csfData.spatialFrequency,  csfData.sensitivity, 14*14, 'o',  ...
                        'MarkerEdgeColor', [0.3 0.3 0.3], 'MarkerFaceColor', squeeze(app.csfColorMap(idx,:)), ...
                        'MarkerFaceAlpha', 0.9, 'LineWidth', 2);
                end
                if (max(y) > 1000)
                    set(app.csfView, 'YLim', [1 max(y)*1.1]);
                end
            end
        end
        
        
        function updateMosaicIntegrationTime(app)
            app.components.coneMosaic.integrationTime = app.mosaicIntegrationTimeMseconds / 1000;
        end

        
        function exportSelectorDialog(app, optionLabels, defaultOptionID, cancelOptionID)
            % Present dialog and get input
            msg = 'select a dataset to export'; title = 'data to export';
            app.exportedDataSelection = uiconfirm(app.mainView, msg, title, ...
                 'Options', optionLabels, ...
                 'DefaultOption', defaultOptionID, ...
                 'CancelOption', cancelOptionID);
        end
        
        
        function thePipelineStruct = assembleCSFcomputePipeline(app)
            % Classifier engine params
            classifierPara = struct('trainFlag', 'none', ...
                        'testFlag', 'random', ...
                        'nTrain', 1, 'nTest', app.psychometricFunctionPerformanceTrialsNum);
        
            % Parameters for threshold estimation/quest engine
            thresholdPara = struct('logThreshLimitLow', -app.psychometricFunctionMinLogThreshold, ...
                       'logThreshLimitHigh', -app.psychometricFunctionMaxLogThreshold, ...
                       'logThreshLimitDelta', app.psychometricFunctionDeltaLogThreshold, ...
                       'slopeRangeLow', app.psychometricFunctionMinSlope, ...
                       'slopeRangeHigh', app.psychometricFunctionMaxSlope, ...
                       'slopeDelta', app.psychometricFunctionDeltaSlope);
            
            % Quest engine parameters
            trialsNum = app.psychometricFunctionPerformanceTrialsNum * app.psychometricFunctionContrastLevelsNum;
            questEnginePara = struct('minTrial', trialsNum, 'maxTrial', trialsNum, ...
                         'numEstimator', 1, 'stopCriterion', 0.05);
            
            % PIPELINE data to export
            thePipelineStruct = struct(...
                'neuralComputeFunction', @nrePhotopigmentExcitationsCmosaicSingleShot, ...
                'coneMosaic', app.components.coneMosaic, ...
                'optics', app.components.optics, ...
                'opticsParams',  app.exportedOpticsParams, ...
                'classifierComputeFunction', @rcePoissonTAFC, ...
                'classifierEngineParams', classifierPara, ...
                'thresholdParams', thresholdPara, ...
                'questEngineParams', questEnginePara ...
                );
        end
        
        function  [sParams, exportedStimData] = assembleStimParams(app, testSpatialFrequency)
            % Compute current stimulus params
            sParams = struct(...
                'contrast', [], ...   % When the contrast is empty, CSFgenerator.computeStimulusSceneEngine() only returns the sceneEngine
                'chromaDir', [app.stimLcontrast app.stimMcontrast app.stimScontrast]/100, ...
                'meanLuminanceCdPerM2', app.stimMeanLuminanceCdPerM2, ...
                'sf', testSpatialFrequency, ...
                'fovDegs', app.stimSizeDegs, ...
                'orientation', app.stimOrientationDegs, ...
                'spatialEnvelope', app.stimSpatialEnvelope, ...
                'spatialEnvelopeRadiusDegs', app.stimSpatialEnvelopeRadiusDegs, ...
                'spatialPhase', app.stimSpatialPhaseDegs, ...
                'pixelsNum', app.stimResolutionPixelsNum, ...
                'minPixelsNumPerCycle', app.stimMinPixelsNumPerCycle, ...
                'spectralSupport', (app.wavelengthMinValue : app.wavelengthStepSize: app.wavelengthMaxValue), ...
                'presentationMode', 'flashed', ...
                'duration', app.stimDurationMsec/1000, ...
                'warningInsteadOfErrorOnOutOfGamut', true ...
              );
                
             % STIM data for export
             sParamsFixed = sParams;
             sParamsFixed = rmfield(sParamsFixed, 'sf');
             sParamsFixed = rmfield(sParamsFixed, 'contrast');
             exportedStimData = sParamsFixed;
            
        end
        
        function exportTheData(app, exportedData)
            % Hide app
            app.mainView.Visible = 'off';
             
            % Ask uset for exportFileName and directory
            [fileName, directoryName] = uiputfile('*.mat','Select where to export the data', fullfile(app.exportDirectoryName,'CSFappExport.mat'));
            
            if (fileName ~= 0)  
                % Get the exportFileName
                app.exportDirectoryName = directoryName;
                if (contains(fileName, '.mat'))
                    fileName = strrep(fileName, '.mat', '');
                end
                
                % Save the data
                exportFileName = sprintf('%s.mat', fullfile(app.exportDirectoryName, fileName));
                save(exportFileName, 'exportedData');
                
                % Show the app again
                app.mainView.Visible = 'on';
            
                % Let the user know
                message = sprintf('stimulus and compute pipeline were saved in %s.', exportFileName);
                uialert(app.mainView, message,'All done !', 'Icon','success');
            end
            
            % Show the app again
            app.mainView.Visible = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.initializeSimulationParams();
            app.initializeViews();
            app.initializeGUIcomponents();
            app.initializeSimulationComponents();
            app.statusEditField.Value = 'Ready !';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.mainView.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1078, 1078};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {556, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: radialeccentricitydegsSlider
        function radialeccentricitydegsSliderUpdated(app, event)
            app.decodeRadialEccentricityDegsSlider(app.radialeccentricitydegsSlider.Value);
            app.updateRadialEccentricitySliderText();
            regenerateMosaicAndOptics(app, [], 0, 1);
        end

        % Value changed function: fieldofviewdegsSlider
        function sizeDegsSliderUpdated(app, event)
            app.decodeSizeDegsSlider(app.fieldofviewdegsSlider.Value);
            app.updateMosaicSizeSliderText();
            regenerateMosaicAndOptics(app, [], 0, 1);
        end

        % Value changing function: radialeccentricitydegsSlider
        function radialeccentricitySliderContinuousUpdate(app, event)
            app.decodeRadialEccentricityDegsSlider(event.Value);
            app.updateVisualFieldView();
        end

        % Value changed function: polareccentricitydegsSlider
        function polareccentricitydegsSliderUpdated(app, event)
            app.decodePolarEccentricityDegsSlider(app.polareccentricitydegsSlider.Value);
            regenerateMosaicAndOptics(app, [], 0,1);
        end

        % Value changing function: polareccentricitydegsSlider
        function polareccentricitySliderContinuousUpdate(app, event)
            app.decodePolarEccentricityDegsSlider(event.Value);
            app.updateVisualFieldView();
        end

        % Value changed function: w_minSpinner
        function minWavelengthChanged(app, event)
            % Update the minWavelength
            app.wavelengthMinValue = app.w_minSpinner.Value;
            
            % Update the wave property of the cone mosaic
            app.components.coneMosaic.wave = app.wavelengthMinValue : app.wavelengthStepSize: app.wavelengthMaxValue;
            
            % Recompute optics
            app.regenerateOptics();
            
            % Flag indicating whether we will need to recompute optical image
            app.opticalImageIsStale = true;
            
            % No need to recompute optics - we just did it
            app.recomputeOptics = ~true;
        end

        % Value changed function: w_maxSpinner
        function maxWavelengthChanged(app, event)
            % Update the maxWavelength
            app.wavelengthMaxValue = app.w_maxSpinner.Value;
            
            % Update the wave property of the cone mosaic
            app.components.coneMosaic.wave = app.wavelengthMinValue : app.wavelengthStepSize: app.wavelengthMaxValue;
            
            % Recompute optics
            app.regenerateOptics();
            
            % Flag indicating whether we will need to recompute optical image
            app.opticalImageIsStale = true;
            
            % No need to recompute optics - we just did it
            app.recomputeOptics = ~true;
        end

        % Value changed function: w_stepSpinner
        function stepWavelengthChanged(app, event)
            app.wavelengthStepSize = app.w_stepSpinner.Value;
            
            % Update the wave property of the cone mosaic
            app.components.coneMosaic.wave = app.wavelengthMinValue : app.wavelengthStepSize: app.wavelengthMaxValue;
            
            % Recompute optics
            app.regenerateOptics();
            
            % Flag indicating whether we will need to recompute optical image
            app.opticalImageIsStale = true;
            
            % No need to recompute optics - we just did it
            app.recomputeOptics = false;
        end

        % Value changed function: PolanssubjectSpinner
        function PolansSubjectChanged(app, event)
            app.opticsPolansSubjectID = app.PolanssubjectSpinner.Value;
            
            % Recompute optics
            app.regenerateOptics();
            
            % Update displayed PSF
            app.updatePSFView();
            
            % Flag indicating whether we will need to recompute optical image
            app.opticalImageIsStale = true;
            
            % Recompute response
            if (~strcmp(app.mosaicDisplayMode, 'cone types'))
                app.recomputeMosaicResponseToDemoStimulusScene();
                app.updateMosaicView();
            end
            
            % No need to recompute optics - we just did it
            app.recomputeOptics = false;
        end

        % Value changed function: pupildiametermmSpinner
        function pupilDiameterMMChanged(app, event)
            app.opticsPupilDiameterMM = app.pupildiametermmSpinner.Value;
            
            % Recompute optics
            app.regenerateOptics();
            
            % Update displayed PSF
            app.updatePSFView();
            
            % Flag indicating whether we will need to recompute optical image
            app.opticalImageIsStale = true;
            
            % Recompute response
            if (~strcmp(app.mosaicDisplayMode, 'cone types'))
                app.recomputeMosaicResponseToDemoStimulusScene();
                app.updateMosaicView();
            end
            
            % No need to recompute optics - we just did it
            app.recomputeOptics = false;
        end

        % Value changed function: cLSpinner
        function stimLcontChanged(app, event)
            app.stimLcontrast = app.cLSpinner.Value;
        end

        % Value changed function: cMSpinner
        function stimMcontChanged(app, event)
            app.stimMcontrast = app.cMSpinner.Value;
        end

        % Value changed function: cSSpinner
        function stimScontChanged(app, event)
            app.stimScontrast = app.cSSpinner.Value;
        end

        % Value changed function: flashmsecSpinner
        function stimDurationChanged(app, event)
            app.stimDurationMsec = app.flashmsecSpinner.Value;
        end

        % Button pushed function: updatestimulusButton
        function stimRefreshupdatestimulusButtonPushed(app, event)
            app.refreshStimulus();
        end

        % Value changed function: sizedegsSpinner
        function stimSizeDegsChanged(app, event)
            app.stimSizeDegs = app.sizedegsSpinner.Value;
            app.refreshStimulus();
        end

        % Value changed function: domainSwitch
        function mosaicDomainChanged(app, event)
            app.mosaicDomain = app.domainSwitch.Value;
            
            switch (app.mosaicDomain)
                case 'um'
                    % Convert min xyLims
                    xyMinLimsDegs = [app.visualizedMosaicViewXLimDegs(1) app.visualizedMosaicViewYLimDegs(1)];
                    % Convert degs to microns
                    if (~isempty(app.components.coneMosaic.micronsPerDegreeApproximation))
                        xyMinLimsMicrons = xyMinLimsDegs * app.components.coneMosaic.micronsPerDegreeApproximation;  
                    else
                        xyMinLimsMicrons = 1e3 * RGCmodels.Watson.convert.rhoDegsToMMs(xyMinLimsDegs);
                    end
                    app.visualizedMosaicViewXLimMicrons(1) = xyMinLimsMicrons(1);
                    app.visualizedMosaicViewYLimMicrons(1) = xyMinLimsMicrons(2);
                    
                    % Convert max xyLims
                    xyMaxLimsDegs = [app.visualizedMosaicViewXLimDegs(2) app.visualizedMosaicViewYLimDegs(2)];
                    % Convert degs to microns
                    if (~isempty(app.components.coneMosaic.micronsPerDegreeApproximation))
                        xyMaxLimsMicrons = xyMaxLimsDegs * app.components.coneMosaic.micronsPerDegreeApproximation;  
                    else
                        xyMaxLimsMicrons = 1e3 * RGCmodels.Watson.convert.rhoDegsToMMs(xyMaxLimsDegs);
                    end
                    app.visualizedMosaicViewXLimMicrons(2) = xyMaxLimsMicrons(1);
                    app.visualizedMosaicViewYLimMicrons(2) = xyMaxLimsMicrons(2);
                    
                case 'deg'
                    % Convert min xyLims
                    xyMinLimsMicrons = [app.visualizedMosaicViewXLimMicrons(1) app.visualizedMosaicViewYLimMicrons(1)];
                    % Convert microns to degs
                    if (~isempty(app.components.coneMosaic.micronsPerDegreeApproximation))
                        xyMinLimsDegs = xyMinLimsMicrons / app.components.coneMosaic.micronsPerDegreeApproximation;  
                    else
                        xyMinLimsDegs = RGCmodels.Watson.convert.rhoMMsToDegs(xyMinLimsMicrons*1e-3);
                    end
                    app.visualizedMosaicViewXLimDegs(1) = xyMinLimsDegs(1);
                    app.visualizedMosaicViewYLimDegs(1) = xyMinLimsDegs(2);
                    
                    % Convert max xyLims
                    xyMaxLimsMicrons = [app.visualizedMosaicViewXLimMicrons(2) app.visualizedMosaicViewYLimMicrons(2)];
                    % Convert microns to degs
                    if (~isempty(app.components.coneMosaic.micronsPerDegreeApproximation))
                        xyMaxLimsDegs = xyMaxLimsMicrons / app.components.coneMosaic.micronsPerDegreeApproximation;  
                    else
                        xyMaxLimsDegs = RGCmodels.Watson.convert.rhoMMsToDegs(xyMaxLimsMicrons*1e-3);
                    end
                    app.visualizedMosaicViewXLimDegs(2) = xyMaxLimsDegs(1);
                    app.visualizedMosaicViewYLimDegs(2) = xyMaxLimsDegs(2);
            end
         
            app.updateMosaicView();
        end

        % Value changing function: fieldofviewdegsSlider
        function sizeDegsSliderContinuousUpdate(app, event)
            app.decodeSizeDegsSlider(event.Value);
            app.updateMosaicSizeSliderText();
            app.updateVisualFieldView();
        end

        % Value changed function: spatialFrequencySpinner
        function stimSpatialFrequencyChanged(app, event)
            app.stimSpatialFrequencyCyclesPerDeg = app.spatialFrequencySpinner.Value;
            app.refreshStimulus();
        end

        % Value changed function: envelopeDropDown
        function stimEnvelopeChanged(app, event)
            app.stimSpatialEnvelope = app.envelopeDropDown.Value;
            app.refreshStimulus();
        end

        % Value changed function: slope_minSpinner
        function minPsychometricSlopeChanged(app, event)
            app.psychometricFunctionMinSlope = app.slope_minSpinner.Value;
            app.updatePsychometricFunctionView();
        end

        % Value changed function: slope_maxSpinner
        function maxPsychometricSlopeChanged(app, event)
            app.psychometricFunctionMaxSlope = app.slope_maxSpinner.Value;
            app.updatePsychometricFunctionView();
        end

        % Value changed function: log10C_minSpinner
        function psychometricFunctionMinLogThresholdChanged(app, event)
            app.psychometricFunctionMinLogThreshold = app.log10C_minSpinner.Value;
            app.updatePsychometricFunctionView();
        end

        % Value changed function: log10C_maxSpinner
        function psychometricFunctionMaxLogThresholdChanged(app, event)
            app.psychometricFunctionMaxLogThreshold = app.log10C_maxSpinner.Value;
            app.updatePsychometricFunctionView();
        end

        % Value changed function: sfMinSpinner
        function minSpatialFrequencyChanged(app, event)
            app.csfMinSpatialFrequency = app.sfMinSpinner.Value;
            app.updateCSFView();
        end

        % Value changed function: sfMaxSpinner
        function maxSpatialFrequencyChanged(app, event)
            app.csfMaxSpatialFrequency = app.sfMaxSpinner.Value;
            app.updateCSFView();
        end

        % Value changed function: sfSamplesSpinner
        function samplesSpatialFrequencyChanged(app, event)
            app.csfSamplesSpatialFrequency = app.sfSamplesSpinner.Value;
            app.updateCSFView();
        end

        % Button pushed function: computeCSFButton
        function computeCSFButtonPressed(app, event)
            
            % Compute CSF with current settings
            sfSupport = app.decodeCSFsupport(); 
            
            % Assemble CSF computation pipeline
            app.exportedPipelineData = app.assembleCSFcomputePipeline();
                       
            % Generate the neural and classifier engines
            [theNeuralEngine, theClassifierEngine] = CSFgenerator.generateNeuralAndClassifierEngines(app.exportedPipelineData);
            
        
            % Run CSF computation for all spatial frequencies
            for idx = 1:length(sfSupport)
                
                app.statusEditField.Value = sprintf('Computing psychometric function for %2.2f c/deg (condition %d of %d) ...', sfSupport(idx), idx, length(sfSupport));
                pause(0.01);
                
                % Compute current stimulus params
                [sParams, app.exportedStimData] = app.assembleStimParams(sfSupport(idx));
                
                % Call the external CSFgenerator.computeStimulusSceneEngine method to
                % generate the gratingSceneEngine for the current stimulus params
                [~, ~, ~, gratingSceneEngine] = CSFgenerator.computeStimulusSceneEngine(sParams, []);
                    
               
                % Compute the threshold for our grating scene with the previously
                % defined neural and classifier engine. 
                [logThresholdData, questObj] = ...
                    computeThresholdTAFC(gratingSceneEngine, theNeuralEngine, theClassifierEngine, ...
                                         app.exportedPipelineData.classifierEngineParams, ...
                                         app.exportedPipelineData.thresholdParams, ...
                                         app.exportedPipelineData.questEngineParams, ...
                                         'beVerbose', false);
                
                % Call the thresholdMLE() method of the questOBJ to update
                % the current psychometric function data
                [~, ~, currentPsychometricFunctionData{idx}] = questObj.thresholdMLE(...
                    'showPlot',  false, 'newFigure', false, ...
                    'returnData', true);

                % Depict the psychometric function
                app.updatePsychometricFunctionView('withData', currentPsychometricFunctionData);
                
                % Update the current csfData
                currentCSFData{idx} = struct(...
                     'spatialFrequency', sfSupport(idx), ...
                     'sensitivity', 1/(10^logThresholdData));

                % Update CSFView
                app.updateCSFView('withData', currentCSFData);
                pause(0.01);
            end
            
            % Reset the last mean activation
            app.lastMeanActivation = [];
            
            % Update status field
            app.statusEditField.Value = 'Ready !';
            
            % CSF data to export
            app.exportedCSFData.psychometricData = currentPsychometricFunctionData;
            app.exportedCSFData.csf = currentCSFData;
        end

        % Value changed function: integrationtimemsecSpinner
        function mosaicIntegrationTimeChanged(app, event)
            app.mosaicIntegrationTimeMseconds = app.integrationtimemsecSpinner.Value;
            app.updateMosaicIntegrationTime();
            if strcmp(app.mosaicDisplayMode, 'activation') || strcmp(app.mosaicDisplayMode, 'modulation')
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
        end

        % Value changed function: log10C_deltaSpinner
        function psychometricFunctionDeltaLogThresholdChanged(app, event)
            app.psychometricFunctionDeltaLogThreshold = app.log10C_deltaSpinner.Value;
        end

        % Value changed function: slope_deltaSpinner
        function deltaPsychometricSlopeChanged(app, event)
            app.psychometricFunctionDeltaSlope = app.slope_deltaSpinner.Value;
        end

        % Value changed function: testtrialsSpinner
        function performanceTrialsChanged(app, event)
            app.psychometricFunctionPerformanceTrialsNum = app.testtrialsSpinner.Value;
        end

        % Value changed function: examinedcontrastlevelsSpinner
        function contrastLevelsChanged(app, event)
            app.psychometricFunctionContrastLevelsNum = app.examinedcontrastlevelsSpinner.Value;
        end

        % Value changed function: coneMosaicViewModeKnob
        function coneMosaicViewModeChanged(app, event)
            app.mosaicDisplayMode = app.coneMosaicViewModeKnob.Value;
            
            if (~strcmp(app.mosaicDisplayMode, 'cone types'))
               if (strcmp(app.mosaicDisplayMode, 'residual, (c-p)/p'))
                   app.lastMeanActivation = [];
               end
               app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
        end

        % Button pushed function: regenerateButton
        function regenerateMosaicPressed(app, event)
            app.mosaicConeDensities = [app.LconeDensitySpinner.Value app.MconeDensitySpinner.Value app.SconeDensitySpinner.Value];
            app.components.coneMosaic.coneDensities = app.mosaicConeDensities;
            % Reset the last mean activation
            app.lastMeanActivation = [];
            if strcmp(app.mosaicDisplayMode, 'activation') || strcmp(app.mosaicDisplayMode, 'modulation')
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
        end

        % Value changed function: tritanopicradiusdegSpinner
        function tritanopicRadiusChanged(app, event)
            app.mosaicTritanopicRadiusDegs = app.tritanopicradiusdegSpinner.Value;
            app.components.coneMosaic.tritanopicRadiusDegs = app.mosaicTritanopicRadiusDegs;
            if strcmp(app.mosaicDisplayMode, 'activation') || strcmp(app.mosaicDisplayMode, 'modulation')
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
        end

        % Value changed function: meanOrNoisyInstance
        function visualizedResponseTypeChanged(app, event)
            app.visualizedResponseType = app.meanOrNoisyInstance.Value;
            if strcmp(app.mosaicDisplayMode, 'activation') || strcmp(app.mosaicDisplayMode, 'modulation')
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
        end

        % Value changed function: xPosdegsSpinner
        function stimXPosDegsChanged(app, event)
            app.stimXposDegs = app.xPosdegsSpinner.Value;
            if (~strcmp(app.mosaicDisplayMode, 'cone types'))
                app.opticalImageIsStale = true;
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
        end

        % Value changed function: yPosdegsSpinner
        function stimYposDegsChanged(app, event)
            app.stimYposDegs = app.yPosdegsSpinner.Value;
            if (~strcmp(app.mosaicDisplayMode, 'cone types'))
                app.opticalImageIsStale = true;
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
        end

        % Value changed function: stimCenteredOnMosaicCheckBox
        function stimMosaicCenteredChanged(app, event)
            app.stimIsMosaicCentered = app.stimCenteredOnMosaicCheckBox.Value;
            if (app.stimIsMosaicCentered == 1)
                % Disable x,y controls
                set(app.xPosdegsSpinner, 'enable', 'off');
                set(app.yPosdegsSpinner, 'enable', 'off');
            else
                % Enable x,y controls
                set(app.xPosdegsSpinner, 'enable', 'on');
                set(app.yPosdegsSpinner, 'enable', 'on');
            end
            if (~strcmp(app.mosaicDisplayMode, 'cone types'))
                app.opticalImageIsStale = true;
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
        end

        % Value changed function: subtractcentralrefractionCheckBox
        function subtractCentralRefractionChanged(app, event)
            app.opticsPolansSubtractCentralRefraction = app.subtractcentralrefractionCheckBox.Value;
            
            % Recompute optics
            app.regenerateOptics();
            
            % Update displayed PSF
            app.updatePSFView();
            
            % Recompute response
            if (~strcmp(app.mosaicDisplayMode, 'cone types'))
                app.opticalImageIsStale = true;
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
            
            % No need to recompute optics - we just did it
            app.recomputeOptics = ~true;
        end

        % Value changed function: 
        % eccvaryingaperturelightgatheringCheckBox
        function eccVaryingApertureLightGatheringChanged(app, event)
            app.mosaicEccVaryingConeAperture = app.eccvaryingaperturelightgatheringCheckBox.Value;
            app.components.coneMosaic.eccVaryingConeAperture = (app.mosaicEccVaryingConeAperture == 1);
            
            % Recompute response
            if strcmp(app.mosaicDisplayMode, 'activation') || strcmp(app.mosaicDisplayMode, 'modulation') || strcmp(app.mosaicDisplayMode, 'residual, (c-p)/p')
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
        end

        % Value changed function: eccvaryingmacularpigmentCheckBox
        function eccVaryingMacularPigmentDensityChanged(app, event)
            app.mosaicEccVaryingMacularPigmentDensity = app.eccvaryingmacularpigmentCheckBox.Value;
            app.components.coneMosaic.eccVaryingMacularPigmentDensity = (app.mosaicEccVaryingMacularPigmentDensity == 1);
            
            % Recompute response
            if strcmp(app.mosaicDisplayMode, 'activation') || strcmp(app.mosaicDisplayMode, 'modulation') || strcmp(app.mosaicDisplayMode, 'residual, (c-p)/p')
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
            
        end

        % Value changed function: eccvaryingapertureblurCheckBox
        function eccVaryingApertureBlurChanged(app, event)
            app.mosaicEccVaryingConeBlur = app.eccvaryingapertureblurCheckBox.Value;
            app.components.coneMosaic.eccVaryingConeBlur = app.mosaicEccVaryingConeBlur;
            
            % Recompute response
            if strcmp(app.mosaicDisplayMode, 'activation') || strcmp(app.mosaicDisplayMode, 'modulation') || strcmp(app.mosaicDisplayMode, 'residual, (c-p)/p')
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
            
        end

        % Value changed function: 
        % eccvaryingoutersegmentlightgatheringCheckBox
        function eccVaryingOuterSegmentLightGatheringChanged(app, event)
            app.mosaicEccVaryingOuterSegmentLength = app.eccvaryingoutersegmentlightgatheringCheckBox.Value;
            app.components.coneMosaic.eccVaryingOuterSegmentLength = app.mosaicEccVaryingOuterSegmentLength;
            app.components.coneMosaic.computeOuterSegmentLengthEccVariationAttenuationFactors('useParfor', true);
            
            % Recompute response
            if strcmp(app.mosaicDisplayMode, 'activation') || strcmp(app.mosaicDisplayMode, 'modulation') || strcmp(app.mosaicDisplayMode, 'residual, (c-p)/p')
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            app.updateMosaicView();
        end

        % Value changed function: overlayconesCheckBox
        function overlayConesChanged(app, event)
            app.overlayConesOnPSF = app.overlayconesCheckBox.Value;
            app.updatePSFView();
        end

        % Value changed function: visualizedwavelengthnmSpinner
        function visualizedWavelengthChanged(app, event)
            app.opticsVisualizedWavelength = app.visualizedwavelengthnmSpinner.Value;
            app.updatePSFView();
        end

        % Value changed function: resolutionpixelsSpinner
        function stimResolutionPixelsChanged(app, event)
            app.stimResolutionPixelsNum = app.resolutionpixelsSpinner.Value;  
            app.refreshStimulus();
        end

        % Value changed function: orientationSpinner
        function stimOrientationChanged(app, event)
            app.stimOrientationDegs = app.orientationSpinner.Value;
            app.refreshStimulus();
        end

        % Button pushed function: exportdataButton
        function exportDataButtonPressed(app, event)

            % Ask the use what data to export
            optionLabels = {'stimulus & compute pipeline'; ...
                            'stimulus, compute pipeline & CSF data'; ...
                            'cancel'};
            defaultOptionID = 1;
            cancelOptionID = 3;
            
            % Present export-selector dialog so that the user can select
            % what to export
            app.exportSelectorDialog(optionLabels, defaultOptionID, cancelOptionID);

            % Save the dataset
            switch (app.exportedDataSelection)
                case optionLabels{1}
                    % Generate struct with exported data
                    app.exportedPipelineData = app.assembleCSFcomputePipeline();
                    [~, app.exportedStimData] = app.assembleStimParams([]);
                    
                    exportedData = struct(...
                        'stimData', app.exportedStimData, ...
                        'pipeline', app.exportedPipelineData ...
                        );
                    
                    % Ask user for a filename and export the data
                    app.exportTheData(exportedData);
                    
                    
                case optionLabels{2}
                    % Generate struct with exported data
                    exportedData = struct(...
                        'stimData', app.exportedStimData, ...
                        'pipeline', app.exportedPipelineData, ...
                        'csfData', app.exportedCSFData ...
                        );
                    
                    if (isempty(app.exportedCSFData))
                        % Let the user know
                        message = sprintf('There have been no csf data computed yet. The exported ''csfData'' field will be empty.');
                        uialert(app.mainView, message,'Warning', 'Icon','warning');
                    end
                    
                    % Ask user for a filename and export the data
                    app.exportTheData(exportedData);

                case 'cancel'
                    % do nothing
            end
            
            
        end

        % Value changed function: lumcdm2Spinner
        function stimMeanLuminanceChanged(app, event)
            app.stimMeanLuminanceCdPerM2 = app.lumcdm2Spinner.Value;
            app.refreshStimulus();
        end

        % Value changed function: eyeSwitch
        function eyeChanged(app, event)
            app.mosaicEye = app.eyeSwitch.Value;
            regenerateMosaicAndOptics(app, [], 0, 1);
        end

        % Value changed function: retinalOrVisualFieldView
        function retinalOrVisualFieldViewChanged(app, event)
            app.mosaicVisualizationView = app.retinalOrVisualFieldView.Value;
            app.updateMosaicView();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainView = uifigure('Visible', 'off');
            app.mainView.Name = 'Interactive ISETBio CSF Generator, Written by N.P. Cottaris, ISETBio Team, 2021';
            app.mainView.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.mainView);
            app.LeftPanel = uipanel(app.GridLayout);
            app.statusPanel = uipanel(app.LeftPanel);
            app.statusPanel.Title = 'status';
            app.statusEditField = uieditfield(app.statusPanel, 'text');
            app.statusEditField.Editable = 'off';
            app.statusEditField.Value = 'ready';
            app.retinalpatchlocationinRightEyeVisualFieldREVFPanel = uipanel(app.LeftPanel);
            app.retinalpatchlocationinRightEyeVisualFieldREVFPanel.Title = 'retinal patch location in Right Eye Visual Field (REVF)';
            app.radialeccentricitydegsSliderLabel = uilabel(app.retinalpatchlocationinRightEyeVisualFieldREVFPanel);
            app.radialeccentricitydegsSliderLabel.Tag = 'eccntricitySliderText';
            app.radialeccentricitydegsSliderLabel.Text = 'radial eccentricity (degs)';
            app.radialeccentricitydegsSlider = uislider(app.retinalpatchlocationinRightEyeVisualFieldREVFPanel);
            app.radialeccentricitydegsSlider.Limits = [0 8];
            app.radialeccentricitydegsSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.radialeccentricitydegsSlider.MajorTickLabels = {'0', '.15', '.3', '.6', '1.2', '2.5', '5', '10', '20'};
            app.radialeccentricitydegsSlider.ValueChangedFcn = createCallbackFcn(app, @radialeccentricitydegsSliderUpdated, true);
            app.radialeccentricitydegsSlider.ValueChangingFcn = createCallbackFcn(app, @radialeccentricitySliderContinuousUpdate, true);
            app.radialeccentricitydegsSlider.MinorTicks = [];
            app.radialeccentricitydegsSlider.Value = 1;
            app.polareccentricitydegsSlider = uiknob(app.retinalpatchlocationinRightEyeVisualFieldREVFPanel, 'continuous');
            app.polareccentricitydegsSlider.Limits = [0 360];
            app.polareccentricitydegsSlider.MajorTicks = [0 90 180 270 360];
            app.polareccentricitydegsSlider.MajorTickLabels = {'0', '90 ', '180 ', '270 ', '360', ''};
            app.polareccentricitydegsSlider.ValueChangedFcn = createCallbackFcn(app, @polareccentricitydegsSliderUpdated, true);
            app.polareccentricitydegsSlider.ValueChangingFcn = createCallbackFcn(app, @polareccentricitySliderContinuousUpdate, true);
            app.polareccentricitydegsSlider.MinorTicks = [];
            app.polareccentricitydegsSlider.Value = 360;
            app.fieldofviewdegsSliderLabel = uilabel(app.retinalpatchlocationinRightEyeVisualFieldREVFPanel);
            app.fieldofviewdegsSliderLabel.Text = 'field of view (degs)';
            app.fieldofviewdegsSlider = uislider(app.retinalpatchlocationinRightEyeVisualFieldREVFPanel);
            app.fieldofviewdegsSlider.Limits = [1 8];
            app.fieldofviewdegsSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.fieldofviewdegsSlider.MajorTickLabels = {'0.07 ', '0.15 ', '0.3 ', '0.6 ', '1.2', ' 2.5', ' 5', '10'};
            app.fieldofviewdegsSlider.ValueChangedFcn = createCallbackFcn(app, @sizeDegsSliderUpdated, true);
            app.fieldofviewdegsSlider.ValueChangingFcn = createCallbackFcn(app, @sizeDegsSliderContinuousUpdate, true);
            app.fieldofviewdegsSlider.MinorTicks = [];
            app.fieldofviewdegsSlider.Value = 4;
            app.eyeSwitch = uiswitch(app.retinalpatchlocationinRightEyeVisualFieldREVFPanel, 'slider');
            app.eyeSwitch.Items = {'left eye', 'right eye'};
            app.eyeSwitch.ValueChangedFcn = createCallbackFcn(app, @eyeChanged, true);
            app.eyeSwitch.Value = 'right eye';
            app.retinalmagnificationfactorumdegEditFieldLabel = uilabel(app.retinalpatchlocationinRightEyeVisualFieldREVFPanel);
            app.retinalmagnificationfactorumdegEditFieldLabel.Text = 'retinal magnification factor (um/deg) ';
            app.retinalmagnificationfactorumdegEditField = uieditfield(app.retinalpatchlocationinRightEyeVisualFieldREVFPanel, 'numeric');
            app.retinalmagnificationfactorumdegEditField.Editable = 'off';
            app.retinalmagnificationfactorumdegEditField.Tooltip = {'retinal magnification factor at current eccentricity'};
            app.visualFieldView = uiaxes(app.retinalpatchlocationinRightEyeVisualFieldREVFPanel);
            xlabel(app.visualFieldView, 'X')
            ylabel(app.visualFieldView, 'Y')
            zlabel(app.visualFieldView, 'Z')
            app.tasksPanel = uipanel(app.LeftPanel);
            app.tasksPanel.Title = 'tasks';
            app.computeCSFButton = uibutton(app.tasksPanel, 'push');
            app.computeCSFButton.ButtonPushedFcn = createCallbackFcn(app, @computeCSFButtonPressed, true);
            app.computeCSFButton.Text = 'compute CSF';
            app.exportdataButton = uibutton(app.tasksPanel, 'push');
            app.exportdataButton.ButtonPushedFcn = createCallbackFcn(app, @exportDataButtonPressed, true);
            app.exportdataButton.Text = 'export data';
            app.RightPanel = uipanel(app.GridLayout);
            app.responseclassifierpsychometricfunctionestimationPanel = uipanel(app.RightPanel);
            app.responseclassifierpsychometricfunctionestimationPanel.Title = 'response classifier & psychometric function estimation';
            app.slope_minSpinnerLabel = uilabel(app.responseclassifierpsychometricfunctionestimationPanel);
            app.slope_minSpinnerLabel.Text = 'slope_min ';
            app.slope_minSpinner = uispinner(app.responseclassifierpsychometricfunctionestimationPanel);
            app.slope_minSpinner.Limits = [1 100];
            app.slope_minSpinner.ValueChangedFcn = createCallbackFcn(app, @minPsychometricSlopeChanged, true);
            app.slope_minSpinner.Value = 8;
            app.slope_maxSpinnerLabel = uilabel(app.responseclassifierpsychometricfunctionestimationPanel);
            app.slope_maxSpinnerLabel.Text = 'slope_max ';
            app.slope_maxSpinner = uispinner(app.responseclassifierpsychometricfunctionestimationPanel);
            app.slope_maxSpinner.Step = 10;
            app.slope_maxSpinner.Limits = [10 10000];
            app.slope_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @maxPsychometricSlopeChanged, true);
            app.slope_maxSpinner.Value = 200;
            app.slope_deltaSpinnerLabel = uilabel(app.responseclassifierpsychometricfunctionestimationPanel);
            app.slope_deltaSpinnerLabel.Text = 'slope_delta ';
            app.slope_deltaSpinner = uispinner(app.responseclassifierpsychometricfunctionestimationPanel);
            app.slope_deltaSpinner.Limits = [1 100];
            app.slope_deltaSpinner.ValueChangedFcn = createCallbackFcn(app, @deltaPsychometricSlopeChanged, true);
            app.slope_deltaSpinner.Value = 2;
            app.log10C_minSpinnerLabel = uilabel(app.responseclassifierpsychometricfunctionestimationPanel);
            app.log10C_minSpinnerLabel.Text = 'log10C_min ';
            app.log10C_minSpinner = uispinner(app.responseclassifierpsychometricfunctionestimationPanel);
            app.log10C_minSpinner.Step = 0.1;
            app.log10C_minSpinner.Limits = [-4 0];
            app.log10C_minSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionMinLogThresholdChanged, true);
            app.log10C_minSpinner.Value = -4;
            app.log10C_maxSpinnerLabel = uilabel(app.responseclassifierpsychometricfunctionestimationPanel);
            app.log10C_maxSpinnerLabel.Text = 'log10C_max ';
            app.log10C_maxSpinner = uispinner(app.responseclassifierpsychometricfunctionestimationPanel);
            app.log10C_maxSpinner.Step = 0.1;
            app.log10C_maxSpinner.Limits = [-4 0];
            app.log10C_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionMaxLogThresholdChanged, true);
            app.log10C_deltaSpinnerLabel = uilabel(app.responseclassifierpsychometricfunctionestimationPanel);
            app.log10C_deltaSpinnerLabel.Text = 'log10C_delta ';
            app.log10C_deltaSpinner = uispinner(app.responseclassifierpsychometricfunctionestimationPanel);
            app.log10C_deltaSpinner.Step = 0.005;
            app.log10C_deltaSpinner.Limits = [0.01 0.1];
            app.log10C_deltaSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionDeltaLogThresholdChanged, true);
            app.log10C_deltaSpinner.Value = 0.02;
            app.examinedcontrastlevelsSpinnerLabel = uilabel(app.responseclassifierpsychometricfunctionestimationPanel);
            app.examinedcontrastlevelsSpinnerLabel.Text = 'examined contrast levels';
            app.examinedcontrastlevelsSpinner = uispinner(app.responseclassifierpsychometricfunctionestimationPanel);
            app.examinedcontrastlevelsSpinner.Limits = [1 16];
            app.examinedcontrastlevelsSpinner.ValueChangedFcn = createCallbackFcn(app, @contrastLevelsChanged, true);
            app.examinedcontrastlevelsSpinner.Value = 5;
            app.testtrialsSpinnerLabel = uilabel(app.responseclassifierpsychometricfunctionestimationPanel);
            app.testtrialsSpinnerLabel.Text = {'test trials'; ''};
            app.testtrialsSpinner = uispinner(app.responseclassifierpsychometricfunctionestimationPanel);
            app.testtrialsSpinner.Limits = [1 100000];
            app.testtrialsSpinner.ValueChangedFcn = createCallbackFcn(app, @performanceTrialsChanged, true);
            app.testtrialsSpinner.Value = 128;
            app.traintrialsLabel = uilabel(app.responseclassifierpsychometricfunctionestimationPanel);
            app.traintrialsLabel.Enable = 'off';
            app.traintrialsLabel.Text = 'train trials ';
            app.traintrialsSpinner = uispinner(app.responseclassifierpsychometricfunctionestimationPanel);
            app.traintrialsSpinner.Limits = [1 100000];
            app.traintrialsSpinner.Enable = 'off';
            app.traintrialsSpinner.Value = 1;
            app.classifierLabel = uilabel(app.responseclassifierpsychometricfunctionestimationPanel);
            app.classifierLabel.Text = 'classifier ';
            app.classifierDropDown = uidropdown(app.responseclassifierpsychometricfunctionestimationPanel);
            app.classifierDropDown.Items = {'ideal observer', 'SVM computational observer'};
            app.classifierDropDown.Value = 'ideal observer';
            app.psychometricFunctionView = uiaxes(app.responseclassifierpsychometricfunctionestimationPanel);
            xlabel(app.psychometricFunctionView, 'X')
            ylabel(app.psychometricFunctionView, 'y')
            zlabel(app.psychometricFunctionView, 'Z')
            app.psychometricFunctionView.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.conemosaicPanel = uipanel(app.RightPanel);
            app.conemosaicPanel.Title = 'cone mosaic';
            app.eccvaryingmacularpigmentCheckBox = uicheckbox(app.conemosaicPanel);
            app.eccvaryingmacularpigmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @eccVaryingMacularPigmentDensityChanged, true);
            app.eccvaryingmacularpigmentCheckBox.Text = ' ecc-varying macular pigment';
            app.eccvaryingmacularpigmentCheckBox.Value = true;
            app.eccvaryingaperturelightgatheringCheckBox = uicheckbox(app.conemosaicPanel);
            app.eccvaryingaperturelightgatheringCheckBox.ValueChangedFcn = createCallbackFcn(app, @eccVaryingApertureLightGatheringChanged, true);
            app.eccvaryingaperturelightgatheringCheckBox.Text = ' ecc-varying aperture (light gathering)';
            app.eccvaryingaperturelightgatheringCheckBox.Value = true;
            app.eccvaryingapertureblurCheckBox = uicheckbox(app.conemosaicPanel);
            app.eccvaryingapertureblurCheckBox.ValueChangedFcn = createCallbackFcn(app, @eccVaryingApertureBlurChanged, true);
            app.eccvaryingapertureblurCheckBox.Text = ' ecc-varying aperture blur';
            app.eccvaryingmacularpigmentdynamicCheckBox = uicheckbox(app.conemosaicPanel);
            app.eccvaryingmacularpigmentdynamicCheckBox.Enable = 'off';
            app.eccvaryingmacularpigmentdynamicCheckBox.Text = ' ecc-varying macular pigment (dynamic)';
            app.coneMosaicViewModeKnob = uiknob(app.conemosaicPanel, 'discrete');
            app.coneMosaicViewModeKnob.Items = {'cone types', 'cones & retinal image', 'modulation', 'activation', 'residual, (c-p)/p'};
            app.coneMosaicViewModeKnob.ValueChangedFcn = createCallbackFcn(app, @coneMosaicViewModeChanged, true);
            app.coneMosaicViewModeKnob.Value = 'cone types';
            app.domainSwitch = uiswitch(app.conemosaicPanel, 'slider');
            app.domainSwitch.Items = {'deg', 'um'};
            app.domainSwitch.ValueChangedFcn = createCallbackFcn(app, @mosaicDomainChanged, true);
            app.domainSwitch.Value = 'deg';
            app.LSpinnerLabel = uilabel(app.conemosaicPanel);
            app.LSpinnerLabel.Text = 'L';
            app.LconeDensitySpinner = uispinner(app.conemosaicPanel);
            app.LconeDensitySpinner.Step = 0.01;
            app.LconeDensitySpinner.Limits = [0 1];
            app.LconeDensitySpinner.Value = 0.62;
            app.MSpinnerLabel = uilabel(app.conemosaicPanel);
            app.MSpinnerLabel.Text = 'M';
            app.MconeDensitySpinner = uispinner(app.conemosaicPanel);
            app.MconeDensitySpinner.Step = 0.01;
            app.MconeDensitySpinner.Limits = [0 1];
            app.MconeDensitySpinner.Value = 0.31;
            app.SSpinnerLabel = uilabel(app.conemosaicPanel);
            app.SSpinnerLabel.Text = 'S';
            app.SconeDensitySpinner = uispinner(app.conemosaicPanel);
            app.SconeDensitySpinner.Step = 0.01;
            app.SconeDensitySpinner.Limits = [0 1];
            app.SconeDensitySpinner.Value = 0.07;
            app.coneratiosLabel = uilabel(app.conemosaicPanel);
            app.coneratiosLabel.Text = 'cone ratios';
            app.regenerateButton = uibutton(app.conemosaicPanel, 'push');
            app.regenerateButton.ButtonPushedFcn = createCallbackFcn(app, @regenerateMosaicPressed, true);
            app.regenerateButton.Text = {'regenerate'; ''};
            app.tritanopicradiusdegSpinnerLabel = uilabel(app.conemosaicPanel);
            app.tritanopicradiusdegSpinnerLabel.Text = 'tritanopic radius (deg) ';
            app.tritanopicradiusdegSpinner = uispinner(app.conemosaicPanel);
            app.tritanopicradiusdegSpinner.Step = 0.01;
            app.tritanopicradiusdegSpinner.Limits = [0 0.4];
            app.tritanopicradiusdegSpinner.ValueChangedFcn = createCallbackFcn(app, @tritanopicRadiusChanged, true);
            app.tritanopicradiusdegSpinner.Value = 0.15;
            app.meanOrNoisyInstance = uiswitch(app.conemosaicPanel, 'slider');
            app.meanOrNoisyInstance.Items = {'mean', 'instance'};
            app.meanOrNoisyInstance.ValueChangedFcn = createCallbackFcn(app, @visualizedResponseTypeChanged, true);
            app.meanOrNoisyInstance.Value = 'mean';
            app.eccvaryingoutersegmentlightgatheringCheckBox = uicheckbox(app.conemosaicPanel);
            app.eccvaryingoutersegmentlightgatheringCheckBox.ValueChangedFcn = createCallbackFcn(app, @eccVaryingOuterSegmentLightGatheringChanged, true);
            app.eccvaryingoutersegmentlightgatheringCheckBox.Text = ' ecc-varying outer segment (light gathering)';
            app.eccvaryingoutersegmentlightgatheringCheckBox.Value = true;
            app.retinalOrVisualFieldView = uiswitch(app.conemosaicPanel, 'slider');
            app.retinalOrVisualFieldView.Items = {'REVF', 'retinal view'};
            app.retinalOrVisualFieldView.ValueChangedFcn = createCallbackFcn(app, @retinalOrVisualFieldViewChanged, true);
            app.retinalOrVisualFieldView.Enable = 'off';
            app.retinalOrVisualFieldView.Value = 'REVF';
            app.mosaicView = uiaxes(app.conemosaicPanel);
            xlabel(app.mosaicView, 'x')
            ylabel(app.mosaicView, 'y')
            app.opticsPanel = uipanel(app.RightPanel);
            app.opticsPanel.Title = 'optics';
            app.PolanssubjectLabel = uilabel(app.opticsPanel);
            app.PolanssubjectLabel.Text = 'Polans subject';
            app.PolanssubjectSpinner = uispinner(app.opticsPanel);
            app.PolanssubjectSpinner.Limits = [1 10];
            app.PolanssubjectSpinner.ValueChangedFcn = createCallbackFcn(app, @PolansSubjectChanged, true);
            app.PolanssubjectSpinner.Value = 10;
            app.eccvaryingopticalblurgridCheckBox = uicheckbox(app.opticsPanel);
            app.eccvaryingopticalblurgridCheckBox.Enable = 'off';
            app.eccvaryingopticalblurgridCheckBox.Text = 'ecc-varying optical blur (grid)';
            app.xSpinnerLabel = uilabel(app.opticsPanel);
            app.xSpinnerLabel.Enable = 'off';
            app.xSpinnerLabel.Text = 'x ';
            app.xSpinner = uispinner(app.opticsPanel);
            app.xSpinner.Limits = [1 9];
            app.xSpinner.Enable = 'off';
            app.xSpinner.Value = 1;
            app.ySpinnerLabel = uilabel(app.opticsPanel);
            app.ySpinnerLabel.Enable = 'off';
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.opticsPanel);
            app.ySpinner.Limits = [1 9];
            app.ySpinner.Enable = 'off';
            app.ySpinner.Value = 1;
            app.pupildiametermmLabel = uilabel(app.opticsPanel);
            app.pupildiametermmLabel.Text = 'pupil diameter (mm) ';
            app.pupildiametermmSpinner = uispinner(app.opticsPanel);
            app.pupildiametermmSpinner.Step = 0.5;
            app.pupildiametermmSpinner.Limits = [1 4];
            app.pupildiametermmSpinner.ValueChangedFcn = createCallbackFcn(app, @pupilDiameterMMChanged, true);
            app.pupildiametermmSpinner.Value = 3;
            app.wavelengthnmSpinnerLabel = uilabel(app.opticsPanel);
            app.wavelengthnmSpinnerLabel.Text = 'wavelength (nm)';
            app.visualizedwavelengthnmSpinner = uispinner(app.opticsPanel);
            app.visualizedwavelengthnmSpinner.Step = 10;
            app.visualizedwavelengthnmSpinner.Limits = [300 800];
            app.visualizedwavelengthnmSpinner.ValueChangedFcn = createCallbackFcn(app, @visualizedWavelengthChanged, true);
            app.visualizedwavelengthnmSpinner.Value = 550;
            app.overlayconesCheckBox = uicheckbox(app.opticsPanel);
            app.overlayconesCheckBox.ValueChangedFcn = createCallbackFcn(app, @overlayConesChanged, true);
            app.overlayconesCheckBox.Text = ' overlay cones';
            app.subtractcentralrefractionCheckBox = uicheckbox(app.opticsPanel);
            app.subtractcentralrefractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @subtractCentralRefractionChanged, true);
            app.subtractcentralrefractionCheckBox.Text = ' subtract central refraction';
            app.actualVisualizedWavelengthField = uieditfield(app.opticsPanel, 'text');
            app.actualVisualizedWavelengthField.Editable = 'off';
            app.actualLabel = uilabel(app.opticsPanel);
            app.actualLabel.Text = 'actual';
            app.psfView = uiaxes(app.opticsPanel);
            xlabel(app.psfView, 'X')
            ylabel(app.psfView, 'y')
            zlabel(app.psfView, 'Z')
            app.stimulusPanel = uipanel(app.RightPanel);
            app.stimulusPanel.Title = 'stimulus';
            app.cLSpinnerLabel = uilabel(app.stimulusPanel);
            app.cLSpinnerLabel.Text = 'L-cont. (%) ';
            app.cMSpinner = uispinner(app.stimulusPanel);
            app.cMSpinner.Step = 0.5;
            app.cMSpinner.Limits = [-100 100];
            app.cMSpinner.ValueChangedFcn = createCallbackFcn(app, @stimMcontChanged, true);
            app.cMSpinner.Value = 80;
            app.cSSpinner = uispinner(app.stimulusPanel);
            app.cSSpinner.Step = 0.5;
            app.cSSpinner.Limits = [-100 100];
            app.cSSpinner.ValueChangedFcn = createCallbackFcn(app, @stimScontChanged, true);
            app.cSSpinner.Value = 80;
            app.cLSpinner = uispinner(app.stimulusPanel);
            app.cLSpinner.Step = 0.5;
            app.cLSpinner.Limits = [-100 100];
            app.cLSpinner.ValueChangedFcn = createCallbackFcn(app, @stimLcontChanged, true);
            app.cLSpinner.Value = 80;
            app.McontLabel = uilabel(app.stimulusPanel);
            app.McontLabel.Text = 'M-cont. (%) ';
            app.ScontLabel = uilabel(app.stimulusPanel);
            app.ScontLabel.Text = 'S-cont. (%) ';
            app.w_minSpinner = uispinner(app.stimulusPanel);
            app.w_minSpinner.Step = 10;
            app.w_minSpinner.Limits = [300 800];
            app.w_minSpinner.ValueChangedFcn = createCallbackFcn(app, @minWavelengthChanged, true);
            app.w_minSpinner.Value = 400;
            app.w_maxSpinner = uispinner(app.stimulusPanel);
            app.w_maxSpinner.Step = 10;
            app.w_maxSpinner.Limits = [400 800];
            app.w_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @maxWavelengthChanged, true);
            app.w_maxSpinner.Value = 700;
            app.w_stepSpinner = uispinner(app.stimulusPanel);
            app.w_stepSpinner.Step = 5;
            app.w_stepSpinner.Limits = [1 100];
            app.w_stepSpinner.ValueChangedFcn = createCallbackFcn(app, @stepWavelengthChanged, true);
            app.w_stepSpinner.Value = 50;
            app.w_minSpinnerLabel = uilabel(app.stimulusPanel);
            app.w_minSpinnerLabel.Text = 'w_min (nm) ';
            app.w_maxSpinnerLabel = uilabel(app.stimulusPanel);
            app.w_maxSpinnerLabel.Text = 'w_max (nm) ';
            app.w_stepSpinnerLabel = uilabel(app.stimulusPanel);
            app.w_stepSpinnerLabel.Text = 'w_step (nm) ';
            app.flashmsecSpinnerLabel = uilabel(app.stimulusPanel);
            app.flashmsecSpinnerLabel.Enable = 'off';
            app.flashmsecSpinnerLabel.Text = 'flash (msec) ';
            app.flashmsecSpinner = uispinner(app.stimulusPanel);
            app.flashmsecSpinner.Step = 10;
            app.flashmsecSpinner.Limits = [10 1000];
            app.flashmsecSpinner.ValueChangedFcn = createCallbackFcn(app, @stimDurationChanged, true);
            app.flashmsecSpinner.Enable = 'off';
            app.flashmsecSpinner.Value = 100;
            app.sizedegsSpinnerLabel = uilabel(app.stimulusPanel);
            app.sizedegsSpinnerLabel.Text = 'size (degs) ';
            app.sizedegsSpinner = uispinner(app.stimulusPanel);
            app.sizedegsSpinner.Step = 0.5;
            app.sizedegsSpinner.Limits = [0.1 60];
            app.sizedegsSpinner.ValueChangedFcn = createCallbackFcn(app, @stimSizeDegsChanged, true);
            app.sizedegsSpinner.Value = 0.5;
            app.updatestimulusButton = uibutton(app.stimulusPanel, 'push');
            app.updatestimulusButton.ButtonPushedFcn = createCallbackFcn(app, @stimRefreshupdatestimulusButtonPushed, true);
            app.updatestimulusButton.Text = 'update stimulus';
            app.sfcdegLabel = uilabel(app.stimulusPanel);
            app.sfcdegLabel.Text = 'sf (c/deg) ';
            app.spatialFrequencySpinner = uispinner(app.stimulusPanel);
            app.spatialFrequencySpinner.Step = 0.5;
            app.spatialFrequencySpinner.Limits = [0 100];
            app.spatialFrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @stimSpatialFrequencyChanged, true);
            app.spatialFrequencySpinner.Value = 5;
            app.stimulusGenerationStatusReport = uieditfield(app.stimulusPanel, 'text');
            app.stimulusGenerationStatusReport.Editable = 'off';
            app.yPosdegsSpinnerLabel = uilabel(app.stimulusPanel);
            app.yPosdegsSpinnerLabel.Enable = 'off';
            app.yPosdegsSpinnerLabel.Text = 'yPos (degs) ';
            app.yPosdegsSpinner = uispinner(app.stimulusPanel);
            app.yPosdegsSpinner.ValueChangedFcn = createCallbackFcn(app, @stimYposDegsChanged, true);
            app.yPosdegsSpinner.Enable = 'off';
            app.xPosdegsSpinner_2Label = uilabel(app.stimulusPanel);
            app.xPosdegsSpinner_2Label.Enable = 'off';
            app.xPosdegsSpinner_2Label.Text = 'xPos (degs) ';
            app.xPosdegsSpinner = uispinner(app.stimulusPanel);
            app.xPosdegsSpinner.ValueChangedFcn = createCallbackFcn(app, @stimXPosDegsChanged, true);
            app.xPosdegsSpinner.Enable = 'off';
            app.envelopeLabel = uilabel(app.stimulusPanel);
            app.envelopeLabel.Text = 'envelope ';
            app.envelopeDropDown = uidropdown(app.stimulusPanel);
            app.envelopeDropDown.Items = {'rect', 'disk', 'soft'};
            app.envelopeDropDown.ValueChangedFcn = createCallbackFcn(app, @stimEnvelopeChanged, true);
            app.envelopeDropDown.Value = 'rect';
            app.sizecyclesSpinnerLabel = uilabel(app.stimulusPanel);
            app.sizecyclesSpinnerLabel.Enable = 'off';
            app.sizecyclesSpinnerLabel.Text = 'size (cycles) ';
            app.sizecyclesSpinner = uispinner(app.stimulusPanel);
            app.sizecyclesSpinner.Step = 0.5;
            app.sizecyclesSpinner.Limits = [0 10];
            app.sizecyclesSpinner.Enable = 'off';
            app.stimCenteredOnMosaicCheckBox = uicheckbox(app.stimulusPanel);
            app.stimCenteredOnMosaicCheckBox.ValueChangedFcn = createCallbackFcn(app, @stimMosaicCenteredChanged, true);
            app.stimCenteredOnMosaicCheckBox.Text = ' mosaic - centered';
            app.stimCenteredOnMosaicCheckBox.Value = true;
            app.resolutionpixelsLabel = uilabel(app.stimulusPanel);
            app.resolutionpixelsLabel.Text = 'resolution (pixels) ';
            app.resolutionpixelsSpinner = uispinner(app.stimulusPanel);
            app.resolutionpixelsSpinner.Step = 64;
            app.resolutionpixelsSpinner.Limits = [64 2048];
            app.resolutionpixelsSpinner.ValueChangedFcn = createCallbackFcn(app, @stimResolutionPixelsChanged, true);
            app.resolutionpixelsSpinner.Value = 128;
            app.orientationSpinnerLabel = uilabel(app.stimulusPanel);
            app.orientationSpinnerLabel.Text = 'orientation ';
            app.orientationSpinner = uispinner(app.stimulusPanel);
            app.orientationSpinner.ValueChangedFcn = createCallbackFcn(app, @stimOrientationChanged, true);
            app.orientationSpinner.Value = 90;
            app.lumcdm2SpinnerLabel = uilabel(app.stimulusPanel);
            app.lumcdm2SpinnerLabel.Text = 'lum (cd/m2) ';
            app.lumcdm2Spinner = uispinner(app.stimulusPanel);
            app.lumcdm2Spinner.Step = 10;
            app.lumcdm2Spinner.Limits = [0 1000];
            app.lumcdm2Spinner.ValueChangedFcn = createCallbackFcn(app, @stimMeanLuminanceChanged, true);
            app.lumcdm2Spinner.Value = 60;
            app.stimulusView = uiaxes(app.stimulusPanel);
            app.stimulusView.XTick = [];
            app.stimulusView.XTickLabel = '';
            app.stimulusView.YTick = [];
            app.stimulusView.YTickLabel = '';
            app.contrastsensitivityfunctionPanel = uipanel(app.RightPanel);
            app.contrastsensitivityfunctionPanel.Title = 'contrast sensitivity function';
            app.sfSamplesSpinnerLabel = uilabel(app.contrastsensitivityfunctionPanel);
            app.sfSamplesSpinnerLabel.Text = 'samples';
            app.sfSamplesSpinner = uispinner(app.contrastsensitivityfunctionPanel);
            app.sfSamplesSpinner.Limits = [1 16];
            app.sfSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @samplesSpatialFrequencyChanged, true);
            app.sfSamplesSpinner.Value = 4;
            app.sfMaxLabel = uilabel(app.contrastsensitivityfunctionPanel);
            app.sfMaxLabel.Text = 'sf_max ';
            app.sfMaxSpinner = uispinner(app.contrastsensitivityfunctionPanel);
            app.sfMaxSpinner.Step = 5;
            app.sfMaxSpinner.Limits = [5 100];
            app.sfMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @maxSpatialFrequencyChanged, true);
            app.sfMaxSpinner.Value = 60;
            app.sfMinLabel = uilabel(app.contrastsensitivityfunctionPanel);
            app.sfMinLabel.Text = 'sf_min ';
            app.sfMinSpinner = uispinner(app.contrastsensitivityfunctionPanel);
            app.sfMinSpinner.Step = 0.5;
            app.sfMinSpinner.Limits = [0.5 50];
            app.sfMinSpinner.ValueChangedFcn = createCallbackFcn(app, @minSpatialFrequencyChanged, true);
            app.sfMinSpinner.Value = 2;
            app.csfView = uiaxes(app.contrastsensitivityfunctionPanel);
            xlabel(app.csfView, 'X')
            ylabel(app.csfView, 'Y')
            zlabel(app.csfView, 'Z')
            app.fixationaleyemovementsPanel = uipanel(app.RightPanel);
            app.fixationaleyemovementsPanel.Title = 'fixational eye movements';
            app.eyemovementcomponentsDropDownLabel = uilabel(app.fixationaleyemovementsPanel);
            app.eyemovementcomponentsDropDownLabel.Enable = 'off';
            app.eyemovementcomponentsDropDownLabel.Text = 'eye movement components ';
            app.eyemovementcomponentsDropDown = uidropdown(app.fixationaleyemovementsPanel);
            app.eyemovementcomponentsDropDown.Items = {'none', 'drift', 'drift + uSaccades'};
            app.eyemovementcomponentsDropDown.Enable = 'off';
            app.eyemovementcomponentsDropDown.Value = 'none';
            app.integrationtimemsecLabel = uilabel(app.fixationaleyemovementsPanel);
            app.integrationtimemsecLabel.Text = 'integration time (msec) ';
            app.integrationtimemsecSpinner = uispinner(app.fixationaleyemovementsPanel);
            app.integrationtimemsecSpinner.Limits = [1 10000];
            app.integrationtimemsecSpinner.ValueChangedFcn = createCallbackFcn(app, @mosaicIntegrationTimeChanged, true);
            app.integrationtimemsecSpinner.Value = 100;
            % Show the figure after all components are created
            app.mainView.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISETBioCSFGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainView)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainView)
        end
    end
end