classdef RGC_MTF_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RGCMTFsimulatorNPCottarisISETBioTeamUIFigure  matlab.ui.Figure
        surroundradiusdegsSliderLabel  matlab.ui.control.Label
        surroundradiusdegsSlider       matlab.ui.control.Slider
        centersurroundpeaksensitivitySliderLabel  matlab.ui.control.Label
        centersurroundpeaksensitivitySlider  matlab.ui.control.Slider
        subjectPolans2015Spinner       matlab.ui.control.Spinner
        xdegsSpinnerLabel              matlab.ui.control.Label
        xdegsSpinner                   matlab.ui.control.Spinner
        ydegsSpinnerLabel              matlab.ui.control.Label
        ydegsSpinner                   matlab.ui.control.Spinner
        surroundoffsetLabel            matlab.ui.control.Label
        integratedsensitivtysurroundcenterEditField  matlab.ui.control.NumericEditField
        integratedsensitivitysurrwghtscenterwghtsLabel  matlab.ui.control.Label
        autoscalesurroundweightsCheckBox  matlab.ui.control.CheckBox
        centerSurroundPeakSensitivityNumberEditField  matlab.ui.control.NumericEditField
        surroundRadiusNumberEditField  matlab.ui.control.NumericEditField
        opticsTypeKnob                 matlab.ui.control.DiscreteKnob
        receptiveFieldView             matlab.ui.control.UIAxes
        MTFamplitudeView               matlab.ui.control.UIAxes
        psfInsetView                   matlab.ui.control.UIAxes
        responseTracesView             matlab.ui.control.UIAxes
        MTFphaseView                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mosaicEcc = [0 0];
        targetConeEcc = [0. 0];
        
        shouldRespondToMouseEvents = false;
        opticsType
        subjectRank
        surroundGain
        surroundRadius
        surroundOffsetXDegs
        surroundOffsetYDegs
        surroundOutlineX
        surroundOutlineY
        
        autoScaleSurroundWeights
        centerWeights
        surroundWeights
        targetConeIndex
        
        centerResponseTrace
        surroundResponseTrace
        mRGCResponseTrace
        visualizedSpatialFrequencyTraceIndex = 1
        
        weightsColormap
        examinedSFs
        MTF
        highightedMTFamplitudePlotHandle
        highightedMTFphasePlotHandle
        zeroResponseTracePlotHandle
        centerResponseTracePlotHandle
        surroundResponseTracePlotHandle 
        RGCResponseTracePlotHandle
        
        centerMFT
        surroundMFT
        coneMosaicSpatiotemporalActivation
        baserateActivation
        temporalSupportSeconds
        coneMosaic
        
        psfIsStale 
        psf
        psfSupportDegsX
        psfSupportDegsY
    end
    
    methods (Access = private)
        
        function loadSubjectData(app)
            
            dataSourceDir = strrep(isetRootPath, 'isettools','demoapps/RGCSFTuningSimulator/Resources');
            switch (app.opticsType)
                case 'Polans subject'
                    dataSourceFile = sprintf('PolansSubject%d_ecc_%2.1f_%2.1f_ConeMosaicDriftingSinewaveResponses.mat', app.subjectRank, app.mosaicEcc(1), app.mosaicEcc(2));
                case 'diffraction-limited'
                    dataSourceFile = sprintf('PolansSubject%d_ecc_%2.1f_%2.1f_ConeMosaicDriftingSinewaveResponses.mat', 0, app.mosaicEcc(1), app.mosaicEcc(2));
                case 'delta function'
                    dataSourceFile = sprintf('PolansSubject%d_ecc_%2.1f_%2.1f_ConeMosaicDriftingSinewaveResponses.mat', -1, app.mosaicEcc(1), app.mosaicEcc(2));
            end
            
            load(fullfile(dataSourceDir, dataSourceFile), ...
                'examinedSFs', ...
                'coneMosaicSpatiotemporalActivation', 'baserateActivation', ...
                'temporalSupportSeconds', 'theConeMosaic', 'psfData');
            
            % Change to a Gaussian-shaped aperture
            newConeApertureModifiers.smoothLocalVariations = true;
            %newConeApertureModifiers.shape = 'Pillowbox'
            newConeApertureModifiers.shape = 'Gaussian';
            newConeApertureModifiers.sigma = 0.204;
            theConeMosaic.coneApertureModifiers = newConeApertureModifiers;
     
            
            app.examinedSFs = examinedSFs;
            app.coneMosaicSpatiotemporalActivation = coneMosaicSpatiotemporalActivation;
            app.baserateActivation = baserateActivation;
            app.temporalSupportSeconds = temporalSupportSeconds;
            app.coneMosaic = theConeMosaic;
            
            % Extract the PSF
            app.psf = psfData.psf;
            app.psfSupportDegsX = psfData.psfSupportDegsX;
            app.psfSupportDegsY = psfData.psfSupportDegsY;
            app.psfIsStale = true;
        end
        
        function visualizeWeights(app)
            tic
            rf = app.surroundWeights;
            rf(app.centerWeights == 1) = 1;
    
            % Part of the mosaic visualized in the inset
            w = app.coneMosaic.sizeDegs(1);
            domainVisualizationLims(1:2) = app.coneMosaic.eccentricityDegs(1) + w/2*[-1 1];
            domainVisualizationLims(3:4) = app.coneMosaic.eccentricityDegs(2) + w/2*[-1 1];
            domainVisualizationTicks = struct('x',  [-0.1 0 0.1]+ app.coneMosaic.eccentricityDegs(1), 'y', [-0.1 0 0.1]+ app.coneMosaic.eccentricityDegs(2));
            
            if (app.autoScaleSurroundWeights)
                activationRange = abs(min(rf(:)))*[-1 1];
            else
                activationRange = 0.01*[-1 1];
            end
  
            app.coneMosaic.visualize('figureHandle', app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure, ...
                'axesHandle', app.receptiveFieldView, ...
                'activation', rf, ...
                'activationColorMap', app.weightsColormap, ...
                'activationRange', activationRange, ...
                'domain', 'degrees', ...
                'domainVisualizationLimits', domainVisualizationLims, ...
                'domainVisualizationTicks', domainVisualizationTicks, ...
                'plotTitle', ' ');
            hold(app.receptiveFieldView, 'on')
            plot(app.receptiveFieldView, app.surroundOutlineX, app.surroundOutlineY, 'b--', 'LineWidth', 1.5); 
            
            fprintf('Visualizing cone weights took %f seconds\n', toc);
            % Visualize the PSF
            app.visualizePSF();
            
        end
        
        function visualizePSF(app)
            if (app.psfIsStale)
                tic
                
                visualizedFraction = 0.2;
                w = app.coneMosaic.sizeDegs(1);
                domainVisualizationLimsInset(1:2) = app.coneMosaic.eccentricityDegs(1) + visualizedFraction*w/2*[-1 1];
                domainVisualizationLimsInset(3:4) = app.coneMosaic.eccentricityDegs(2) + visualizedFraction*w/2*[-1 1];

                domainVisualizationTicks = struct('x',  [nan], 'y', [nan]);
                % Inset showing a high-res version of the mosaic and the PSF
                app.coneMosaic.visualize('figureHandle', app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure, ...
                    'axesHandle', app.psfInsetView, ...
                    'domain', 'degrees', ...
                    'domainVisualizationLimits', domainVisualizationLimsInset, ...
                    'domainVisualizationTicks', domainVisualizationTicks, ...
                    'labelCones', ~true, ...
                    'visualizedConeAperture', 'lightCollectingArea', ...
                    'noYLabel', true, ...
                    'noXLabel', true, ...
                    'backgroundColor', [0 0 0], ...
                    'plotTitle', ' ');
                
                hold(app.psfInsetView, 'on');
                cmap = brewermap(1024,'reds');
                alpha = 0.4;
        
                contourLineColor = [0.0 0.0 0.0];
                cMosaic.semiTransparentContourPlot(app.psfInsetView, app.psfSupportDegsX, app.psfSupportDegsY, app.psf, ...
                    0.02:0.1:0.95, cmap, alpha, contourLineColor);
                
                app.psfIsStale = false;
                fprintf('Visualizing PSF took %f seconds\n', toc);
            end
        end
        
        
        function computeWeights(app)
            
            tic
            % Find the cone at the target eccentricity
            r = sqrt(sum((bsxfun(@minus, app.coneMosaic.coneRFpositionsDegs, app.targetConeEcc)).^2,2));
            [~,app.targetConeIndex] = min(r);

            % Surround radius estimate based on eccentricity
            %mRGCeccDegs = sqrt(sum(theConeMosaic.coneRFpositionsDegs(targetConeIndex,:).^2,2));
            %surroundCharacteristicRadiusFromCroner = ...
            %    RGCmodels.CronerKaplan.constants.surroundCharacteristicRadiusFromFitToPandMcells(mRGCeccDegs);

            % Surround weights (Gaussian profile)
            surroundPos = app.coneMosaic.coneRFpositionsDegs(app.targetConeIndex,:) + ...
                         [app.surroundOffsetXDegs app.surroundOffsetYDegs];
            
            app.surroundOutlineX = surroundPos(1) + app.surroundRadius*cosd(0:10:360);
            app.surroundOutlineY = surroundPos(2) + app.surroundRadius*sind(0:10:360);
            
            d = bsxfun(@minus, app.coneMosaic.coneRFpositionsDegs, surroundPos);
            d = sqrt(sum(d.^2,2));
            if (app.surroundRadius > 0)
                surroundWeights = exp(-(d/app.surroundRadius).^2);
                % If weight < 1%, zero it
                surroundWeights(surroundWeights<1/100) = 0;

                % Scale so as to achieve desired peak sensitivity ratio
                maxW = max(surroundWeights(:));
                surroundWeights = surroundWeights / maxW;
            else 
                surroundWeights = 0 * d;
            end
    
            % Surround weights
            app.surroundWeights = -surroundWeights * app.surroundGain;
            
            % Center weights
            app.centerWeights = 0 * app.surroundWeights;
            app.centerWeights(app.targetConeIndex) = 1; 
            
            % Sum integrated center input (centerWeights weighted by cone area aperture.^2)
            apertureAreas = app.coneMosaic.coneRFspacingsDegs.^2;
            integratedCenterInput = dot(app.centerWeights, apertureAreas);
            integratedSurroundInput = abs(dot(app.surroundWeights, apertureAreas));
            app.integratedsensitivtysurroundcenterEditField.Value = integratedSurroundInput/integratedCenterInput;
            
            fprintf('Computing cone weights took %f seconds\n', toc);
        end
        
        function computeMTF(app)
            
            tic
            
             for iSF = 1:numel(app.examinedSFs) 
                 % Retriece the cone mosaic activation by sinusoidal
                 % stimulation with this spatial frequency
                 xtActivation = (squeeze(app.coneMosaicSpatiotemporalActivation(iSF,:,:)))';
                 
                 % The RF center response time series is that of the target
                 % cone index (simulating single cone RF center)
                 app.centerResponseTrace(iSF,:) = xtActivation(app.targetConeIndex,:);
                 
                 % Compute RF surround response trace
                 for t = 1:size(xtActivation,2)
                    % spatial summation of cone responses weighted by the
                    % RF surround weights
                    app.surroundResponseTrace(iSF,t) = dot(app.surroundWeights, squeeze(xtActivation(:,t)),1);
                 end
   
                 % Compute mRGCresponse trace
                 app.mRGCResponseTrace(iSF,:) = app.centerResponseTrace(iSF,:) + app.surroundResponseTrace(iSF,:);
                 
                 % Compute MTFs (amplitude and phase)
                 app.centerMFT(iSF,:) = modulationAmplitudeAndPhase(app, squeeze(app.centerResponseTrace(iSF,:)));
                 app.surroundMFT(iSF,:) = modulationAmplitudeAndPhase(app, squeeze(app.surroundResponseTrace(iSF,:)));
                 app.MTF(iSF,:) = modulationAmplitudeAndPhase(app, squeeze(app.mRGCResponseTrace(iSF,:)));
                 
             end
             
             % Unwrap phase
             app.MTF(:,2) = unwrap(app.MTF(:,2)/180*pi,1)/pi*180;
             if (max(app.MTF(:,2)) > 180)
                app.MTF(:,2) = app.MTF(:,2) - 360;
             elseif (min(app.MTF(:,2)) <-180)
                 app.MTF(:,2) = app.MTF(:,2) + 360;
             end
             
             fprintf('Computing MTF took %f seconds\n', toc);
        end
        
        function mtf = modulationAmplitudeAndPhase(app, response)
            
            stimulusTemporalFrequencyHz = 4.0;
            sinFunction = @(params,time)(params(3) + params(1) * cos(2.0*pi*stimulusTemporalFrequencyHz*app.temporalSupportSeconds - params(2)));
            opts.RobustWgtFun = []; %'talwar';
            opts.MaxIter = 1000;
        
            initialParams(1) = max(abs(response))-mean(response); % amplitude
            initialParams(2) = 0.0; % phase
            initialParams(3) = mean(response);  % mean
            fittedParams = nlinfit(app.temporalSupportSeconds ,response, sinFunction, initialParams, opts);
            if (fittedParams(1)<0)
                fittedParams(1) = -fittedParams(1);
                fittedParams(2) = fittedParams(2) + pi;
            end
            
            mtf = [fittedParams(1) fittedParams(2)/pi*180];
        end
        
        function plotMTF(app)
            tic
            
            RGC_MTFamplitude = app.MTF(:,1);
            RGC_MTFphase = app.MTF(:,2);
            
            surround_MTFamplitude = app.surroundMFT(:,1);
            center_MTFamplitude = app.centerMFT(:,1);
            
            maxTuning = max(surround_MTFamplitude)*1.15;
            if (max(center_MTFamplitude)) > maxTuning
                maxTuning = 1.1*max(center_MTFamplitude);
            end
            
            if (maxTuning>1000)
                yTicks = round([0 0.25*maxTuning 0.5*maxTuning 0.75*maxTuning maxTuning]/100)*100;
            elseif (maxTuning>100)
                yTicks = round([0 0.25*maxTuning 0.5*maxTuning 0.75*maxTuning maxTuning]/10)*10;
            elseif (maxTuning>10)
                yTicks = round([0 0.25*maxTuning 0.5*maxTuning 0.75*maxTuning maxTuning]);
            else
                yTicks = [0 0.25*maxTuning 0.5*maxTuning 0.75*maxTuning maxTuning];
            end
            
            % Compute the amount of low-frequency attenuation
            [peakSFTuning, idx] = max(RGC_MTFamplitude);
            minSFTuning = RGC_MTFamplitude(1);
            lowFrequencyAttenuation = 100-100*minSFTuning/peakSFTuning;
            
            % The RGC MTF (just the thick line)
            plot(app.MTFamplitudeView, app.examinedSFs, RGC_MTFamplitude, 'k-', 'LineWidth', 3, 'Color', [0 0 1]);
            plot(app.MTFphaseView, app.examinedSFs, RGC_MTFphase, 'ko-', 'LineWidth', 2, 'Color', [0 0 0], 'MarkerSize', 16, 'MarkerFaceColor', [0.8 0.8 0.8]);
            hold(app.MTFamplitudeView, 'on');
            hold(app.MTFphaseView, 'on');
            
            % The center MTF
            hC = app.makeShadedPlot(app.MTFamplitudeView, app.examinedSFs, center_MTFamplitude, [1 0.5 0.5], [1 0 0]);
            % The surround MTF
            hS = app.makeShadedPlot(app.MTFamplitudeView, app.examinedSFs, surround_MTFamplitude, [0.0 0.5 1], [0 0 1]);
            
            % The low-frequency attenuation
            plot(app.MTFamplitudeView, app.examinedSFs(end-1)*[1 1], [minSFTuning peakSFTuning], 'k-', 'Color', [0.4 0.4 0.4], 'LineWidth', 3.0);
            plot(app.MTFamplitudeView, [app.examinedSFs(1) app.examinedSFs(end-1)], minSFTuning*[1 1], 'k--', 'Color', [0.4 0.4 0.4], 'LineWidth', 1.0);
            plot(app.MTFamplitudeView, [app.examinedSFs(1) app.examinedSFs(end-1)], peakSFTuning*[1 1], 'k--', 'Color', [0.4 0.4 0.4], 'LineWidth', 1.0);
            
            % The RGC MTF (points + thin line)
            hRGC = plot(app.MTFamplitudeView, app.examinedSFs, RGC_MTFamplitude, 'o-', 'MarkerEdgeColor', [0.3 0.3 0.3], 'LineWidth', 2, 'MarkerSize', 20, 'MarkerFaceColor', [0.8 0.8 0.8]);
            
            % The currently highlighted MTF amplitude point
            app.highightedMTFamplitudePlotHandle = plot(app.MTFamplitudeView, nan,nan, 'o', 'MarkerEdgeColor', [0.3 0.3 0.3], 'LineWidth', 2, 'MarkerSize', 20, 'MarkerFaceColor', [1 1 0.8]);
            
            % The currently highlighted MTF phase point
            app.highightedMTFphasePlotHandle = plot(app.MTFphaseView, nan,nan, 'o', 'MarkerEdgeColor', [0.3 0.3 0.3], 'LineWidth', 2, 'MarkerSize', 16, 'MarkerFaceColor', [1 1 0.8]);
            
            % Finalize
            text(app.MTFamplitudeView, app.examinedSFs(end-1), 0.5*(minSFTuning+peakSFTuning), sprintf(' %2.1f%%', lowFrequencyAttenuation), 'FontSize', 18, 'FontWeight', 'bold')
            hold(app.MTFamplitudeView, 'off');
            hold(app.MTFphaseView, 'off');
            legend(app.MTFamplitudeView, [hC, hS, hRGC],{'center', 'surround', 'RGC'}, 'Location', 'NorthOutside', 'Orientation', 'Horizontal');
            legend(app.MTFamplitudeView, 'boxoff'); 
            set(app.MTFamplitudeView, 'YLim', [0 yTicks(end)], 'YTick', yTicks, 'XLim', [min(app.examinedSFs) 120]);
            set(app.MTFphaseView, 'YLim', [-230 50]+max(RGC_MTFphase), 'YTick', -(180*6):45:(180*6), 'YTickLabel', -(180*6):45:(180*6), 'XLim', [min(app.examinedSFs) 120]);
            fprintf('Plotting MTF took %2.1f seconds\n', toc)
        end
        
        function h = makeShadedPlot(app,axesHandle, x,y, faceColor, edgeColor)
            px = reshape(x, [1 numel(x)]);
            py = reshape(y, [1 numel(y)]);
            px = [px(1) px px(end)];
            py = [1*eps py 2*eps];
            pz = -10*eps*ones(size(py)); 
            h = patch(px,py,pz,'FaceColor',faceColor,'EdgeColor',edgeColor, 'FaceAlpha', 0.3, 'LineWidth', 1.5, 'Parent', axesHandle);
        end
        

        function initializeViews(app)
            
            % Do not show the interactions toolbax
            app.MTFamplitudeView.Toolbar.Visible = 'off';
            app.MTFphaseView.Toolbar.Visible = 'off';
            app.receptiveFieldView.Toolbar.Visible = 'off';
            app.psfInsetView.Toolbar.Visible = 'off';
            app.responseTracesView.Toolbar.Visible = 'off';
            
            % No interactions
            app.MTFamplitudeView.Interactions = [];
            app.MTFphaseView.Interactions = [];
            app.receptiveFieldView.Interactions = [];
            app.responseTracesView.Interactions = [];
            
            % Weights colormap
            app.weightsColormap = brewermap(1024, '*RdBu');
            app.weightsColormap(515:end,:) = repmat([1 0.5 0.5], [510 1]);
            
            set(app.responseTracesView, 'Color', [1 1 1 0.9], 'XColor', [0.4 0.4 0.4], 'YColor', [0.4 0.4 0.4], 'YTick', 0, 'XTick', 0:0.1:1, 'XTickLabel', {}, 'YTickLabel', {}, 'FontSize', 14);
            grid(app.responseTracesView, 'on');
            box(app.responseTracesView, 'on');
            app.zeroResponseTracePlotHandle = plot(app.responseTracesView, nan, nan, 'k-', 'Color', [0.4 0.4 0.4], 'LineWidth', 1.0);
            hold(app.responseTracesView, 'on');
            app.centerResponseTracePlotHandle = plot(app.responseTracesView, nan, nan, 'r-', 'Color', [1 0.5 0.5], 'LineWidth', 2.0);
            app.surroundResponseTracePlotHandle = plot(app.responseTracesView, nan, nan, 'b-', 'Color', [0.5 0.5 1], 'LineWidth', 2.0);
            app.RGCResponseTracePlotHandle = plot(app.responseTracesView, nan, nan, 'k-', 'Color', [0.35 0.35 0.35], 'LineWidth', 2.0);
            hold(app.responseTracesView, 'off');
            %xlabel(app.responseTracesView, 'time (sec)');
            
            box(app.MTFamplitudeView, 'on');
        end
        
        function visualizeTraces(app)              
            idx = app.visualizedSpatialFrequencyTraceIndex;
            set(app.highightedMTFamplitudePlotHandle, 'XData', app.examinedSFs(idx), 'YData', app.MTF(idx,1));
            set(app.highightedMTFphasePlotHandle, 'XData', app.examinedSFs(idx), 'YData', app.MTF(idx,2));
            set(app.zeroResponseTracePlotHandle, 'XData', [app.temporalSupportSeconds(1) app.temporalSupportSeconds(end)], 'YData', [0 0]);
            set(app.RGCResponseTracePlotHandle, 'XData', app.temporalSupportSeconds, 'YData', app.mRGCResponseTrace(idx,:));
            set(app.centerResponseTracePlotHandle, 'XData', app.temporalSupportSeconds, 'YData', app.centerResponseTrace(idx,:));
            set(app.surroundResponseTracePlotHandle, 'XData', app.temporalSupportSeconds, 'YData', app.surroundResponseTrace(idx,:));
            maxR = max([max(abs(app.centerResponseTrace(:))) max(abs(app.surroundResponseTrace(:))) max(abs(app.mRGCResponseTrace(:)))]);
            set(app.responseTracesView, 'XLim', [app.temporalSupportSeconds(1) app.temporalSupportSeconds(end)], 'YLim', maxR*[-1 1], 'YTick', maxR*[-1 -0.5 0 0.5 1]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup(app)
            app.autoScaleSurroundWeights = app.autoscalesurroundweightsCheckBox.Value;
            app.opticsType = app.opticsTypeKnob.Value;
            app.subjectRank = app.subjectPolans2015Spinner.Value;
            app.surroundGain = 1/app.centersurroundpeaksensitivitySlider.Value; 
            app.centerSurroundPeakSensitivityNumberEditField.Value = app.centersurroundpeaksensitivitySlider.Value;
            app.surroundRadiusNumberEditField.Value = app.surroundradiusdegsSlider.Value;
            app.surroundRadius = app.surroundradiusdegsSlider.Value;
            app.surroundOffsetXDegs = app.xdegsSpinner.Value;
            app.surroundOffsetYDegs = app.ydegsSpinner.Value;
            app.initializeViews();
            app.loadSubjectData();
            app.computeWeights();
            app.visualizeWeights();
            app.computeMTF();
            app.plotMTF();
            app.visualizeTraces();
            app.shouldRespondToMouseEvents = true;
        end

        % Value changed function: subjectPolans2015Spinner
        function subjectIDChanged(app, event)
            app.subjectRank = app.subjectPolans2015Spinner.Value;
            app.loadSubjectData();
            app.visualizePSF();
            app.computeMTF();
            app.plotMTF();
            app.visualizeTraces();
        end

        % Value changed function: 
        % centersurroundpeaksensitivitySlider
        function surroundGainChanged(app, event)
            app.surroundGain  = 1/app.centersurroundpeaksensitivitySlider.Value;
            app.computeWeights();
            app.visualizeWeights();
            app.computeMTF();
            app.plotMTF();
            app.visualizeTraces();
        end

        % Value changed function: surroundradiusdegsSlider
        function surroundRadiusChanged(app, event)
            app.surroundRadius = app.surroundradiusdegsSlider.Value;
            app.computeWeights();
            app.visualizeWeights();
            app.computeMTF();
            app.plotMTF();
            app.visualizeTraces();
        end

        % Value changed function: xdegsSpinner
        function xdegsSpinnerValueChanged(app, event)
            app.surroundOffsetXDegs = app.xdegsSpinner.Value;
            app.computeWeights();
            app.visualizeWeights();
            app.computeMTF();
            app.plotMTF();
            app.visualizeTraces();
        end

        % Value changed function: ydegsSpinner
        function ydegsSpinnerValueChanged(app, event)
            app.surroundOffsetYDegs = app.ydegsSpinner.Value;
            app.computeWeights();
            app.visualizeWeights();
            app.computeMTF();
            app.plotMTF();
            app.visualizeTraces();
        end

        % Value changed function: autoscalesurroundweightsCheckBox
        function autoscalesurroundweightsCheckBoxValueChanged(app, event)
            app.autoScaleSurroundWeights = app.autoscalesurroundweightsCheckBox.Value;
            app.visualizeWeights();
        end

        % Value changing function: 
        % centersurroundpeaksensitivitySlider
        function centersurroundpeaksensitivitySliderValueChanging(app, event)
            app.centerSurroundPeakSensitivityNumberEditField.Value = event.Value;
        end

        % Value changing function: surroundradiusdegsSlider
        function surroundradiusdegsSliderValueChanging(app, event)
            app.surroundRadiusNumberEditField.Value = event.Value;
        end

        % Window button motion function: 
        % RGCMTFsimulatorNPCottarisISETBioTeamUIFigure
        function mouseMotion(app, event)
             if (app.shouldRespondToMouseEvents)
                 c = get (app.MTFamplitudeView, 'CurrentPoint');
                 if (c(1,1) >= app.examinedSFs(1)) && (c(1,1) <= app.examinedSFs(end))
                   
                    [~,idx] = min(abs(app.examinedSFs-c(1,1)));
                    idx = max([idx, 1]);
                    app.visualizedSpatialFrequencyTraceIndex = idx;
                    app.visualizeTraces();
                 end
             end
        end

        % Value changed function: opticsTypeKnob
        function opticsTypeKnobValueChanged(app, event)
            app.opticsType = app.opticsTypeKnob.Value;
            app.loadSubjectData();
            app.visualizePSF();
            app.computeMTF();
            app.plotMTF();
            app.visualizeTraces();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure = uifigure('Visible', 'off');
            app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure.Name = 'RGC MTF simulator  N.P. Cottaris, ISETBio Team';
            app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @mouseMotion, true);
            app.surroundradiusdegsSliderLabel = uilabel(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.surroundradiusdegsSliderLabel.Text = 'surround radius (degs)';
            app.surroundradiusdegsSlider = uislider(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.surroundradiusdegsSlider.Limits = [0.005 0.1];
            app.surroundradiusdegsSlider.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1 0.12 0.14 0.16 0.18 0.2];
            app.surroundradiusdegsSlider.ValueChangedFcn = createCallbackFcn(app, @surroundRadiusChanged, true);
            app.surroundradiusdegsSlider.ValueChangingFcn = createCallbackFcn(app, @surroundradiusdegsSliderValueChanging, true);
            app.surroundradiusdegsSlider.Value = 0.03;
            app.centersurroundpeaksensitivitySliderLabel = uilabel(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.centersurroundpeaksensitivitySliderLabel.Text = 'center/surround peak sensitivity';
            app.centersurroundpeaksensitivitySlider = uislider(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.centersurroundpeaksensitivitySlider.Limits = [5 400];
            app.centersurroundpeaksensitivitySlider.MajorTicks = [5 20 50 100 150 200 300 400];
            app.centersurroundpeaksensitivitySlider.MajorTickLabels = {'5', '20', '50', '100', '150', '200', '300', '400'};
            app.centersurroundpeaksensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @surroundGainChanged, true);
            app.centersurroundpeaksensitivitySlider.ValueChangingFcn = createCallbackFcn(app, @centersurroundpeaksensitivitySliderValueChanging, true);
            app.centersurroundpeaksensitivitySlider.Value = 80;
            app.subjectPolans2015Spinner = uispinner(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.subjectPolans2015Spinner.Limits = [1 10];
            app.subjectPolans2015Spinner.ValueChangedFcn = createCallbackFcn(app, @subjectIDChanged, true);
            app.subjectPolans2015Spinner.Value = 5;
            app.xdegsSpinnerLabel = uilabel(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.xdegsSpinnerLabel.Text = 'x (degs)';
            app.xdegsSpinner = uispinner(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.xdegsSpinner.Step = 0.001;
            app.xdegsSpinner.Limits = [-0.1 0.1];
            app.xdegsSpinner.ValueDisplayFormat = '%2.3f';
            app.xdegsSpinner.ValueChangedFcn = createCallbackFcn(app, @xdegsSpinnerValueChanged, true);
            app.ydegsSpinnerLabel = uilabel(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.ydegsSpinnerLabel.Text = 'y (degs)';
            app.ydegsSpinner = uispinner(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.ydegsSpinner.Step = 0.001;
            app.ydegsSpinner.Limits = [-0.1 0.1];
            app.ydegsSpinner.ValueDisplayFormat = '%2.3f';
            app.ydegsSpinner.ValueChangedFcn = createCallbackFcn(app, @ydegsSpinnerValueChanged, true);
            app.surroundoffsetLabel = uilabel(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.surroundoffsetLabel.Text = 'surround offset';
            app.integratedsensitivtysurroundcenterEditField = uieditfield(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure, 'numeric');
            app.integratedsensitivtysurroundcenterEditField.ValueDisplayFormat = '%2.2f';
            app.integratedsensitivtysurroundcenterEditField.Enable = 'off';
            app.integratedsensitivitysurrwghtscenterwghtsLabel = uilabel(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.integratedsensitivitysurrwghtscenterwghtsLabel.Text = {'integrated sensitivity'; '(surr. wghts / center wghts)'};
            app.autoscalesurroundweightsCheckBox = uicheckbox(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.autoscalesurroundweightsCheckBox.ValueChangedFcn = createCallbackFcn(app, @autoscalesurroundweightsCheckBoxValueChanged, true);
            app.autoscalesurroundweightsCheckBox.Text = 'auto-scale surround weights';
            app.autoscalesurroundweightsCheckBox.Value = true;
            app.centerSurroundPeakSensitivityNumberEditField = uieditfield(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure, 'numeric');
            app.centerSurroundPeakSensitivityNumberEditField.Editable = 'off';
            app.surroundRadiusNumberEditField = uieditfield(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure, 'numeric');
            app.surroundRadiusNumberEditField.ValueDisplayFormat = '%2.4f';
            app.surroundRadiusNumberEditField.Editable = 'off';
            app.opticsTypeKnob = uiknob(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure, 'discrete');
            app.opticsTypeKnob.Items = {'Polans subject', 'diffraction-limited', 'delta function'};
            app.opticsTypeKnob.ValueChangedFcn = createCallbackFcn(app, @opticsTypeKnobValueChanged, true);
            app.opticsTypeKnob.Value = 'Polans subject';
            app.receptiveFieldView = uiaxes(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            xlabel(app.receptiveFieldView, 'X')
            ylabel(app.receptiveFieldView, 'Y')
            zlabel(app.receptiveFieldView, 'Z')
            app.MTFamplitudeView = uiaxes(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            xlabel(app.MTFamplitudeView, 'spatial frequency (c/deg)')
            ylabel(app.MTFamplitudeView, 'response')
            zlabel(app.MTFamplitudeView, 'Z')
            app.MTFamplitudeView.XLim = [0.4 100];
            app.MTFamplitudeView.XTick = [0.5 1 2 4 6 10 20 40 60 100];
            app.MTFamplitudeView.XScale = 'log';
            app.MTFamplitudeView.XTickLabel = {'0.5'; '1'; '2'; '4'; '6'; '10'; '20'; '40'; '60'; '100'};
            app.MTFamplitudeView.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.psfInsetView = uiaxes(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            app.psfInsetView.XTick = [];
            app.psfInsetView.YTick = [];
            app.responseTracesView = uiaxes(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            zlabel(app.responseTracesView, {''; ''})
            app.responseTracesView.XTick = [];
            app.responseTracesView.YTick = [];
            app.MTFphaseView = uiaxes(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure);
            ylabel(app.MTFphaseView, 'phase (deg)')
            app.MTFphaseView.XLim = [0.4 120];
            app.MTFphaseView.YLim = [-200 200];
            app.MTFphaseView.XTick = [0.5 1 2 4 6 10 20 40 60 100];
            app.MTFphaseView.XScale = 'log';
            app.MTFphaseView.XTickLabel = '';
            app.MTFphaseView.YTick = [];
            app.MTFphaseView.YTickLabel = '';
            % Show the figure after all components are created
            app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RGC_MTF_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RGCMTFsimulatorNPCottarisISETBioTeamUIFigure)
        end
    end
end