classdef opticsAndConeMosaic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Polansetal2015datasetPanel  matlab.ui.container.Panel
        loaddataButton              matlab.ui.control.Button
        subjectIDSpinnerLabel       matlab.ui.control.Label
        subjectIDSpinner            matlab.ui.control.Spinner
        summaryfigureacrosseccentricitiesButton  matlab.ui.control.Button
        controlsPanel               matlab.ui.container.Panel
        wavelengthnmSliderLabel     matlab.ui.control.Label
        wavelengthnmSlider          matlab.ui.control.Slider
        pupilmmSliderLabel          matlab.ui.control.Label
        pupilmmSlider               matlab.ui.control.Slider
        horizontalretinaleccentricitydegsLabel  matlab.ui.control.Label
        horizontalretinaleccentricitydegsSlider  matlab.ui.control.Slider
        verticalretinaleccentricitydegsSliderLabel  matlab.ui.control.Label
        verticalretinaleccentricitydegsSlider  matlab.ui.control.Slider
        eyeeccentricityButtonGroup  matlab.ui.container.ButtonGroup
        lefteyetemporalecc0Button   matlab.ui.control.ToggleButton
        righteyenasalecc0Button     matlab.ui.control.ToggleButton
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        theConeMosaic;              % the cone mosaic object
        thePSF;                     % the point spread function 3D matrix
        thePSFsupportX;               % the spatial support of the PSF
        thePSFsupportY;               % the spatial support of the PSF
        visualizedWavelenth;        % the wavelength that is currently visualized
        
        computedWavelengths = 450:25:700    % wavelengths for which to compute the PSF
        pupilDiameterMM = 3                 % pupil diameter for which to compute the PSF
        eccDegs = [0 0];                    % eccentricity (horizontal, vertical) in degrees
        whichEye = PolansOptics.constants.rightEye;             % which eye
        subjectID = 10;                     % Polans subject ID
        micronsPerDegree                    % microns/deg factor
        
        dataIsLoaded = false                % Flag indicating that some data have been loaded
        
        optics = struct(...
            'flipPSFUpsideDown', true, ...
            'zeroCenterPSF', ~true, ...
            'subtractCentralRefraction', ~true ...
            );
    end
    
    methods (Access = private)
        
        function generateEccVaryingOpticsAndConeMosaic(app)
              
            % Open progress bar
            hProgressDialog = uiprogressdlg(app.UIFigure,'Title','Status');
            hProgressDialog.Value = 0.2;
            hProgressDialog.Message = 'Loading data';
            
            % Compute microns per degree factor for the target eccentricity
            sizeDegs = 0.1;
            eccRadiusDegs = sqrt(sum(app.eccDegs.^2));
            sizeMicrons = RGCmodels.Watson.convert.sizeVisualDegsToSizeRetinalMicrons(sizeDegs, eccRadiusDegs);
            app.micronsPerDegree = sizeMicrons(1)/sizeDegs(1);
        
            % Compute spacing for cone mosaic appropriate for this eccentricity
            eccMicrons = 1e3 * RGCmodels.Watson.convert.rhoDegsToMMs(app.eccDegs);
            [~,coneSpacingMMs] = RGCmodels.Watson.compute.rfSpacingAtRetinalPositions(app.whichEye, eccMicrons, 'cones', false);
            coneSpacingMicrons = coneSpacingMMs  * 1e3;
            coneApertureMicrons = RGCmodels.Watson.constants.coneApertureToDiameterRatio * coneSpacingMicrons;
            
            % Update progress bar
            hProgressDialog.Value = 0.4;
            hProgressDialog.Message = 'Generating cone mosaic for given eccentricity';
            
            % Generate a 0.2 x 0.2 deg regular hex cone mosaic
            app.theConeMosaic = coneMosaicHex(9, ...
                'fovDegs', 0.2, ...
                'micronsPerDegree', app.micronsPerDegree, ...
                'customLambda', coneSpacingMicrons, ...
                'customInnerSegmentDiameter', coneApertureMicrons ...
            );
            
            % Update progress bar
            hProgressDialog.Value = 0.6;
            hProgressDialog.Message = 'Generating optics for given eccentricity';
            
            % The mosaic ecc is in visual field coordinates. But Polans
            % coordinates are in retinal field.
            % So flip them 
            % Compute optics for eccentricity  based on the Polans data
            [~, app.thePSF, app.thePSFsupportX, app.thePSFsupportY] = PolansOptics.oiForSubjectAtEccentricity(...
                app.subjectID, app.whichEye, app.eccDegs,...
                app.pupilDiameterMM, app.computedWavelengths, app.micronsPerDegree, ...
                'wavefrontSpatialSamples', 701, ...
                'noLCA', ~true, ...
                'deNoisedZernikeCoefficients', false, ...
                'flipPSFUpsideDown', app.optics.flipPSFUpsideDown, ...
                'zeroCenterPSF', app.optics.zeroCenterPSF, ...
                'subtractCentralRefraction', app.optics.subtractCentralRefraction);
            
            % Normalize PSF
            %app.thePSF = app.thePSF / max(app.thePSF(:));
            
            % Set the visualized wavelength
            app.visualizedWavelenth = 550;
            
            % Update progress bar
            hProgressDialog.Value = 0.8;
            hProgressDialog.Message = 'Rendering';
            
            % Update the plot
            app.updatePlot();
            
            close(hProgressDialog);
        end
        
        function updatePlot(app)
            cMap = brewermap(1024, '*spectral');
            zLevels = linspace(0.05, 1.0-0.05, 7);
            psfRangeArcMin = 15*[-1 1];
    
            % Find the visualized wavelength index
            [~,waveIndex] = min(abs(app.computedWavelengths-app.visualizedWavelenth));
            
            % Extract the visualized PSF slice
            theWavePSF = squeeze(app.thePSF(:,:,waveIndex));
            theWavePSF = theWavePSF  / max(theWavePSF(:));
            
            % Render the visualized PSF slice
            cla(app.UIAxes)
             PolansOptics.renderPSF(app.UIAxes, app.thePSFsupportX, ...
                app.thePSFsupportY, theWavePSF, ...
                psfRangeArcMin, zLevels, cMap, 0.1*[1 1 1], ...
                'superimposedConeMosaic', app.theConeMosaic, ...
                'alpha', 0.5);
            title(app.UIAxes,sprintf('eccentricity: ( %2.0f , %2.0f ) degs', app.eccDegs(1), app.eccDegs(2)));
        end
        
        function generateSummaryFigure(app)
            % Open progress bar
            hProgressDialog = uiprogressdlg(app.UIFigure,'Title','Status');
            hProgressDialog.Value = 0.0;
            hProgressDialog.Message = 'Computing PSFs and mosaics';
            
            % Range of eccentricities examined
            eccXexamined = [-20:5:-10 -6 -3 -2 -1 0 1 2 3 6 10:5:20];  %[-25:1:25]; % 
            eccYexamined = 20:-5:-20; % 25:-5:-25;
            
            % Plot index
            plotIndex = 0;
            
            % Initialize figure
            hFig = figure(app.subjectID); clf;
            set(hFig, 'Position', [10 10 1700 1200], 'Color', [1 1 1]);
            
            cMap = brewermap(1024, '*spectral');
            zLevels = linspace(0.05, 1.0-0.05, 7);
 
    
            % Find the visualized wavelength index
            [~,waveIndex] = min(abs(app.computedWavelengths-app.visualizedWavelenth));
            
            subplotPosVectors = NicePlot.getSubPlotPosVectors(...
               'rowsNum', numel(eccYexamined), ...
               'colsNum', numel(eccXexamined), ...
               'heightMargin',  0.005, ...
               'widthMargin',    0.005, ...
               'leftMargin',     0.01, ...
               'rightMargin',    0.00, ...
               'bottomMargin',   0.005, ...
               'topMargin',      0.005);
           
            psfRangeArcMin = 15*[-1 1];
            for eccX = 1:numel(eccXexamined)
                % Flip ecc because optics ecc is in retinal coords, 
                % whereas the cone mosaic ecc is in visual field coors
                app.eccDegs(1) = eccXexamined(eccX);
                for eccY = 1:numel(eccYexamined)
                    % Flip ecc because optics ecc is in retinal coords, 
                    % whereas the cone mosaic ecc is in visual field coors
                    app.eccDegs(2) = eccYexamined(eccY);
                    
                    % Update plot counter and progress bar
                    plotIndex = plotIndex+1;
                    hProgressDialog.Value = plotIndex/(numel(eccXexamined)*numel(eccYexamined));
                    
                    % Compute
                    app.generateEccVaryingOpticsAndConeMosaic();
                    
                    % Extract the visualized PSF slice
                    theWavePSF = squeeze(app.thePSF(:,:,waveIndex));
                    theWavePSF = theWavePSF  / max(theWavePSF(:));
                    
                    % Render the visualized PSF slice
                    ax = subplot('Position', subplotPosVectors(eccY,eccX).v);
                    PolansOptics.renderPSF(ax, app.thePSFsupportX, app.thePSFsupportY, theWavePSF, ...
                        psfRangeArcMin, zLevels, cMap, 0.1*[1 1 1], ...
                        'superimposedConeMosaic', app.theConeMosaic, ...
                        'alpha', 0.5);
                   
                    % No ticks, box on
                    set(ax, 'XTick', [], 'YTick', []);
                    box(ax, 'on');
                    
                    xlabel(ax, ''); ylabel(ax, '');
                    % Title showing eccentricity
                    title(ax, sprintf('%2.0f,%2.0f', app.eccDegs(1), app.eccDegs(2)), 'FontWeight', 'Normal', 'FontSize', 12);
                    drawnow;
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: wavelengthnmSlider
        function wavelengthnmSliderValueChanged(app, event)
            
            if (app.dataIsLoaded)
                % Open progress bar
                hProgressDialog = uiprogressdlg(app.UIFigure,'Title','Status');
                hProgressDialog.Value = 0.3;
                hProgressDialog.Message = 'Re-rendering PSF at new wavelength';
                
                app.visualizedWavelenth = app.wavelengthnmSlider.Value;
                app.updatePlot()
                
                % Close progress bar
                close(hProgressDialog)
            end
        end

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
             % Compute cone mosaic and optics for this eccentricity,
             % subject and pupil size
             app.generateEccVaryingOpticsAndConeMosaic();
             app.dataIsLoaded = true;
        end

        % Value changed function: pupilmmSlider
        function pupilmmSliderValueChanged(app, event)
            
            if (app.dataIsLoaded)
                % Open progress bar
                hProgressDialog = uiprogressdlg(app.UIFigure,'Title','Status');
                hProgressDialog.Value = 0.3;
                hProgressDialog.Message = 'Computing PSF for new pupil size';
                
                app.pupilDiameterMM = app.pupilmmSlider.Value;
                
                % Compute optics for eccentricity  based on the Polans data
                [~, app.thePSF, app.thePSFsupportX, app.thePSFsupportY] = PolansOptics.oiForSubjectAtEccentricity(app.subjectID, ...
                    app.whichEye, app.eccDegs, ...
                    app.pupilDiameterMM, app.computedWavelengths, app.micronsPerDegree, ...
                    'wavefrontSpatialSamples', 701, ...
                    'noLCA', ~true, ...
                    'deNoisedZernikeCoefficients', false, ...
                    'flipPSFUpsideDown', app.optics.flipPSFUpsideDown, ...
                     'zeroCenterPSF', app.optics.zeroCenterPSF, ...
                     'subtractCentralRefraction', app.optics.subtractCentralRefraction);
                
                % Normalize PSF
                app.thePSF = app.thePSF / max(app.thePSF(:));
                
                % Update progress bar
                hProgressDialog.Value = 0.6;
                hProgressDialog.Message = 'Re-rendering PSF';
                
                % Update the plot
                app.updatePlot();
                
                % Close progress bar
                close(hProgressDialog)
            end
        end

        % Value changed function: subjectIDSpinner
        function subjectIDSpinnerValueChanged(app, event)
            app.subjectID = app.subjectIDSpinner.Value;
            if (app.dataIsLoaded)
                app.generateEccVaryingOpticsAndConeMosaic();
            end
        end

        % Value changed function: 
        % horizontalretinaleccentricitydegsSlider
        function horizontalretinaleccentricitydegsSliderValueChanged(app, event)
            app.eccDegs(1) = app.horizontalretinaleccentricitydegsSlider.Value;
            if (app.dataIsLoaded)
                app.generateEccVaryingOpticsAndConeMosaic();
            end
        end

        % Value changed function: 
        % verticalretinaleccentricitydegsSlider
        function verticalretinaleccentricitydegsSliderValueChanged(app, event)
            app.eccDegs(2)  = app.verticalretinaleccentricitydegsSlider.Value;
            if (app.dataIsLoaded)
                app.generateEccVaryingOpticsAndConeMosaic();
            end
        end

        % Button pushed function: 
        % summaryfigureacrosseccentricitiesButton
        function summaryfigureacrosseccentricitiesButtonPushed(app, event)
            if (app.dataIsLoaded)
                app.generateSummaryFigure();
            end
        end

        % Selection changed function: eyeeccentricityButtonGroup
        function eyeChanged(app, event)
          
            if (app.righteyenasalecc0Button.Value == 1)
                app.whichEye = PolansOptics.constants.rightEye;
            else
                app.whichEye = PolansOptics.constants.leftEye;
            end
            if (app.dataIsLoaded)
                app.generateEccVaryingOpticsAndConeMosaic();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Eccentricity-varying cone mosaic & optics visualizer. Written by N.P. Cottaris';
            app.Polansetal2015datasetPanel = uipanel(app.UIFigure);
            app.Polansetal2015datasetPanel.Title = 'Polans et al. (2015) data set';
            app.loaddataButton = uibutton(app.Polansetal2015datasetPanel, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.subjectIDSpinnerLabel = uilabel(app.Polansetal2015datasetPanel);
            app.subjectIDSpinnerLabel.Text = 'subject ID';
            app.subjectIDSpinner = uispinner(app.Polansetal2015datasetPanel);
            app.subjectIDSpinner.Limits = [1 10];
            app.subjectIDSpinner.RoundFractionalValues = 'on';
            app.subjectIDSpinner.ValueChangedFcn = createCallbackFcn(app, @subjectIDSpinnerValueChanged, true);
            app.subjectIDSpinner.Value = 10;
            app.summaryfigureacrosseccentricitiesButton = uibutton(app.UIFigure, 'push');
            app.summaryfigureacrosseccentricitiesButton.ButtonPushedFcn = createCallbackFcn(app, @summaryfigureacrosseccentricitiesButtonPushed, true);
            app.summaryfigureacrosseccentricitiesButton.Text = {'summary figure across eccentricities'; ''};
            app.controlsPanel = uipanel(app.UIFigure);
            app.controlsPanel.Title = 'controls';
            app.wavelengthnmSliderLabel = uilabel(app.controlsPanel);
            app.wavelengthnmSliderLabel.Text = 'wavelength (nm)';
            app.wavelengthnmSlider = uislider(app.controlsPanel);
            app.wavelengthnmSlider.Limits = [450 700];
            app.wavelengthnmSlider.MajorTicks = [450 500 550 600 650 700];
            app.wavelengthnmSlider.ValueChangedFcn = createCallbackFcn(app, @wavelengthnmSliderValueChanged, true);
            app.wavelengthnmSlider.MinorTicks = [450 475 500 525 550 575 600 625 650 675 700];
            app.wavelengthnmSlider.Value = 550;
            app.pupilmmSliderLabel = uilabel(app.controlsPanel);
            app.pupilmmSliderLabel.Text = 'pupil (mm)';
            app.pupilmmSlider = uislider(app.controlsPanel);
            app.pupilmmSlider.Limits = [1 4];
            app.pupilmmSlider.ValueChangedFcn = createCallbackFcn(app, @pupilmmSliderValueChanged, true);
            app.pupilmmSlider.Value = 3;
            app.horizontalretinaleccentricitydegsLabel = uilabel(app.UIFigure);
            app.horizontalretinaleccentricitydegsLabel.Text = 'horizontal retinal eccentricity (degs)';
            app.horizontalretinaleccentricitydegsSlider = uislider(app.UIFigure);
            app.horizontalretinaleccentricitydegsSlider.Limits = [-40 40];
            app.horizontalretinaleccentricitydegsSlider.MajorTicks = [-40 -30 -20 -10 0 10 20 30 40];
            app.horizontalretinaleccentricitydegsSlider.ValueChangedFcn = createCallbackFcn(app, @horizontalretinaleccentricitydegsSliderValueChanged, true);
            app.horizontalretinaleccentricitydegsSlider.MinorTicks = [-40 -35 -30 -25 -20 -15 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 15 20 25 30 35];
            app.verticalretinaleccentricitydegsSliderLabel = uilabel(app.UIFigure);
            app.verticalretinaleccentricitydegsSliderLabel.Text = 'vertical retinal eccentricity (degs)';
            app.verticalretinaleccentricitydegsSlider = uislider(app.UIFigure);
            app.verticalretinaleccentricitydegsSlider.Limits = [-25 25];
            app.verticalretinaleccentricitydegsSlider.ValueChangedFcn = createCallbackFcn(app, @verticalretinaleccentricitydegsSliderValueChanged, true);
            app.eyeeccentricityButtonGroup = uibuttongroup(app.UIFigure);
            app.eyeeccentricityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @eyeChanged, true);
            app.eyeeccentricityButtonGroup.Title = 'eye & eccentricity';
            app.lefteyetemporalecc0Button = uitogglebutton(app.eyeeccentricityButtonGroup);
            app.lefteyetemporalecc0Button.Text = {'left eye'; '(temporal ecc < 0)'; ''};
            app.righteyenasalecc0Button = uitogglebutton(app.eyeeccentricityButtonGroup);
            app.righteyenasalecc0Button.Text = {'right eye'; '(nasal ecc < 0)'; ''};
            app.righteyenasalecc0Button.Value = true;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opticsAndConeMosaic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end