classdef fixationalEMdemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure  matlab.ui.Figure
        VISUALIZATIONcontrolsPanel      matlab.ui.container.Panel
        positionScaleSlider             matlab.ui.control.Slider
        visualizedspacearcminLabel      matlab.ui.control.Label
        timeScaleSlider                 matlab.ui.control.Slider
        visualizeddurationsecondsLabel  matlab.ui.control.Label
        FIXATIONmapparamsPanel          matlab.ui.container.Panel
        fixationMapSpaceConstantKnob    matlab.ui.control.Knob
        spaceconstantarcminLabel_2      matlab.ui.control.Label
        HEATmapparametersPanel          matlab.ui.container.Panel
        heatmapTimeConstantKnob         matlab.ui.control.Knob
        timeconstantsecsLabel           matlab.ui.control.Label
        heatmapSpaceConstantKnob        matlab.ui.control.Knob
        spaceconstantarcminLabel        matlab.ui.control.Label
        SACCADEparametersPanel          matlab.ui.container.Panel
        heatmapFixationBalanceKnob      matlab.ui.control.Knob
        mapbalanceLabel                 matlab.ui.control.Label
        microSaccadeAmplitudeKnob       matlab.ui.control.Knob
        meanamplitudearcminLabel        matlab.ui.control.Label
        microSaccadeIntervalKnob        matlab.ui.control.Knob
        meanintervalsecsLabel           matlab.ui.control.Label
        microSaccadeTypeKnob            matlab.ui.control.DiscreteKnob
        generationmechanismLabel        matlab.ui.control.Label
        PLAYBACKcontrolsPanel           matlab.ui.container.Panel
        runPauseButton                  matlab.ui.control.StateButton
        jumpToLastMicroSaccadeTime      matlab.ui.control.Button
        visualizationSpeedSlider        matlab.ui.control.Slider
        playbackspeedLabel              matlab.ui.control.Label
        jumpToNextMicroSaccadeTime      matlab.ui.control.Button
        newTrialButton                  matlab.ui.control.Button
        timeMachineSlider               matlab.ui.control.Slider
        playbacktimeLabel               matlab.ui.control.Label
        DRIFTparametersPanel            matlab.ui.container.Panel
        positionalNoiseKnob             matlab.ui.control.Knob
        positionalnoiseLabel            matlab.ui.control.Label
        controlNoiseKnob                matlab.ui.control.Knob
        controlnoiseKnobLabel           matlab.ui.control.Label
        feedbackDelayYKnob              matlab.ui.control.Knob
        feedbackdelayyLabel             matlab.ui.control.Label
        feedbackDelayXKnob              matlab.ui.control.Knob
        feedbackdelayxLabel             matlab.ui.control.Label
        feedbackSteepnessKnob           matlab.ui.control.DiscreteKnob
        feedbacksteepnessKnobLabel      matlab.ui.control.Label
        feedbackGainKnob                matlab.ui.control.DiscreteKnob
        feedbackgainKnobLabel           matlab.ui.control.Label
        controlGainKnob                 matlab.ui.control.DiscreteKnob
        controlgainKnobLabel            matlab.ui.control.Label
        emMicroSaccadeJointAmplitudeSpeedAxes  matlab.ui.control.UIAxes
        emMicroSaccadeIntervalAxes      matlab.ui.control.UIAxes
        emMicroSaccadeTargetLikelihoodMapAxes  matlab.ui.control.UIAxes
        emHeatMapAxes                   matlab.ui.control.UIAxes
        emTimeSeriesVelocityAxes        matlab.ui.control.UIAxes
        emDynamicsAxes                  matlab.ui.control.UIAxes
        emPathAxes                      matlab.ui.control.UIAxes
        emTimeSeriesAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private)

        % Interactive visualization timer and status
        interactiveVisualizationTimer = timer();            % Timer for updating the GUI when in interactive mode
        interactiveVisualizationTimerIntervalSeconds=0.020; % How ofter to trigger the timer function
        interactiveVisualizationStatus = 'paused';          % Status of interactive visualization
        earliestVisualizedTimeStep = 0;                     % The first visualized time sample, increased by the timer function
        visualizationSpeedStepsNum = 5;
        
        % Interactive visualization params
        visualizedDurationSeconds = 0.2;                    % How many seconds to visualize at any given instant, modified via the timeScaleSlider
        visualizedTimeSamplesNum = [];                      % How many samples to visualized; computed from visualizedDurationSeconds
        visualizedEMrange = 4;                              % How many arc mins to visualize at any given instant, modified via the amplitudeScaleSlider
        timeTickIntervalSeconds = 0.05;                     % Tick interval for time
        displayedMosaicFOVdegs = 15/60;                     % How big a cone mosaic to display
        
        % Interactive visualization plot handles
        emTimeSeriesXposPlotHandle = [];                    % Handle for the line plot of the x-Pos time series
        emTimeSeriesYposPlotHandle = [];                    % Handle for the line plot of the y-Pos time series
        emTimeSeriesPlotCrossHairHandle = [];
        emHeatMapPlotHandle = [];                           % Handle for the heat map of the emPath
        emMicroSaccadeTargetLikelihoodMapHandle = [];       % Handle for the microsaccade target likelihood map
        emMicroSaccadePositionHandle = [];                  % Handle for the microsaccade position
        emMicroSaccadeIndex = [];
        emLastVisualizedSample = [];                        % Last sample being visualized
        emTimeSeriesVelocityPlotHandles = [];               % Handles for the line plot of the velocity time series
        emPathPlotHandle = [];                              % Handle for the x/y-Pos eye movement path
        emPathTipPlotHandle = [];                           % Handle for the last point in the x/y-Pos eye movement path
        emCrossHairsPlotHandle = [];                        % Handles for the cross-hairs in the x/y-Pos eye movement path
        
        mosaicPlotHandle = [];                              % Handle for the displayed cone mosaic plot
        coneLocsMinArc = [];
        
        % Displacement analysis plot handles
        emDynamicsXposDisplacementPlotHandle = [];
        emDynamicsYposDisplacementPlotHandle = [];
        emDynamicsXposRandomizedIntervalDisplacementPlotHandle = [];
        emDynamicsYposRandomizedIntervalDisplacementPlotHandle = [];
        emDynamicsXposRandomWalkDisplacementPlotHandle = [];
        emDynamicsYposRandomWalkDisplacementPlotHandle= [];
        
        % Microsaccade analysis plot handles
        emMicroSaccadeIntervalsDistributionPlotHandle = [];
        emMicroSaccadeIntervalsInThisTrialHandle = [];
        emMicroSaccadeAmplitudesDistributionPlotHandle = [];
        emMicroSaccadeAmplitudeSpeedsInThisTrialHandle = [];
        emMicroSaccadeAmplitudeSpeedsDistributionHandle = [];
        
        % Data generation properties
        fixationalEMobj = [];                        % The driftEyeMovement object which generates eye movements based on passed params
        trialDurationSeconds = 3.0;                  % How many seconds to generate eye movement trials
        sampleDurationSeconds = 1.0/1000;
    end
    
    properties(Access = private)
        % Properties (that are internal to the model and used only for enhanced visualization of the model)
        visualizablePropertyNames = {
            'emPosTimeSeriesArcMin', ...                        % emPath 
            'velocityArcMinPerSecTimeSeries', ...               % velocity
            'microSaccadeIntervalsMilliSecondsDist', ...        % distribution of microsaccade intervals - useful for visualization
            'microSaccadeIntervalsMilliSecondsList', ...        % list with possible microsaccade intervals
            'microSaccadeIntervalsMilliseconds', ...            % list with generated microsaccade intervals
            'microSaccadeAmplitudesArcMinDist', ...             % distribution of microsaccade amplitudes - useful for visualization
            'microSaccadeAmplitudesArcMinList', ...             % list with possible microsaccade amplitudes
            'microSaccadeAmplitudesArcMin', ...                 % list with generated microsaccade amplitudes
            'microSaccadeSpeedDegsPerSecondDist', ...           % distribution of microsaccade speeds - useful for visualization
            'microSaccadeSpeedDegsPerSecondList', ...           % list with possible microsaccade speeds
            'microSaccadeSpeedsDegsPerSecond', ...              % list with generated microsaccade speeds
            'microSaccadeDurationsMillisecondsDist', ...        % distribution of microsaccde durations (derived from speed and amplitude distribution)
            'microSaccadeDurationsMillisecondsList', ...        % list with generated microsaccade durations
            'microSaccadeOnsetStepIndices', ...                 % list with generated microsaccade onset time steps
            'microSaccadeTargetLikelihoodMapsList', ...         % list with generated microsaccade target likelihood maps
            'microSaccadePositions', ...
            'heatMapTimeSeries', ...
            'heatMapXYsupport', ...
         };
         % Dictionary with all visualizable proprties (updated after each call to fixationalEMobj.compute();
         visualizablePropertiesDictionary = containers.Map();
    end
    

    methods (Access = private)
    
        % Method that keeps track of interactiveVisualization status
        function timerStatusChanged(app, obj, event, status)
            app.interactiveVisualizationStatus = status;
        end
        
        % Method triggerred from the timer which updates the visualized data
        function updateInteractiveVisualizations(app, incrementTimeStep)   
            % How many time samples we will be visualizing
            app.visualizedTimeSamplesNum = round(app.visualizedDurationSeconds/app.fixationalEMobj.timeStepDurationSeconds);
            
            % Increment the earliestVisualizedTimeStep
            if (incrementTimeStep)
                app.earliestVisualizedTimeStep = app.earliestVisualizedTimeStep + app.visualizationSpeedStepsNum;
                if (app.earliestVisualizedTimeStep >= numel(app.fixationalEMobj.timeAxis)-app.visualizedTimeSamplesNum)
                    app.earliestVisualizedTimeStep = 1;
                end
            end         
            
            % Determine visualized time samples
            visualizedTimeSamples = app.earliestVisualizedTimeStep + (0:app.visualizedTimeSamplesNum);
            timeTicks = (0:round((app.visualizedTimeSamplesNum/10)):(app.visualizedTimeSamplesNum-round(app.visualizedTimeSamplesNum/20)));
            timeTicks = round(timeTicks*app.fixationalEMobj.timeStepDurationSeconds*1000)/1000;
            visualizedTimeSamples = visualizedTimeSamples((visualizedTimeSamples>=1 & visualizedTimeSamples<=numel(app.fixationalEMobj.timeAxis)));
            app.emLastVisualizedSample = visualizedTimeSamples(end);
            
            % Determine current microsaccade index
            currentMicroSaccadeIndex = find(visualizedTimeSamples(end-1)>=app.visualizablePropertiesDictionary('microSaccadeOnsetStepIndices'));
            if (~isempty(currentMicroSaccadeIndex))
               app.emMicroSaccadeIndex = currentMicroSaccadeIndex(end);
            end
            
            % Extract visualized signal portion
            visualizedTimeAxis = app.fixationalEMobj.timeAxis(visualizedTimeSamples);
            visualizedXYposPath = app.visualizablePropertiesDictionary('emPosTimeSeriesArcMin');
            visualizedXposPath = visualizedXYposPath(1,visualizedTimeSamples);
            visualizedYposPath = visualizedXYposPath(2,visualizedTimeSamples);
            
            velocityArcMinPerSecTimeSeries = app.visualizablePropertiesDictionary('velocityArcMinPerSecTimeSeries');
            visualizedVelocity = velocityArcMinPerSecTimeSeries(1,visualizedTimeSamples);
            
            % Compute visualized signal limits
            timeLimits = [visualizedTimeAxis(1) visualizedTimeAxis(end)];
            
            samplesToAverageForCurrentPosition = 10;
            xPosMeanCenter = mean(visualizedXposPath(end-samplesToAverageForCurrentPosition:end));
            yPosMeanCenter = mean(visualizedYposPath(end-samplesToAverageForCurrentPosition:end));
            
            xPosCenter = 0;
            maxDeviationBeforePan = app.visualizedEMrange*0.4;
            if (xPosMeanCenter > maxDeviationBeforePan)
                xPosCenter = xPosMeanCenter - maxDeviationBeforePan;
            elseif (xPosMeanCenter < -maxDeviationBeforePan)
                xPosCenter = xPosMeanCenter + maxDeviationBeforePan;
            end
            yPosCenter = 0;
            if (yPosMeanCenter > maxDeviationBeforePan)
                yPosCenter = yPosMeanCenter - maxDeviationBeforePan;
            elseif (yPosMeanCenter < -maxDeviationBeforePan)
                yPosCenter = yPosMeanCenter + maxDeviationBeforePan;
            end  
            
            xPosLimits = xPosCenter + app.visualizedEMrange*[-1 1]*0.5;
            yPosLimits = yPosCenter + app.visualizedEMrange*[-1 1]*0.5;
            if (app.visualizedEMrange < 10)
                emTicksArcMin = -100:1:100;
                if (~isempty(app.mosaicPlotHandle))
                    set(app.mosaicPlotHandle, 'XData', app.coneLocsMinArc(:,1), 'YData', app.coneLocsMinArc(:,2));
                end
            elseif (app.visualizedEMrange < 30)
                emTicksArcMin = -100:2:100;
                if (~isempty(app.mosaicPlotHandle))
                    set(app.mosaicPlotHandle, 'XData', app.coneLocsMinArc(:,1), 'YData', app.coneLocsMinArc(:,2));
                end
            else
                emTicksArcMin = -100:5:100;
                if (~isempty(app.mosaicPlotHandle))
                    set(app.mosaicPlotHandle, 'XData', nan, 'YData', nan);
                end
            end
            emTicksArcMin = round(emTicksArcMin * 100)/100;
            
            % Update emTimeSeriesAxes limits
            currentTTicks = timeLimits(1) + timeTicks;
            dt = 0.5*(emTicksArcMin(2)-emTicksArcMin(1));
            currentXTicks = emTicksArcMin;
            currentXTicks = currentXTicks(currentXTicks>xPosLimits(1)+dt & currentXTicks<xPosLimits(2)-dt);
            currentYTicks = emTicksArcMin;
            currentYTicks = currentYTicks(currentYTicks>yPosLimits(1)+dt & currentYTicks<yPosLimits(2)-dt);
           
            
            % Ticks and limits
            set(app.emTimeSeriesAxes, 'XLim', timeLimits, 'XTick', currentTTicks, 'XTickLabel', sprintf('%02.03f\n',currentTTicks), ...
                'YLim', yPosLimits, 'YTick', currentYTicks, 'YTickLabel', sprintf('%02.1f\n',currentYTicks));
            set(app.emTimeSeriesVelocityAxes, 'XLim', timeLimits, 'XTick', currentTTicks); % , ...'XTickLabel', sprintf('%02.03f\n',currentTTicks));

            % Update the emVelocityPlotHandle
            set(app.emTimeSeriesVelocityPlotHandles, 'XData', visualizedTimeAxis, 'YData', visualizedVelocity);
        
            
            % Update emTimeSeriesAxes data
            set(app.emTimeSeriesXposPlotHandle, ...
                'XData', visualizedTimeAxis, ...
                'YData', visualizedXposPath);
            
            set(app.emTimeSeriesYposPlotHandle, ...
                'XData', visualizedTimeAxis, ...
                'YData', visualizedYposPath);
                       
            set(app.emTimeSeriesPlotCrossHairHandle, ...
                'XData', visualizedTimeAxis(end), 'YData', visualizedYposPath(end));
           
            
            % Update app.emPathAxes data
            set(app.emPathPlotHandle, 'XData', visualizedXposPath, 'YData', visualizedYposPath);
            set(app.emPathTipPlotHandle, 'XData', visualizedXposPath(end), 'YData', visualizedYposPath(end));
            
            
            % Update the app.emHeatMap data
            heatMapTimeSeries = app.visualizablePropertiesDictionary('heatMapTimeSeries');
            set(app.emHeatMapPlotHandle,'CData', squeeze(heatMapTimeSeries(visualizedTimeSamples(end),:,:)));
            
            % Update the microsaccade target likelihood map if we are at the onset of a microsaccade and if we are in the fixation/heat map generation mode
            microSaccadeOnsetStepIndices = app.visualizablePropertiesDictionary('microSaccadeOnsetStepIndices');
            
            if (strcmp(app.fixationalEMobj.microSaccadeType, 'heatmap/fixation based')) && (~isempty(microSaccadeOnsetStepIndices))
                if (visualizedTimeSamples(end-1)<microSaccadeOnsetStepIndices(1))
                    set(app.emMicroSaccadeTargetLikelihoodMapHandle, 'CData', 0*get(app.emMicroSaccadeTargetLikelihoodMapHandle, 'CData'));
                    set(app.emMicroSaccadePositionHandle, 'XData', nan,'YData', nan);
                else
                    if (~isempty(currentMicroSaccadeIndex))
                        microSaccadeTargetLikelihoodMapsList = app.visualizablePropertiesDictionary('microSaccadeTargetLikelihoodMapsList');
                        microSaccadePositions = app.visualizablePropertiesDictionary('microSaccadePositions');
                        map = squeeze(microSaccadeTargetLikelihoodMapsList(app.emMicroSaccadeIndex,:,:));
                        set(app.emMicroSaccadeTargetLikelihoodMapHandle, 'CData', map);
                        set(app.emMicroSaccadeTargetLikelihoodMapAxes,'CLim', [min(map(:)) max(map(:))]);
                        set(app.emMicroSaccadePositionHandle, ...
                            'XData', microSaccadePositions(app.emMicroSaccadeIndex,1), ...
                            'YData', microSaccadePositions(app.emMicroSaccadeIndex,2) ...
                            );
                    end
                end
            else
                set(app.emMicroSaccadeTargetLikelihoodMapHandle, 'CData', 0*get(app.emMicroSaccadeTargetLikelihoodMapHandle, 'CData'));
                set(app.emMicroSaccadePositionHandle, 'XData', nan,'YData', nan);
            end
            
            % Update app.emPathAxes limits
            set(app.emPathAxes, 'XLim', xPosLimits, 'YLim', yPosLimits, ...
                'XTick', currentXTicks, 'XTickLabel', sprintf('%1.0f\n',currentXTicks), ...
                'YTick', currentYTicks, 'YTickLabel', {});
            
            % Update app.emHeatMapAxes, app.emMicroSaccadeTargetLikelihoodMapAxes, limits
            if (strcmp(app.fixationalEMobj.microSaccadeType, 'heatmap/fixation based'))
                set(app.emHeatMapAxes, 'XLim', xPosLimits, 'YLim', yPosLimits, ...
                    'XTick', currentXTicks, 'XTickLabel', sprintf('%1.0f\n',currentXTicks), ...
                    'YTick', currentYTicks, 'YTickLabel', {});
                
                set(app.emMicroSaccadeTargetLikelihoodMapAxes, 'XLim', xPosLimits, 'YLim', yPosLimits, ...
                    'XTick', currentXTicks, 'XTickLabel', sprintf('%1.0f\n',currentXTicks), ...
                    'YTick', currentYTicks, 'YTickLabel', {});
            end
            
            % Update any GUI controls the need to be updated
            updateGUIcontrols(app);
        end
        
        function initTimer(app)
            app.interactiveVisualizationTimer = timer(...
                'Period', app.interactiveVisualizationTimerIntervalSeconds, ...
                'StartDelay', 100/1000, ...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf...
                );
            % Queue events so we do not skip some if drawing takes longer than period
            app.interactiveVisualizationTimer.BusyMode = 'queue';
            % Set the timer callbacks
            app.interactiveVisualizationTimer.TimerFcn = @(obj,event) updateInteractiveVisualizations(app, true);
            app.interactiveVisualizationTimer.StartFcn = {@app.timerStatusChanged, 'running'};
            app.interactiveVisualizationTimer.StopFcn = {@app.timerStatusChanged, 'paused'};
            % Pause
            app.interactiveVisualizationStatus = 'paused';
        end
        
        
        function initPlots(app)   
           % A small cone mosaic to get cone locs
           c = cMosaic('sizeDegs', app.displayedMosaicFOVdegs*[1 1]);
           app.coneLocsMinArc = c.coneRFpositionsDegs*60;
           idx = sqrt(sum(app.coneLocsMinArc.^2, 2)) < app.displayedMosaicFOVdegs/2*60;
           app.coneLocsMinArc = app.coneLocsMinArc(idx,:);
           
           % Clear all axes
           cla(app.emTimeSeriesAxes);
           cla(app.emPathAxes);
           cla(app.emDynamicsAxes);
           
           % Retrieve visualization params from the GUI
           app.visualizedEMrange = app.positionScaleSlider.Value;
           app.visualizedDurationSeconds = app.timeScaleSlider.Value;
           
           % Add plots to the velocityAxes
           app.emTimeSeriesVelocityPlotHandles = line(app.emTimeSeriesVelocityAxes, [1 1], [nan nan], 'Color', [0.3 0.7 0.8], 'LineWidth', 2.0);
           set(app.emTimeSeriesVelocityAxes, 'FontSize', 12, 'YLim', [0 800], ...
               'YTick', [0:50:1000], 'YTickLabel', 0:50:1000, 'YScale', 'linear');
           %     'YTick', [0.3 1 3 10 30 100 300 1000 3000], 'YTickLabel', {'0.3' '  1' '  3' ' 10' ' 30' '100' '300' ' 1K' ' 3K'}, 'YScale', 'log');
           xlabel(app.emTimeSeriesVelocityAxes, 'time (seconds)');
           ylabel(app.emTimeSeriesVelocityAxes, 'speed (arc min / sec)');
           
           % Add plots to the emTimeSeriesAxes
           app.emTimeSeriesXposPlotHandle = line(app.emTimeSeriesAxes, ...
               [0 app.visualizedDurationSeconds], [0 0], 'Color', [1 0 0], ...
               'LineWidth', 1.5, 'Marker', '.', 'MarkerSize', 4);
           hold(app.emTimeSeriesAxes, 'on');
           app.emTimeSeriesYposPlotHandle = line(app.emTimeSeriesAxes, ...
               [0 app.visualizedDurationSeconds], [0 0], 'Color', [0 0 1], ...
               'LineWidth', 1.5, 'Marker', '.', 'MarkerSize', 4);
           app.emTimeSeriesPlotCrossHairHandle = plot(app.emTimeSeriesAxes, [0 0], [0 0], 'ko', 'LineWidth', 1.5, ...
               'MarkerSize', 6, 'MarkerFaceColor', [0.1 0.8 0.9], 'MarkerEdgeColor', [0.0 0.0 1.0]);
           hold(app.emTimeSeriesAxes, 'off');
           set(app.emTimeSeriesAxes, 'FontSize', 12);
           xlabel(app.emTimeSeriesAxes, 'time (seconds)');
           ylabel(app.emTimeSeriesAxes, 'y-position (arc min)');


           % Add plots to the emPathAxes
           markerSize = 100/app.visualizedEMrange;
           app.mosaicPlotHandle = plot(app.emPathAxes, app.coneLocsMinArc(:,1), app.coneLocsMinArc(:,2), 'ko', 'LineWidth', 1.5, ...
               'Color', [0.6 0.6 0.6], 'MarkerSize', markerSize, 'MarkerFaceColor', 'none', 'MarkerEdgeColor', [0.7 0.7 0.7]);
           hold(app.emPathAxes, 'on');
           radiusMinArc = 22;
           circle.x = cosd(0:5:360) * radiusMinArc;
           circle.y = sind(0:5:360) * radiusMinArc;
           plot(app.emPathAxes, circle.x, circle.y, 'k--', 'LineWidth', 1.5, 'Color', [0 0 0]);
           
           hold(app.emPathAxes, 'on');
           % The eye movement path
           app.emPathPlotHandle = plot(app.emPathAxes, [0 0], [0 0], 'k-', 'LineWidth', 1.5);
           % The last position as a green dot
           app.emPathTipPlotHandle = plot(app.emPathAxes, [0 0], [0 0], 'ko', 'LineWidth', 1.5, ...
               'MarkerSize', 6, 'MarkerFaceColor', [0.1 0.8 0.9], 'MarkerEdgeColor', [0.0 0.0 1.0]);
           % Add cross hairs
           plot(app.emPathAxes, [0 0], [-1000 100], 'c-', 'LineWidth', 1.5);
           plot(app.emPathAxes, [-1000 100], [0 0], 'c-', 'LineWidth', 1.5);
           hold(app.emPathAxes, 'off');
           axis(app.emPathAxes, 'square');
           set(app.emPathAxes, 'FontSize', 12, 'YColor', 'none');
           xlabel(app.emPathAxes, 'x-position (arc min)');
           ylabel(app.emPathAxes, ' ');
          
           
           % Add plots to the app.emDynamicsAxes
           app.emDynamicsXposDisplacementPlotHandle = plot(app.emDynamicsAxes, [1 1], [nan nan], 'r-', 'LineWidth', 2.0);
           hold(app.emDynamicsAxes, 'on');
           %app.emDynamicsXposRandomizedIntervalDisplacementPlotHandle = plot(app.emDynamicsAxes, [1 1], [nan nan], 'r--', 'LineWidth', 1.0);
           app.emDynamicsXposRandomWalkDisplacementPlotHandle = plot(app.emDynamicsAxes, [1 1], [nan nan], 'k--', 'LineWidth', 1.0);
           app.emDynamicsYposDisplacementPlotHandle = plot(app.emDynamicsAxes, [1 1], [nan nan], 'b-', 'LineWidth', 2.0);
           %app.emDynamicsYposRandomizedIntervalDisplacementPlotHandle = plot(app.emDynamicsAxes, [1 1], [nan nan], 'b--', 'LineWidth', 1.0);
           app.emDynamicsYposRandomWalkDisplacementPlotHandle = plot(app.emDynamicsAxes, [1 1], [nan nan], 'k--', 'LineWidth', 1.0);
           hold(app.emDynamicsAxes, 'off');
           displacementTicks = [0.03 0.1 0.3 1 3 10 30];
           set(app.emDynamicsAxes, 'BackgroundColor', [0.94 0.94 0.94], 'FontSize', 12, ...
               'YTick', [0.1 0.3 1 3 10 30], 'YTickLabel', sprintf('%0.2f\n', displacementTicks), ...
               'XLim', [1 1000], 'XTick', [2 4 10 20 40 100 200 400 1000], 'XScale', 'log', 'YScale', 'log');
           xlabel(app.emDynamicsAxes, 'time interval (ms)');
           ylabel(app.emDynamicsAxes, 'displacement (arc min)');
           
  
           % Add plots to the emMicroSaccadeIntervalAxes
           xData = 0:50:1000;
           yData = xData *0;
           app.emMicroSaccadeIntervalsDistributionPlotHandle = bar(app.emMicroSaccadeIntervalAxes, xData, yData, 0.75);
           set(app.emMicroSaccadeIntervalsDistributionPlotHandle, 'FaceColor', [1 0.7 0.6], 'EdgeColor', [1 0 0]);
           hold(app.emMicroSaccadeIntervalAxes, 'on');
           app.emMicroSaccadeIntervalsInThisTrialHandle = plot(app.emMicroSaccadeIntervalAxes, nan,nan, 'bo', 'MarkerFaceColor', [0.2 0.95 0.4], 'MarkerSize', 8, 'LineWidth', 1.5);
           hold(app.emMicroSaccadeIntervalAxes, 'off');
           set(app.emMicroSaccadeIntervalAxes, 'BackgroundColor', [0.94 0.94 0.94], 'FontSize', 12, ...
               'YTick', [0:0.1:1.0], 'YTickLabel', sprintf('%0.2f\n', 0:0.1:1.0), 'YLim', [0 1],...
               'XLim', [0 1000], 'XTick', [0:100:1000]);
           xlabel(app.emMicroSaccadeIntervalAxes, 'micro-saccade interval (ms)');
           ylabel(app.emMicroSaccadeIntervalAxes, 'count');
           
           % Add plots to the emMicroSaccadeJointAmplitudeSpeedAxes
           xData = 0:2:60;
           yData = xData *0;
           app.emMicroSaccadeAmplitudesDistributionPlotHandle = bar(app.emMicroSaccadeJointAmplitudeSpeedAxes, xData, yData, 0.75);
           set(app.emMicroSaccadeAmplitudesDistributionPlotHandle, 'FaceColor', [0.6 0.8 1.0], 'EdgeColor', [0 0 1]);
           hold(app.emMicroSaccadeJointAmplitudeSpeedAxes, 'on');
           app.emMicroSaccadeAmplitudeSpeedsDistributionHandle = plot(app.emMicroSaccadeJointAmplitudeSpeedAxes, nan,nan, 'k.', 'MarkerEdgeColor', [1 0.7 0.6]);
           app.emMicroSaccadeAmplitudeSpeedsInThisTrialHandle = plot(app.emMicroSaccadeJointAmplitudeSpeedAxes, nan,nan, 'bo', 'MarkerFaceColor', [0.2 0.95 0.4], 'MarkerSize', 8, 'LineWidth', 1.0);
           hold(app.emMicroSaccadeJointAmplitudeSpeedAxes, 'off');
           set(app.emMicroSaccadeJointAmplitudeSpeedAxes, 'BackgroundColor', [0.94 0.94 0.94], 'FontSize', 12, ...
               'YTick', [0:5:60], 'YLim', [0 60], ...
               'XLim', [0 30], 'XTick', [0:5:30]);
           xlabel(app.emMicroSaccadeJointAmplitudeSpeedAxes, 'micro-saccade amplitude (arc min)');
           ylabel(app.emMicroSaccadeJointAmplitudeSpeedAxes, 'micro-saccade speed (degs/sec)');
           
           
           % Add plots to the app.emHeatMapAxes
           app.emHeatMapPlotHandle = imagesc(app.emHeatMapAxes, [-1 1], [-1 1], [0 0; 0 0]);
           hold(app.emHeatMapAxes, 'on');
           % Add cross hairs
           plot(app.emHeatMapAxes, [0 0], [-1000 100], 'c-', 'LineWidth', 1.5);
           plot(app.emHeatMapAxes, [-1000 100], [0 0], 'c-', 'LineWidth', 1.5);
           hold(app.emHeatMapAxes, 'off');
           axis(app.emHeatMapAxes, 'xy');
           axis(app.emHeatMapAxes, 'square');
           box(app.emHeatMapAxes, 'on');
           set(app.emHeatMapAxes, 'FontSize', 12, 'YColor', 'none');
           xlabel(app.emHeatMapAxes, 'x-position (arc min)');
           ylabel(app.emHeatMapAxes, ' ');
           colormap(app.emHeatMapAxes, hot(1024));
           
           % Add plots to the app.emMicroSaccadeTargetLikelihoodMapAxes
           app.emMicroSaccadeTargetLikelihoodMapHandle = imagesc(app.emMicroSaccadeTargetLikelihoodMapAxes, [-1 1], [-1 1], [0 0; 0 0]);
           hold(app.emMicroSaccadeTargetLikelihoodMapAxes, 'on');
           % Add cross hairs
           plot(app.emMicroSaccadeTargetLikelihoodMapAxes, [0 0], [-1000 100], 'c-', 'LineWidth', 1.5);
           plot(app.emMicroSaccadeTargetLikelihoodMapAxes, [-1000 100], [0 0], 'c-', 'LineWidth', 1.5);
           app.emMicroSaccadePositionHandle = plot(app.emMicroSaccadeTargetLikelihoodMapAxes, 0,0,'rs', 'MarkerSize', 10, 'LineWidth', 1.5);
           hold(app.emMicroSaccadeTargetLikelihoodMapAxes, 'off');
           axis(app.emMicroSaccadeTargetLikelihoodMapAxes, 'xy');
           axis(app.emMicroSaccadeTargetLikelihoodMapAxes, 'square');
           box(app.emMicroSaccadeTargetLikelihoodMapAxes, 'on');
           set(app.emMicroSaccadeTargetLikelihoodMapAxes, 'FontSize', 12, 'YColor', 'none');
           xlabel(app.emMicroSaccadeTargetLikelihoodMapAxes, 'x-position (arc min)');
           ylabel(app.emMicroSaccadeTargetLikelihoodMapAxes, ' ');
           colormap(app.emMicroSaccadeTargetLikelihoodMapAxes, bone(1024));
        end
        
        % Method to generate new eye movement trial data
        function generateNewEyeMovementPath(app)
            % Check timer status
            needToRestartTimer = false;
            if strcmp(app.interactiveVisualizationStatus, 'running')
                stop(app.interactiveVisualizationTimer);
                needToRestartTimer = true;
            end
            % Generate new data
            app.fixationalEMobj.displayComputeProgress = true;
            nTrials = 1;
            computeVelocity = true;
            app.fixationalEMobj.compute(app.trialDurationSeconds, app.sampleDurationSeconds, nTrials, computeVelocity);
            
            % Retrieve propected properties of the @fixationalEM class for visualization here
            retrieveProtectedPropertiesForVisualization(app);
            
            % Perform and Visualize displacement analysis
            performAndVisualizeDisplacementAnalysis(app);
            
            % Perform and Visualize microsaccade stats
            performAndVisualizeMicroSaccadeStatAnalysis(app);
            
            % Reset heat map/microSaccadeTargetLikelihood map
            resetVisualizedSaccadeMaps(app);
            
            % Set visualized timer step to 0
            app.earliestVisualizedTimeStep = 0;
            incrementTimeStep = true;
            app.emMicroSaccadeIndex = [];
            updateInteractiveVisualizations(app, incrementTimeStep); 
            
            % Restart timer
            if (needToRestartTimer)
                start(app.interactiveVisualizationTimer);
            end
        end 
        
        function retrieveProtectedPropertiesForVisualization(app)
            % Properties (that are internal to the model and used only for enhanced visualization of the model)
            app.visualizablePropertiesDictionary = containers.Map();
            for k = 1:numel(app.visualizablePropertyNames)
                app.visualizablePropertiesDictionary(app.visualizablePropertyNames{k}) = app.fixationalEMobj.getValueOfProperty(app.visualizablePropertyNames{k});
            end
        end
        
        
        function resetVisualizedSaccadeMaps(app)
            % Update heat map
            heatMapXYsupport = app.visualizablePropertiesDictionary('heatMapXYsupport');
            set(app.emHeatMapAxes, ...
                'XLim', [heatMapXYsupport(1) heatMapXYsupport(end)], ...
                'YLim', [heatMapXYsupport(1) heatMapXYsupport(end)], ...
                'CLim', [0 1]);
            set(app.emHeatMapPlotHandle, ...
                'XData', heatMapXYsupport, ...
                'YData', heatMapXYsupport);
     
            % Update microSaccadeTargetLikelihood map
            set(app.emMicroSaccadeTargetLikelihoodMapAxes, ...
                'XLim', [heatMapXYsupport(1) heatMapXYsupport(end)], ...
                'YLim', [heatMapXYsupport(1) heatMapXYsupport(end)], ...
                'CLim', [0 1]);
            set(app.emMicroSaccadeTargetLikelihoodMapHandle, ...
                'XData', heatMapXYsupport, ...
                'YData', heatMapXYsupport);
            set(app.emMicroSaccadePositionHandle, 'XData', 0, 'YData', 0);
        end
        
        function performAndVisualizeDisplacementAnalysis(app)
            visualizedXYposPath = app.visualizablePropertiesDictionary('emPosTimeSeriesArcMin');
            % Compute displacement analysis for the x-Pos
            [meanD1, maxD1, Dscrambled, DrandomWalk, timeLagsMilliseconds] = ...
                app.fixationalEMobj.performDisplacementAnalysis(...
                squeeze(visualizedXYposPath(1,:)), ...
                app.fixationalEMobj.timeAxis);
            set(app.emDynamicsXposDisplacementPlotHandle, 'XData', timeLagsMilliseconds, 'YData', meanD1);
            set(app.emDynamicsYposRandomWalkDisplacementPlotHandle, 'XData', timeLagsMilliseconds, 'YData', DrandomWalk);
            
            % Compute displacement analysis for the y-Pos
            [meanD2, maxD2, Dscrambled, DrandomWalk, timeLagsMilliseconds] = ...
                app.fixationalEMobj.performDisplacementAnalysis(...
                squeeze(visualizedXYposPath(2,:)), ...
                app.fixationalEMobj.timeAxis);
            set(app.emDynamicsYposDisplacementPlotHandle, 'XData', timeLagsMilliseconds, 'YData', meanD2);
            set(app.emDynamicsYposRandomWalkDisplacementPlotHandle, 'XData', timeLagsMilliseconds, 'YData', DrandomWalk);
            
            % Update displacement range
            %displacementRange = [min([min(D1) min(D2)]) max([max(D1) max(D2)])];
            displacementRange = [0.03 30];
            set(app.emDynamicsAxes, 'YLim', displacementRange);
        end
        
        function updateGUIcontrols(app)
            % Update time machine slider value
            app.visualizedTimeSamplesNum = round(app.visualizedDurationSeconds/app.fixationalEMobj.timeStepDurationSeconds);
            tRange = app.timeMachineSlider.Limits;
            app.timeMachineSlider.Value = max([tRange(1) min([ tRange(2) (app.earliestVisualizedTimeStep+app.visualizedTimeSamplesNum+1)/numel(app.fixationalEMobj.timeAxis) ]) ]);
        end
        
        function performAndVisualizeMicroSaccadeStatAnalysis(app)
            if (strcmp(app.fixationalEMobj.microSaccadeType, 'none'))
                set(app.emMicroSaccadeIntervalsDistributionPlotHandle,'XData', nan, 'YData', nan);
                set(app.emMicroSaccadeIntervalsInThisTrialHandle, 'XData', nan, 'YData', nan);
                set(app.emMicroSaccadeAmplitudeSpeedsInThisTrialHandle, 'XData', nan, 'YData', nan);
                set(app.emMicroSaccadeAmplitudeSpeedsDistributionHandle, 'XData', nan, 'YData', nan);
            else
                % Update microsaccade intervals distribution
                bins = [0:50:1000];
                [n, edges] = histcounts(app.visualizablePropertiesDictionary('microSaccadeIntervalsMilliSecondsDist'), bins);
                % distribution
                set(app.emMicroSaccadeIntervalsDistributionPlotHandle, 'XData', edges(1:end-1), 'YData', n/max(n));
                % actual events
                set(app.emMicroSaccadeIntervalsInThisTrialHandle, ...
                    'XData', app.visualizablePropertiesDictionary('microSaccadeIntervalsMilliseconds'), ...
                    'YData', 0.5 + 0.03*randn(1,numel(app.visualizablePropertiesDictionary('microSaccadeIntervalsMilliseconds'))));
                
                % Update microsaccade amplitude/speed distribution
                if (strcmp(app.fixationalEMobj.microSaccadeType, 'stats based'))
                    %bins = [0:2:60];
                    %[n, edges] = histcounts(app.visualizablePropertiesDictionary('microSaccadeAmplitudesArcMinDist'), bins);
                    % distribution of amplitudes as a histogram
                    %set(app.emMicroSaccadeAmplitudesDistributionPlotHandle, 'XData', edges(1:end-1), 'YData', n/max(n)*60);
                    % joint distribution of amplitudes/speeds as dots
                    set(app.emMicroSaccadeAmplitudeSpeedsDistributionHandle, ...
                        'XData', app.visualizablePropertiesDictionary('microSaccadeAmplitudesArcMinDist'), ...
                        'YData', app.visualizablePropertiesDictionary('microSaccadeSpeedDegsPerSecondDist'));
                else
                    set(app.emMicroSaccadeAmplitudesDistributionPlotHandle,'XData', nan, 'YData', nan);
                    set(app.emMicroSaccadeAmplitudeSpeedsDistributionHandle,'XData', nan, 'YData', nan);
                end
                % actual events
                set(app.emMicroSaccadeAmplitudeSpeedsInThisTrialHandle, ...
                    'XData', app.visualizablePropertiesDictionary('microSaccadeAmplitudesArcMin'), ...
                    'YData', app.visualizablePropertiesDictionary('microSaccadeSpeedsDegsPerSecond'));
            end
        end
        
        function initParameterKnobsOnGUI(app)
            app.initDriftParameterKnobsOnGUI();
            app.initMicroSaccadeParameterKnobsOnGUI();
        end
        
        function initDriftParameterKnobsOnGUI(app)
            % Control gain knob (discrete knob, so use interfacing function)
            app.setKnobValue(app.fixationalEMobj.controlGamma, '%1.2f', app.controlGainKnob);

            % Control noise (continous knob, set directly)
            set(app.controlNoiseKnob, 'Value', app.fixationalEMobj.controlNoiseStd);
            
            % Feedback gain knob (discrete knob, so use interfacing function)
            app.setKnobValue(app.fixationalEMobj.feedbackGain, '%1.2f', app.feedbackGainKnob);
            
            % Feedback steepness knob (discrete knov, so use interfacing function)
            app.setKnobValue(app.fixationalEMobj.feedbackSteepness, '%1.1f', app.feedbackSteepnessKnob)
            
            % Feedback delay (ms), X (continous knob, set directly)
            set(app.feedbackDelayXKnob, 'Value', app.fixationalEMobj.feedbackXposDelaySeconds*1000);
            
            % Feedback delay (ms), Y (continous knob, set directly)
            set(app.feedbackDelayYKnob, 'Value', app.fixationalEMobj.feedbackYposDelaySeconds*1000);
            
            % Positional noise (continous knob, set directly)
            set(app.positionalNoiseKnob, 'Value', app.fixationalEMobj.positionNoiseStd);
        end
        
        function initMicroSaccadeParameterKnobsOnGUI(app)
            % MICROSACCADE PARAMS
            
            % Microsaccade mean interval (seconds) (continous knob, set directly)
            set(app.microSaccadeIntervalKnob, 'Value', app.fixationalEMobj.microSaccadeMeanIntervalSeconds);

            % Microsaccade mean amplitude (arc min) (continous knob, set directly)
            set(app.microSaccadeAmplitudeKnob, 'Value', app.fixationalEMobj.microSaccadeMeanAmplitudeArcMin);
            
            % Heatmap-fixation balance knob
            set(app.heatmapFixationBalanceKnob, 'Value', app.fixationalEMobj.heatMapWeight)
            
            % Heatmap space/time constants
            set(app.heatmapSpaceConstantKnob, 'Value', app.fixationalEMobj.heatMapKernelSpaceConstantArcMin);
            set(app.heatmapTimeConstantKnob, 'Value', app.fixationalEMobj.heatMapKernelTimeConstantSeconds);
             
            % Fixation map space constant
            set(app.fixationMapSpaceConstantKnob, 'Value', app.fixationalEMobj.fixationMapSpaceConstantArcMin);
            
            % Microsaccade type and knob activation states
            setMicroSaccadeKnobActivationStates(app);
        end
        
        % Method to set the value of a discrete knob
        function setKnobValue(app, value, precisionString, knobHandle)
            valueAsString = sprintf(precisionString, value);
            items = knobHandle.Items;
            if ismember(valueAsString, items)
                 set(knobHandle, 'Value', valueAsString);
            else
                error('%s does not match the labels of the knob.\n', valueAsString);
            end
        end
        
        % Methot to set the activation state of microSaccade knobs depending on current microSaccadeType
        function setMicroSaccadeKnobActivationStates(app)
            switch(app.fixationalEMobj.microSaccadeType) 
                case 'heatmap/fixation based'
                    set(app.microSaccadeTypeKnob, 'Value', 'heatmap/fixation based');
                    set(app.microSaccadeAmplitudeKnob, 'Enable', 'off');
                    set(app.microSaccadeIntervalKnob, 'Enable', 'on');
                    set(app.fixationMapSpaceConstantKnob, 'Enable', 'on');
                    set(app.heatmapSpaceConstantKnob, 'Enable', 'on');
                    set(app.heatmapTimeConstantKnob, 'Enable', 'on');
                    set(app.heatmapFixationBalanceKnob, 'Enable', 'on');
                case 'none'
                    set(app.microSaccadeTypeKnob, 'Value', 'no saccades');
                    set(app.microSaccadeIntervalKnob, 'Enable', 'off');
                    set(app.microSaccadeAmplitudeKnob, 'Enable', 'off');
                    set(app.fixationMapSpaceConstantKnob, 'Enable', 'off');
                    set(app.heatmapSpaceConstantKnob, 'Enable', 'off');
                    set(app.heatmapTimeConstantKnob, 'Enable', 'off');
                    set(app.heatmapFixationBalanceKnob, 'Enable', 'off');
                case 'stats based'
                    set(app.microSaccadeTypeKnob, 'Value', 'stats based');
                    set(app.microSaccadeAmplitudeKnob, 'Enable', 'on');
                    set(app.microSaccadeIntervalKnob, 'Enable', 'on');
                    set(app.fixationMapSpaceConstantKnob, 'Enable', 'on');
                    set(app.heatmapSpaceConstantKnob, 'Enable', 'off');
                    set(app.heatmapTimeConstantKnob, 'Enable', 'off');
                    set(app.heatmapFixationBalanceKnob, 'Enable', 'off');
                otherwise
                    error('Unknown microsacadeType: ''%s''.', app.fixationalEMobj.microSaccadeType);
            end
        end
        
        function jumpToMicroSaccade(app,whichDirection)     
            needToRestartTimer = false;
            if strcmp(app.interactiveVisualizationStatus, 'running')
                stop(app.interactiveVisualizationTimer);
                needToRestartTimer = true;
            end

            % Move to next saccade
            microSaccadeOnsetStepIndices = app.visualizablePropertiesDictionary('microSaccadeOnsetStepIndices');
            if (~isempty(microSaccadeOnsetStepIndices))
                
                % Are we exactly on the onset of a microsaccade ?
                exactlyOnOnsetOfMicroSaccade = ~isempty(find(app.emLastVisualizedSample == microSaccadeOnsetStepIndices+1, 1));
            
                if (isempty(app.emMicroSaccadeIndex))
                    app.emMicroSaccadeIndex = 1;
                else
                    if (~exactlyOnOnsetOfMicroSaccade) && (whichDirection < 0)
                        app.earliestVisualizedTimeStep = microSaccadeOnsetStepIndices(app.emMicroSaccadeIndex)-app.visualizedTimeSamplesNum+1;
                    else
                        app.emMicroSaccadeIndex = app.emMicroSaccadeIndex+whichDirection;
                        app.emMicroSaccadeIndex = min([numel(microSaccadeOnsetStepIndices) max([1 app.emMicroSaccadeIndex])]);
                        app.earliestVisualizedTimeStep = microSaccadeOnsetStepIndices(app.emMicroSaccadeIndex)-app.visualizedTimeSamplesNum+1;
                    end
                end
                
                incrementTimeStep = false;
                updateInteractiveVisualizations(app, incrementTimeStep); 
            end      
            
            % Restart timer if we need to
            if (needToRestartTimer)
                start(app.interactiveVisualizationTimer);
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Instantiate a fixationalEM object with default params
            app.fixationalEMobj = visualizableFixationalEM();
            % Coarser grid to make visualization faster
            %app.fixationalEMobj.heatMapWidthArcMin = 45;
            %app.fixationalEMobj.heatMapSpatialSampleArcMin = 3;
            
            % Initialize the parameter knobs on the GUI to the defaults set in the @fixationEM class.
            initParameterKnobsOnGUI(app);
            
            % Initialize plots
            initPlots(app);
            
            % Generate eye movement path
            generateNewEyeMovementPath(app);
            
            % Setup timer
            initTimer(app);
            
            % Show initial data
            incrementTimeStep = true;
            updateInteractiveVisualizations(app, incrementTimeStep);
        end

        % Close request function: 
        % FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.interactiveVisualizationTimer);
            delete(app.interactiveVisualizationTimer);
            delete(app);  
        end

        % Value changed function: controlGainKnob
        function controlGainKnobValueChanged(app, event)
            % Update fixationalEMobj.controlGamma parameter
            value = app.controlGainKnob.Value;
            app.fixationalEMobj.controlGamma = str2double(value);
            % Generate new eye movement
            generateNewEyeMovementPath(app);
        end

        % Value changed function: controlNoiseKnob
        function controlNoiseKnobValueChanged(app, event)
            % Update fixationalEMobj.controlNoiseStd parameter
            value = app.controlNoiseKnob.Value;
            app.fixationalEMobj.controlNoiseStd = value;
            % Generate new eye movement
            generateNewEyeMovementPath(app);
        end

        % Value changed function: feedbackDelayXKnob
        function feedbackDelayXKnobValueChanged(app, event)
            % Update fixationalEMobj.feedbackXposDelaySeconds parameter
            value = app.feedbackDelayXKnob.Value;
            app.fixationalEMobj.feedbackXposDelaySeconds = value/1000;
            % Generate new eye movement
            generateNewEyeMovementPath(app);
        end

        % Value changed function: feedbackDelayYKnob
        function feedbackDelayYKnobValueChanged(app, event)
            % Update fixationalEMobj feedbackYposDelaySeconds parameter
            value = app.feedbackDelayYKnob.Value;
            app.fixationalEMobj.feedbackYposDelaySeconds = value/1000;
            % Generate new eye movement
            generateNewEyeMovementPath(app);
        end

        % Value changed function: feedbackGainKnob
        function feedbackGainKnobValueChanged(app, event)
            % Update fixationalEMobj feedbackGain parameter
            value = app.feedbackGainKnob.Value;
            app.fixationalEMobj.feedbackGain = str2double(value);
            % Generate new eye movement
            generateNewEyeMovementPath(app);
        end

        % Value changed function: feedbackSteepnessKnob
        function feedbackSteepnessKnobValueChanged(app, event)
            % Update fixationalEMobj feedbackSteepness parameter
            value = app.feedbackSteepnessKnob.Value;
            app.fixationalEMobj.feedbackSteepness = str2double(value);
            % Generate new eye movement
            generateNewEyeMovementPath(app);
        end

        % Value changed function: fixationMapSpaceConstantKnob
        function fixationMapSpaceConstantKnobValueChanged(app, event)
            value = app.fixationMapSpaceConstantKnob.Value;
            app.fixationalEMobj.fixationMapSpaceConstantArcMin = value;
            % Generate new eye movement
            generateNewEyeMovementPath(app);
        end

        % Value changed function: heatmapFixationBalanceKnob
        function heatmapFixationBalanceKnobValueChanged(app, event)
            value = app.heatmapFixationBalanceKnob.Value;  
            % Update fixationalEMobj.heatMapWeight parameter
            app.fixationalEMobj.heatMapWeight = value;
            % Generate new eye movement
            generateNewEyeMovementPath(app);
            
        end

        % Value changed function: heatmapSpaceConstantKnob
        function heatmapSpaceConstantKnobValueChanged(app, event)
            value = app.heatmapSpaceConstantKnob.Value;
            app.fixationalEMobj.heatMapKernelSpaceConstantArcMin = value;
            % Generate new eye movement
            generateNewEyeMovementPath(app);
        end

        % Value changed function: heatmapTimeConstantKnob
        function heatmapTimeConstantKnobValueChanged(app, event)
            value = app.heatmapTimeConstantKnob.Value;
            app.fixationalEMobj.heatMapKernelTimeConstantSeconds = value; 
            % Generate new eye movement
            generateNewEyeMovementPath(app);
            
        end

        % Button pushed function: jumpToLastMicroSaccadeTime
        function jumpToLastMicroSaccadeTimeButtonPushed(app, event)
            jumpToMicroSaccade(app,-1);
        end

        % Button pushed function: jumpToNextMicroSaccadeTime
        function jumpToNextMicroSaccadeTimeButtonPushed(app, event)
            jumpToMicroSaccade(app,1);
        end

        % Value changed function: microSaccadeAmplitudeKnob
        function microSaccadeAmplitudeKnobValueChanged(app, event)
            value = app.microSaccadeAmplitudeKnob.Value;
            app.fixationalEMobj.microSaccadeMeanAmplitudeArcMin = value;
            % Generate new eye movement
            generateNewEyeMovementPath(app);
        end

        % Value changed function: microSaccadeIntervalKnob
        function microSaccadeIntervalKnobValueChanged(app, event)
            value = app.microSaccadeIntervalKnob.Value;
            app.fixationalEMobj.microSaccadeMeanIntervalSeconds = value;
            % Generate new eye movement
            generateNewEyeMovementPath(app);
        end

        % Value changed function: microSaccadeTypeKnob
        function microSaccadeTypeKnobValueChanged(app, event)
            value = app.microSaccadeTypeKnob.Value;
            switch value
                case 'no saccades'
                    microSaccadeType = 'none';
                case 'heatmap/fixation based'
                    microSaccadeType = 'heatmap/fixation based';
                case 'stats based'
                    microSaccadeType  = 'stats based';
                otherwise
                    error('Unknown microsaccade type: ''%s''.', value);   
            end
            app.fixationalEMobj.microSaccadeType = microSaccadeType;
            setMicroSaccadeKnobActivationStates(app);
            % Generate new eye movement
            generateNewEyeMovementPath(app);
        end

        % Button pushed function: newTrialButton
        function newTrialButtonPushed(app, event)
            generateNewEyeMovementPath(app);
        end

        % Value changing function: positionScaleSlider
        function positionScaleSliderValueChanging(app, event)
            % Get new visualized EM range
            changingValue = event.Value;        
            app.visualizedEMrange = changingValue;
            set(app.mosaicPlotHandle, 'MarkerSize', 100/app.visualizedEMrange);
            % Update the visualized data
            if strcmp(app.interactiveVisualizationStatus, 'paused')
                incrementTimeStep = false;
                updateInteractiveVisualizations(app, incrementTimeStep);
            end
        end

        % Value changed function: positionalNoiseKnob
        function positionalNoiseKnobValueChanged(app, event)
            % Update fixationalEMobj.positionNoiseStdDegs parameter
            value = app.positionalNoiseKnob.Value;
            app.fixationalEMobj.positionNoiseStd = value;
            % Generate new eye movement
            generateNewEyeMovementPath(app);
        end

        % Value changed function: runPauseButton
        function runPauseButtonPushed(app, event)
            if (strcmp(app.interactiveVisualizationStatus, 'running'))
                stop(app.interactiveVisualizationTimer);
            else
                start(app.interactiveVisualizationTimer);
            end
        end

        % Value changing function: timeMachineSlider
        function timeMachineSliderValueChanging(app, event)
        % Check timer status and stop if we are running
            needToRestartTimer = false;
            if strcmp(app.interactiveVisualizationStatus, 'running')
                stop(app.interactiveVisualizationTimer);
                needToRestartTimer = true;
            end
            
            % Set visualized timer step
            changingValue = event.Value;
            app.visualizedTimeSamplesNum = round(app.visualizedDurationSeconds/app.fixationalEMobj.timeStepDurationSeconds);
            app.earliestVisualizedTimeStep = floor(changingValue*(numel(app.fixationalEMobj.timeAxis)))-app.visualizedTimeSamplesNum-1;
            if (app.earliestVisualizedTimeStep <= 1)
                app.earliestVisualizedTimeStep = 1;
            end
            incrementTimeStep = false;
            updateInteractiveVisualizations(app, incrementTimeStep);
            
            % Restart timer if we need to
            if (needToRestartTimer)
                start(app.interactiveVisualizationTimer);
            end
        end

        % Value changing function: timeScaleSlider
        function timeScaleSliderValueChanging(app, event)
            % Get new visualization duration
            changingValue = event.Value;
            app.visualizedDurationSeconds = changingValue;
            % Update the visualized data
            if strcmp(app.interactiveVisualizationStatus, 'paused')
                incrementTimeStep = false;
                updateInteractiveVisualizations(app, incrementTimeStep);
            end
        end

        % Value changing function: visualizationSpeedSlider
        function visualizationSpeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.visualizationSpeedStepsNum = min([10 max([1 round(changingValue)])]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure = uifigure('Visible', 'off');
            app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure.Name = 'Fixational eye movements demo (NPC, ISETBIO Team, 2018)';
            app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.emTimeSeriesAxes = uiaxes(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            xlabel(app.emTimeSeriesAxes, 'X')
            ylabel(app.emTimeSeriesAxes, 'Y')
            app.emPathAxes = uiaxes(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            xlabel(app.emPathAxes, 'X')
            ylabel(app.emPathAxes, 'Y')
            app.emDynamicsAxes = uiaxes(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            xlabel(app.emDynamicsAxes, 'X')
            ylabel(app.emDynamicsAxes, 'Y')
            app.emTimeSeriesVelocityAxes = uiaxes(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            xlabel(app.emTimeSeriesVelocityAxes, 'X')
            ylabel(app.emTimeSeriesVelocityAxes, 'Y')
            app.emHeatMapAxes = uiaxes(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            xlabel(app.emHeatMapAxes, 'X')
            ylabel(app.emHeatMapAxes, 'Y')
            app.emMicroSaccadeTargetLikelihoodMapAxes = uiaxes(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            xlabel(app.emMicroSaccadeTargetLikelihoodMapAxes, 'X')
            ylabel(app.emMicroSaccadeTargetLikelihoodMapAxes, 'Y')
            app.emMicroSaccadeIntervalAxes = uiaxes(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            xlabel(app.emMicroSaccadeIntervalAxes, 'X')
            ylabel(app.emMicroSaccadeIntervalAxes, 'Y')
            app.emMicroSaccadeJointAmplitudeSpeedAxes = uiaxes(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            xlabel(app.emMicroSaccadeJointAmplitudeSpeedAxes, 'X')
            ylabel(app.emMicroSaccadeJointAmplitudeSpeedAxes, 'Y')
            app.DRIFTparametersPanel = uipanel(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            app.DRIFTparametersPanel.Title = 'DRIFT parameters';
            app.controlgainKnobLabel = uilabel(app.DRIFTparametersPanel);
            app.controlgainKnobLabel.Text = 'control gain';
            app.controlGainKnob = uiknob(app.DRIFTparametersPanel, 'discrete');
            app.controlGainKnob.Items = {'0.14', '0.17', '0.20', '0.25', '0.30', '0.40', '0.50', '1.0'};
            app.controlGainKnob.ValueChangedFcn = createCallbackFcn(app, @controlGainKnobValueChanged, true);
            app.controlGainKnob.Value = '0.14';
            app.feedbackgainKnobLabel = uilabel(app.DRIFTparametersPanel);
            app.feedbackgainKnobLabel.Text = 'feedback gain';
            app.feedbackGainKnob = uiknob(app.DRIFTparametersPanel, 'discrete');
            app.feedbackGainKnob.Items = {'-0.15', '0.00', '0.15', '0.17', '0.19', '0.21', '0.23', '0.25'};
            app.feedbackGainKnob.ValueChangedFcn = createCallbackFcn(app, @feedbackGainKnobValueChanged, true);
            app.feedbackGainKnob.Value = '-0.15';
            app.feedbacksteepnessKnobLabel = uilabel(app.DRIFTparametersPanel);
            app.feedbacksteepnessKnobLabel.Text = 'feedback steepness';
            app.feedbackSteepnessKnob = uiknob(app.DRIFTparametersPanel, 'discrete');
            app.feedbackSteepnessKnob.Items = {'0.7', '0.9', '1.1', '1.2', '1.3', '1.4'};
            app.feedbackSteepnessKnob.ValueChangedFcn = createCallbackFcn(app, @feedbackSteepnessKnobValueChanged, true);
            app.feedbackSteepnessKnob.Value = '0.7';
            app.feedbackdelayxLabel = uilabel(app.DRIFTparametersPanel);
            app.feedbackdelayxLabel.Text = 'feedback delay (x)';
            app.feedbackDelayXKnob = uiknob(app.DRIFTparametersPanel, 'continuous');
            app.feedbackDelayXKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.feedbackDelayXKnob.ValueChangedFcn = createCallbackFcn(app, @feedbackDelayXKnobValueChanged, true);
            app.feedbackdelayyLabel = uilabel(app.DRIFTparametersPanel);
            app.feedbackdelayyLabel.Text = 'feedback delay (y)';
            app.feedbackDelayYKnob = uiknob(app.DRIFTparametersPanel, 'continuous');
            app.feedbackDelayYKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.feedbackDelayYKnob.ValueChangedFcn = createCallbackFcn(app, @feedbackDelayYKnobValueChanged, true);
            app.controlnoiseKnobLabel = uilabel(app.DRIFTparametersPanel);
            app.controlnoiseKnobLabel.Text = 'control noise';
            app.controlNoiseKnob = uiknob(app.DRIFTparametersPanel, 'continuous');
            app.controlNoiseKnob.Limits = [0 0.1];
            app.controlNoiseKnob.MajorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1];
            app.controlNoiseKnob.MajorTickLabels = {'.00', '.01', '.02', '.03', '.04', '.05', '.06', '.07', '.08', '.09', '.10'};
            app.controlNoiseKnob.ValueChangedFcn = createCallbackFcn(app, @controlNoiseKnobValueChanged, true);
            app.positionalnoiseLabel = uilabel(app.DRIFTparametersPanel);
            app.positionalnoiseLabel.Text = 'positional noise';
            app.positionalNoiseKnob = uiknob(app.DRIFTparametersPanel, 'continuous');
            app.positionalNoiseKnob.Limits = [0 0.7];
            app.positionalNoiseKnob.ValueChangedFcn = createCallbackFcn(app, @positionalNoiseKnobValueChanged, true);
            app.PLAYBACKcontrolsPanel = uipanel(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            app.PLAYBACKcontrolsPanel.Title = 'PLAYBACK controls';
            app.playbacktimeLabel = uilabel(app.PLAYBACKcontrolsPanel);
            app.playbacktimeLabel.Text = 'playback time';
            app.timeMachineSlider = uislider(app.PLAYBACKcontrolsPanel);
            app.timeMachineSlider.Limits = [0 1];
            app.timeMachineSlider.MajorTicks = [];
            app.timeMachineSlider.MajorTickLabels = {};
            app.timeMachineSlider.ValueChangingFcn = createCallbackFcn(app, @timeMachineSliderValueChanging, true);
            app.timeMachineSlider.MinorTicks = [];
            app.newTrialButton = uibutton(app.PLAYBACKcontrolsPanel, 'push');
            app.newTrialButton.ButtonPushedFcn = createCallbackFcn(app, @newTrialButtonPushed, true);
            app.newTrialButton.Text = 'new trial';
            app.jumpToNextMicroSaccadeTime = uibutton(app.PLAYBACKcontrolsPanel, 'push');
            app.jumpToNextMicroSaccadeTime.ButtonPushedFcn = createCallbackFcn(app, @jumpToNextMicroSaccadeTimeButtonPushed, true);
            app.jumpToNextMicroSaccadeTime.Text = 'next u-saccade';
            app.playbackspeedLabel = uilabel(app.PLAYBACKcontrolsPanel);
            app.playbackspeedLabel.Text = 'playback speed';
            app.visualizationSpeedSlider = uislider(app.PLAYBACKcontrolsPanel);
            app.visualizationSpeedSlider.Limits = [1 10];
            app.visualizationSpeedSlider.MajorTicks = [];
            app.visualizationSpeedSlider.MajorTickLabels = {};
            app.visualizationSpeedSlider.ValueChangingFcn = createCallbackFcn(app, @visualizationSpeedSliderValueChanging, true);
            app.visualizationSpeedSlider.MinorTicks = [];
            app.visualizationSpeedSlider.Value = 5;
            app.jumpToLastMicroSaccadeTime = uibutton(app.PLAYBACKcontrolsPanel, 'push');
            app.jumpToLastMicroSaccadeTime.ButtonPushedFcn = createCallbackFcn(app, @jumpToLastMicroSaccadeTimeButtonPushed, true);
            app.jumpToLastMicroSaccadeTime.Text = 'last u-saccade';
            app.runPauseButton = uibutton(app.PLAYBACKcontrolsPanel, 'state');
            app.runPauseButton.ValueChangedFcn = createCallbackFcn(app, @runPauseButtonPushed, true);
            app.runPauseButton.Text = '>>';
            app.SACCADEparametersPanel = uipanel(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            app.SACCADEparametersPanel.Title = 'μ-SACCADE parameters';
            app.generationmechanismLabel = uilabel(app.SACCADEparametersPanel);
            app.generationmechanismLabel.Text = 'generation mechanism';
            app.microSaccadeTypeKnob = uiknob(app.SACCADEparametersPanel, 'discrete');
            app.microSaccadeTypeKnob.Items = {'no saccades', 'heatmap/fixation based', 'stats based'};
            app.microSaccadeTypeKnob.ValueChangedFcn = createCallbackFcn(app, @microSaccadeTypeKnobValueChanged, true);
            app.microSaccadeTypeKnob.Value = 'no saccades';
            app.meanintervalsecsLabel = uilabel(app.SACCADEparametersPanel);
            app.meanintervalsecsLabel.Text = 'mean interval (secs)';
            app.microSaccadeIntervalKnob = uiknob(app.SACCADEparametersPanel, 'continuous');
            app.microSaccadeIntervalKnob.Limits = [0 1];
            app.microSaccadeIntervalKnob.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.microSaccadeIntervalKnob.MajorTickLabels = {'0.0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1.0'};
            app.microSaccadeIntervalKnob.ValueChangedFcn = createCallbackFcn(app, @microSaccadeIntervalKnobValueChanged, true);
            app.meanamplitudearcminLabel = uilabel(app.SACCADEparametersPanel);
            app.meanamplitudearcminLabel.Text = 'mean amplitude (arc min)';
            app.microSaccadeAmplitudeKnob = uiknob(app.SACCADEparametersPanel, 'continuous');
            app.microSaccadeAmplitudeKnob.Limits = [0 30];
            app.microSaccadeAmplitudeKnob.MajorTicks = [0 5 10 15 20 25 30];
            app.microSaccadeAmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @microSaccadeAmplitudeKnobValueChanged, true);
            app.mapbalanceLabel = uilabel(app.SACCADEparametersPanel);
            app.mapbalanceLabel.Text = 'map balance';
            app.heatmapFixationBalanceKnob = uiknob(app.SACCADEparametersPanel, 'continuous');
            app.heatmapFixationBalanceKnob.Limits = [0 1];
            app.heatmapFixationBalanceKnob.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.heatmapFixationBalanceKnob.MajorTickLabels = {'fixation', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', 'heatmap'};
            app.heatmapFixationBalanceKnob.ValueChangedFcn = createCallbackFcn(app, @heatmapFixationBalanceKnobValueChanged, true);
            app.HEATmapparametersPanel = uipanel(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            app.HEATmapparametersPanel.Title = 'HEAT map parameters';
            app.spaceconstantarcminLabel = uilabel(app.HEATmapparametersPanel);
            app.spaceconstantarcminLabel.Text = 'space constant (arc min)';
            app.heatmapSpaceConstantKnob = uiknob(app.HEATmapparametersPanel, 'continuous');
            app.heatmapSpaceConstantKnob.Limits = [0 5];
            app.heatmapSpaceConstantKnob.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.heatmapSpaceConstantKnob.MajorTickLabels = {'0.0', '0.5', '1.0', '1.5', '2.0', '2.5', '3.0', '3.5', '4.0', '4.5', '5.0'};
            app.heatmapSpaceConstantKnob.ValueChangedFcn = createCallbackFcn(app, @heatmapSpaceConstantKnobValueChanged, true);
            app.timeconstantsecsLabel = uilabel(app.HEATmapparametersPanel);
            app.timeconstantsecsLabel.Text = 'time constant (secs)';
            app.heatmapTimeConstantKnob = uiknob(app.HEATmapparametersPanel, 'continuous');
            app.heatmapTimeConstantKnob.Limits = [0 5];
            app.heatmapTimeConstantKnob.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.heatmapTimeConstantKnob.MajorTickLabels = {'0.0', '0.5', '1.0', '1.5', '2.0', '2.5', '3.0', '3.5', '4.0', '4.5', '5.0'};
            app.heatmapTimeConstantKnob.ValueChangedFcn = createCallbackFcn(app, @heatmapTimeConstantKnobValueChanged, true);
            app.FIXATIONmapparamsPanel = uipanel(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            app.FIXATIONmapparamsPanel.Title = 'FIXATION map params';
            app.spaceconstantarcminLabel_2 = uilabel(app.FIXATIONmapparamsPanel);
            app.spaceconstantarcminLabel_2.Text = 'space constant (arc min)';
            app.fixationMapSpaceConstantKnob = uiknob(app.FIXATIONmapparamsPanel, 'continuous');
            app.fixationMapSpaceConstantKnob.Limits = [0 50];
            app.fixationMapSpaceConstantKnob.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.fixationMapSpaceConstantKnob.ValueChangedFcn = createCallbackFcn(app, @fixationMapSpaceConstantKnobValueChanged, true);
            app.fixationMapSpaceConstantKnob.Value = 1;
            app.VISUALIZATIONcontrolsPanel = uipanel(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure);
            app.VISUALIZATIONcontrolsPanel.Title = 'VISUALIZATION controls';
            app.visualizeddurationsecondsLabel = uilabel(app.VISUALIZATIONcontrolsPanel);
            app.visualizeddurationsecondsLabel.Text = 'visualized duration (seconds)';
            app.timeScaleSlider = uislider(app.VISUALIZATIONcontrolsPanel);
            app.timeScaleSlider.Limits = [0.1 1.1];
            app.timeScaleSlider.ValueChangingFcn = createCallbackFcn(app, @timeScaleSliderValueChanging, true);
            app.timeScaleSlider.MinorTicks = [];
            app.timeScaleSlider.Value = 0.1;
            app.visualizedspacearcminLabel = uilabel(app.VISUALIZATIONcontrolsPanel);
            app.visualizedspacearcminLabel.Text = 'visualized space (arc min)';
            app.positionScaleSlider = uislider(app.VISUALIZATIONcontrolsPanel);
            app.positionScaleSlider.Limits = [0 60];
            app.positionScaleSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60];
            app.positionScaleSlider.ValueChangingFcn = createCallbackFcn(app, @positionScaleSliderValueChanging, true);
            app.positionScaleSlider.Value = 50;
            % Show the figure after all components are created
            app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fixationalEMdemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FixationaleyemovementsdemoNPCISETBIOTeam2018UIFigure)
        end
    end
end