classdef PointSpreadFunction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        hereButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        RadialordernLabel               matlab.ui.control.Label
        OSAjindexDropDown               matlab.ui.control.DropDown
        OSAjindexDropDownLabel          matlab.ui.control.Label
        EyeDropDown                     matlab.ui.control.DropDown
        EyeDropDownLabel                matlab.ui.control.Label
        PistonSlider_1                  matlab.ui.control.Slider
        PistonSlider_7                  matlab.ui.control.Slider
        PistonSlider_8                  matlab.ui.control.Slider
        PistonSlider_9                  matlab.ui.control.Slider
        PistonSlider_10                 matlab.ui.control.Slider
        PistonSlider_11                 matlab.ui.control.Slider
        PistonSlider_12                 matlab.ui.control.Slider
        PistonSlider_13                 matlab.ui.control.Slider
        PistonSlider_14                 matlab.ui.control.Slider
        PistonSlider_15                 matlab.ui.control.Slider
        Image_1                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        Image_6                         matlab.ui.control.Image
        Image_15                        matlab.ui.control.Image
        Image_14                        matlab.ui.control.Image
        Image_13                        matlab.ui.control.Image
        Image_12                        matlab.ui.control.Image
        Image_11                        matlab.ui.control.Image
        Image_10                        matlab.ui.control.Image
        Image_9                         matlab.ui.control.Image
        Image_8                         matlab.ui.control.Image
        Image_7                         matlab.ui.control.Image
        PistonSlider_6                  matlab.ui.control.Slider
        PistonSlider_5                  matlab.ui.control.Slider
        PistonSlider_4                  matlab.ui.control.Slider
        PistonSlider_3                  matlab.ui.control.Slider
        PistonSlider_2                  matlab.ui.control.Slider
        ButtonGroup                     matlab.ui.container.ButtonGroup
        FigureaxisrangeEditFieldLabel_2  matlab.ui.control.Label
        FigureaxisrangeEditField_2      matlab.ui.control.EditField
        GroupDropDownLabel_2            matlab.ui.control.Label
        SubjNum                         matlab.ui.control.EditField
        EccentricityEditField           matlab.ui.control.EditField
        EccentricityEditFieldLabel      matlab.ui.control.Label
        m4Label_2                       matlab.ui.control.Label
        m3Label_2                       matlab.ui.control.Label
        m2Label_2                       matlab.ui.control.Label
        m1Label_2                       matlab.ui.control.Label
        m4Label                         matlab.ui.control.Label
        m3Label                         matlab.ui.control.Label
        m1Label                         matlab.ui.control.Label
        m2Label                         matlab.ui.control.Label
        m0Label                         matlab.ui.control.Label
        AzimuthalFrequencymLabel        matlab.ui.control.Label
        n4Label                         matlab.ui.control.Label
        FigureaxisrangeEditField        matlab.ui.control.EditField
        n3Label                         matlab.ui.control.Label
        n2Label                         matlab.ui.control.Label
        n1Label                         matlab.ui.control.Label
        n0Label                         matlab.ui.control.Label
        MeasuredpupilEditField          matlab.ui.control.EditField
        MeasuredpupilEditFieldLabel     matlab.ui.control.Label
        FigureaxisrangeEditFieldLabel   matlab.ui.control.Label
        CalculationwavelengthEditField  matlab.ui.control.EditField
        CalculationwavelengthEditFieldLabel  matlab.ui.control.Label
        MeasuredwavelengthEditField     matlab.ui.control.EditField
        MeasuredwavelengthEditFieldLabel  matlab.ui.control.Label
        CalculationpupilEditField       matlab.ui.control.EditField
        CalculationpupilEditFieldLabel  matlab.ui.control.Label
        LoadexistingzcoefficientsfromJaekenArtal2012Button  matlab.ui.control.RadioButton
        ManuallysetzcoefficientsButton  matlab.ui.control.RadioButton
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        RightPanel                      matlab.ui.container.Panel
        ClearfigureButton               matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {703, 703};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {673, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RunButton
        function ComputePSF(app, event)
            %add isetbio into path before any computation happens
%             try
%                 directory_isetbio = what('isetbio-master');
%                 addpath(genpath(directory_isetbio.path));
%             catch
%                 errordlg(['Check if you''ve downloaded ISETBIO, ',...
%                     'and make sure the folder remains its original name ',...
%                     '(i.e., ''isetbio-maters'').'],'File Error');
%             end
            
            %close off the message box otherwise the second plot won't show
            global msgBox
            if ishandle(msgBox); close(msgBox); end
            
            %if manually enter z-coefficients
            if app.ManuallysetzcoefficientsButton.Value == 1
                %initialize a vector that stores all the z-coefficient (OSA
                %j-index is up to 14)
                zcoeffs = zeros(1,15);
                %read off values from the sliders
                for i = 1:length(zcoeffs)
                    eval(['zcoeffs(i) = app.PistonSlider_',num2str(i),'.Value;']);
                end
                %read pupil size
                measuredPupil = str2double(app.MeasuredpupilEditField.Value);
                calcPupil     = str2double(app.CalculationpupilEditField.Value);
                %generate error message if inputs are invalid
                if calcPupil > measuredPupil
                    errordlg(['The calculated pupil size must be equal to or',...
                       ' smaller than the measured pupil size!'],'File Error');
                    return
                end
                %read wavelength
                measuredWvl   = str2double(app.MeasuredwavelengthEditField.Value);
                calcWvl       = str2double(app.CalculationwavelengthEditField.Value);
                %read figure axis boundaries
                minUM         = str2double(app.FigureaxisrangeEditField.Value);
                maxUM         = str2double(app.FigureaxisrangeEditField_2.Value);
                %generate error message if inputs are invalid
                if minUM >= maxUM
                    errordlg(['Invalid boundaries! The upper bound has to be',...
                       ' greater than the lower bound'],'File Error');
                    return
                elseif maxUM - minUM < 20
                    errordlg('Perhaps make the range a bit larger.','File Error');
                    return
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %        compute wavefront point spread function
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                wvf = wvfCreate('zcoeffs', zcoeffs,'measured pupil',...
                        measuredPupil, 'measured wl', measuredWvl,...
                        'calc pupil size', calcPupil, 'calc wavelengths',...
                        calcWvl,'spatial samples', 201);
                wvf = wvfComputePSF(wvf);
            else %load existing z-coefficients
                %Read OSA j-index
                jIdx        = 0:str2double(app.OSAjindexDropDown.Value);
                %Read selected participant
                slc_sub     = app.SubjNum.Value;
                if strcmp(slc_sub, '-')
                    slc_subs = randi(130); %randomly select a participant
                elseif abs(floor(str2double(slc_sub)) - str2double(slc_sub)) < 1e-4 &&...
                        str2double(slc_sub) < 130 && str2double(slc_sub) >= 1
                    slc_subs = str2double(slc_sub);
                else
                    errordlg(['The index for the selected participant has',...
                       ' to be between 1-130!'],'File Error');
                    return
                end
                %Read selected eye and horizontal eccentricity
                eye         = lower(app.EyeDropDown.Value);
                eccen       = str2double(app.EccentricityEditField.Value);
                if ~(abs(floor(eccen) - eccen) < 1e-4) || eccen > 40 || eccen < -40
                    errordlg(['The value for eccentricity has to be an integer',...
                       ' between -40 and 40 '],'File Error');
                    return
                end
                
                %load data given inputs
                %Caution: some data are not examined/stored. Find out which
                %piece(s) is missing and inform users.
                try
                    wvf = wvfLoadWavefrontOpticsData('source', 'JaekenArtal2012', ...
                             'jIndex', jIdx, 'whichEye', eye, 'whichGroup',...
                             slc_subs, 'eccentricity', eccen, 'verbose', false);
                catch
                    %if fails, it means at the given combination of inputs,
                    %the data is missing.
                    eccen_vec = -40:40;
                    zcoeff_mat = NaN(81, 15);
                    idx_NaN = [];
                    %find the other missing pieces
                    for i = 1:length(eccen_vec) %81 eccentricities
                        try
                            wvf = wvfLoadWavefrontOpticsData('source', 'JaekenArtal2012', ...
                                     'jIndex', jIdx, 'whichEye', eye, 'whichGroup',...
                                     slc_subs, 'eccentricity', eccen_vec(i), 'verbose', false);
                        catch
                            idx_NaN = [idx_NaN, eccen_vec(i)];
                        end
                    end
                    errordlg(['The following eccentricities for this selected participant',...
                       ' were not examined: ', num2str(idx_NaN)],'File Error');
                    return
                end
                calcWvl     = wvf.wls;
                zcoeffs     = wvf.zcoeffs;
                
                %put the slider to the values indicated by uploaded zcoeffs
                for i = 1:length(zcoeffs)
                    eval(['app.PistonSlider_',num2str(i),'.Value = zcoeffs(i);']);
                end
                app.MeasuredpupilEditField.Value = num2str(wvf.measpupilMM);
                app.CalculationpupilEditField.Value = num2str(wvf.calcpupilMM);
                app.MeasuredwavelengthEditField.Value = num2str(wvf.measWlNM);
                app.CalculationwavelengthEditField.Value = num2str(wvf.wls);
            end
            psf = wvf.psf{1};
            %normalize it
            psf = psf./ max(psf(:));
            %select figure range
            if app.ManuallysetzcoefficientsButton.Value == 1
                samp = wvfGet(wvf, 'psf spatial samples', 'um', calcWvl);
                %Chop the image and only keep the part that's within the
                %boundaries
                if ~isempty(maxUM) && ~isempty(minUM)
                    index = (samp < maxUM & samp > minUM);
                    psf = psf(index, index);
                end
            end
            
            %fill in the first figure
            surf(app.UIAxes, psf,'EdgeColor','none','FaceAlpha', 0.3);
            xlim(app.UIAxes,[1,size(psf,2)]);
            ylim(app.UIAxes,[1,size(psf,1)]); 
            
            %fill in the second figure
            imagesc(app.UIAxes_2, psf);
            colormap(app.UIAxes_2,'bone');
            xlim(app.UIAxes_2,[1,size(psf,2)]);
            ylim(app.UIAxes_2,[1,size(psf,2)]);
            
            clear wvf psf zcoeffs
        end

        % Button pushed function: ResetButton
        function resetValues(app, event)
            %put all the sliders back to 0
            for i = 1:15
                eval(['app.PistonSlider_',num2str(i),'.Value = 0;']);
            end
            %reset all the other parameters
            app.MeasuredpupilEditField.Value = '4';
            app.CalculationpupilEditField.Value = '4';
            app.MeasuredwavelengthEditField.Value = '550';
            app.CalculationwavelengthEditField.Value = '550';
            app.FigureaxisrangeEditField.Value = '-60';
            app.FigureaxisrangeEditField_2.Value = '60';
        end

        % Button pushed function: ClearfigureButton
        function ClearFigures(app, event)
            %clear figures
            imagesc(app.UIAxes,[])
            imagesc(app.UIAxes_2,[])
        end

        % Button pushed function: hereButton
        function ShowInstructions(app, event)
            global msgBox
            msgBox = msgbox(sprintf(['To run this app, you would need to have',...
                ' isetbio (https://github.com/isetbio/isetbio) in the path',...
                ' as well as the folder /icons_zernikePolynomials.\n\n',...
                'The purpose of this application is to provide a ',...
                'visualization of how varying Zernike polynomial coefficients',...
                ' influence the point spread function. There',...
                ' are two primary modes of interaction available within the ',...
                'interface: you can either manually set z-coefficients or load ',...
                'existing z-coefficients from Jaeken & Artal 2012. You may select',...
                ' your preference by clicking the radio buttons positioned on the',...
                ' left-hand side of the interface. \n\nIf you want to manually set',...
                ' z-coefficients, simply adjust the slider on the side of each Zernike',...
                ' polynomial. Furthermore, it allows for the specification of pupil and',...
                ' wavelength values. A comprehensive understanding of each Zernike ',...
                'polynomial, as well as the appropriate range of input values, can ',...
                'be obtained by placing the cursor over the respective icon or label. ',...
                'To restore the interface to its default settings, simply click the ''Reset'' button',...
                ' on the right panel. \n\nAlternatively, if you want to ',...
                'load z-coefficients derived from the study (Jaeken & Artal 2012), you can',...
                ' specify an individual participant, the eye under examination, ',...
                'the order of Zernike polynomials included in the computation, and the',...
                ' (horizontal) eccentricity being examined. More details regarding the appropriate',...
                ' range of these variables can be found by hovering the cursor over the ',...
                'respective labels. \n\nUpon finalizing your selections, initiate the ',...
                'visualization process by clicking the ''Run'' button on the right panel.',...
                ' Subsequently, two plots will be displayed: the upper plot presents a 3D ',...
                'view of the point spread function, while the lower plot provides a planar ',...
                'view from the top (the brighter, the greater the amplitude). If you have ',...
                'opted to use z-coefficients derived from the Jaeken & Artal 2012 study, ',...
                'the sliders will adjust automatically to reflect these values upon execution',...
                ' of the ''Run'' command. The ''Clear Figure'' button serves to remove the ',...
                'generated plots from the display panel.'],'Instructions'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.ButtonGroup_2 = uibuttongroup(app.ButtonGroup);
            app.ButtonGroup_2.Title = '_';
            app.ManuallysetzcoefficientsButton = uiradiobutton(app.ButtonGroup);
            app.ManuallysetzcoefficientsButton.Tooltip = {'Adjust the sliders below to set values for z-coefficients'};
            app.ManuallysetzcoefficientsButton.Text = 'Manually set z-coefficients';
            app.LoadexistingzcoefficientsfromJaekenArtal2012Button = uiradiobutton(app.ButtonGroup);
            app.LoadexistingzcoefficientsfromJaekenArtal2012Button.Tooltip = {'Load z-coefficients estimated from an existing study'};
            app.LoadexistingzcoefficientsfromJaekenArtal2012Button.Text = 'Load existing z-coefficients from Jaeken & Artal 2012 ';
            app.LoadexistingzcoefficientsfromJaekenArtal2012Button.Value = true;
            app.CalculationpupilEditFieldLabel = uilabel(app.ButtonGroup);
            app.CalculationpupilEditFieldLabel.Tooltip = {'Pupil size for calculation (mm), which is not necessarily the same as the pupil size manipulated in an experiment. Note that this value has to be smaller than the measured pupil size.'};
            app.CalculationpupilEditFieldLabel.Text = 'Calculation pupil';
            app.CalculationpupilEditField = uieditfield(app.ButtonGroup, 'text');
            app.CalculationpupilEditField.Value = '4';
            app.MeasuredwavelengthEditFieldLabel = uilabel(app.ButtonGroup);
            app.MeasuredwavelengthEditFieldLabel.Tooltip = {'The wavefront aberration measurement wavelength (nm)'};
            app.MeasuredwavelengthEditFieldLabel.Text = 'Measured wavelength';
            app.MeasuredwavelengthEditField = uieditfield(app.ButtonGroup, 'text');
            app.MeasuredwavelengthEditField.Value = '550';
            app.CalculationwavelengthEditFieldLabel = uilabel(app.ButtonGroup);
            app.CalculationwavelengthEditFieldLabel.Tooltip = {'Wavelengths to calculate over (nm), which is not necessarily the same as the measured wavelength.'};
            app.CalculationwavelengthEditFieldLabel.Text = 'Calculation wavelength';
            app.CalculationwavelengthEditField = uieditfield(app.ButtonGroup, 'text');
            app.CalculationwavelengthEditField.Value = '550';
            app.FigureaxisrangeEditFieldLabel = uilabel(app.ButtonGroup);
            app.FigureaxisrangeEditFieldLabel.Tooltip = {'The lower and upper boundaries for the figure axis (micrometer). The range should be between -60 and 60 micrometers. '};
            app.FigureaxisrangeEditFieldLabel.Text = 'Figure axis range';
            app.MeasuredpupilEditFieldLabel = uilabel(app.ButtonGroup);
            app.MeasuredpupilEditFieldLabel.Tooltip = {'The pupil size for the measured wavefront aberration (mm) '};
            app.MeasuredpupilEditFieldLabel.Text = 'Measured pupil';
            app.MeasuredpupilEditField = uieditfield(app.ButtonGroup, 'text');
            app.MeasuredpupilEditField.Tooltip = {''};
            app.MeasuredpupilEditField.Value = '4';
            app.n0Label = uilabel(app.ButtonGroup);
            app.n0Label.Text = 'n = 0 ━';
            app.n1Label = uilabel(app.ButtonGroup);
            app.n1Label.Text = 'n = 1 ━';
            app.n2Label = uilabel(app.ButtonGroup);
            app.n2Label.Text = 'n = 2 ━';
            app.n3Label = uilabel(app.ButtonGroup);
            app.n3Label.Text = 'n = 3 ━';
            app.FigureaxisrangeEditField = uieditfield(app.ButtonGroup, 'text');
            app.FigureaxisrangeEditField.Value = '-60';
            app.n4Label = uilabel(app.ButtonGroup);
            app.n4Label.Text = 'n = 4 ━';
            app.AzimuthalFrequencymLabel = uilabel(app.ButtonGroup);
            app.AzimuthalFrequencymLabel.Tooltip = {'This is an integer that indicates the number of times the value of the polynomial changes along a circle (azimuthal direction). Positive m values denote counterclockwise phase changes, and negative m values denote clockwise phase changes.'};
            app.AzimuthalFrequencymLabel.Text = 'Azimuthal Frequency (m):';
            app.m0Label = uilabel(app.ButtonGroup);
            app.m0Label.Text = '      |          m = 0';
            app.m2Label = uilabel(app.ButtonGroup);
            app.m2Label.Text = '     |          m = -2';
            app.m1Label = uilabel(app.ButtonGroup);
            app.m1Label.Text = '     |          m = -1';
            app.m3Label = uilabel(app.ButtonGroup);
            app.m3Label.Text = '     |          m = -3';
            app.m4Label = uilabel(app.ButtonGroup);
            app.m4Label.Text = '     |          m = -4';
            app.m1Label_2 = uilabel(app.ButtonGroup);
            app.m1Label_2.Text = '      |          m = 1';
            app.m2Label_2 = uilabel(app.ButtonGroup);
            app.m2Label_2.Text = '      |          m = 2';
            app.m3Label_2 = uilabel(app.ButtonGroup);
            app.m3Label_2.Text = '      |          m = 3';
            app.m4Label_2 = uilabel(app.ButtonGroup);
            app.m4Label_2.Text = '      |          m = 4';
            app.EccentricityEditFieldLabel = uilabel(app.ButtonGroup);
            app.EccentricityEditFieldLabel.Tooltip = {'This represents the horizontal eccentricity. It has to be an integer between -40 and 40 (deg), steps of 1. For the right eye, -40 = temporal, 40 = nasal. For the left eye, -40 = nasal, 40 = temporal. '};
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.ButtonGroup, 'text');
            app.EccentricityEditField.Value = '0';
            app.SubjNum = uieditfield(app.ButtonGroup, 'text');
            app.SubjNum.Value = '-';
            app.GroupDropDownLabel_2 = uilabel(app.ButtonGroup);
            app.GroupDropDownLabel_2.Tooltip = {'Specify which participant to analyze by entering an integer between 1 to 130 (empty input will lead to a randomly selected participant). '};
            app.GroupDropDownLabel_2.Text = 'Participant #';
            app.FigureaxisrangeEditField_2 = uieditfield(app.ButtonGroup, 'text');
            app.FigureaxisrangeEditField_2.Value = '60';
            app.FigureaxisrangeEditFieldLabel_2 = uilabel(app.ButtonGroup);
            app.FigureaxisrangeEditFieldLabel_2.Text = '-';
            app.PistonSlider_2 = uislider(app.LeftPanel);
            app.PistonSlider_2.Limits = [-12 12];
            app.PistonSlider_2.MajorTicks = [-12 0 12];
            app.PistonSlider_2.MajorTickLabels = {'-12', '0', '12'};
            app.PistonSlider_2.MinorTicks = [-12 -9 -6 -3 0 3 6 9 12];
            app.PistonSlider_2.Tag = 'Z1';
            app.PistonSlider_3 = uislider(app.LeftPanel);
            app.PistonSlider_3.Limits = [-12 12];
            app.PistonSlider_3.MajorTicks = [-12 0 12];
            app.PistonSlider_3.MajorTickLabels = {'-12', '0', '12'};
            app.PistonSlider_3.MinorTicks = [-12 -9 -6 -3 0 3 6 9 12];
            app.PistonSlider_3.Tag = 'Z1';
            app.PistonSlider_4 = uislider(app.LeftPanel);
            app.PistonSlider_4.Limits = [-5 5];
            app.PistonSlider_4.MajorTicks = [-5 0 5];
            app.PistonSlider_4.MajorTickLabels = {'-5', '0', '5'};
            app.PistonSlider_4.MinorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.PistonSlider_4.Tag = 'Z1';
            app.PistonSlider_5 = uislider(app.LeftPanel);
            app.PistonSlider_5.Limits = [-5 5];
            app.PistonSlider_5.MajorTicks = [-5 0 5];
            app.PistonSlider_5.MajorTickLabels = {'-5', '0', '5'};
            app.PistonSlider_5.MinorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.PistonSlider_5.Tag = 'Z1';
            app.PistonSlider_6 = uislider(app.LeftPanel);
            app.PistonSlider_6.Limits = [-5 5];
            app.PistonSlider_6.MajorTicks = [-5 0 5];
            app.PistonSlider_6.MajorTickLabels = {'-5', '0', '5'};
            app.PistonSlider_6.MinorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.PistonSlider_6.Tag = 'Z1';
            app.Image_7 = uiimage(app.LeftPanel);
            app.Image_7.Tooltip = {'Vertical trefoil'; '(j = 6)'};
            app.Image_7.ImageSource = 'Z7.png';
            app.Image_8 = uiimage(app.LeftPanel);
            app.Image_8.Tooltip = {'Vertical coma'; '(j = 7)'};
            app.Image_8.ImageSource = 'Z8.png';
            app.Image_9 = uiimage(app.LeftPanel);
            app.Image_9.Tooltip = {'Horizontal coma'; '(j = 8)'};
            app.Image_9.ImageSource = 'Z9.png';
            app.Image_10 = uiimage(app.LeftPanel);
            app.Image_10.Tooltip = {'Horizontal trefoil'; '(j = 9)'};
            app.Image_10.ImageSource = 'Z10.png';
            app.Image_11 = uiimage(app.LeftPanel);
            app.Image_11.Tooltip = {'Oblique quadrafoil'; '(j = 10)'};
            app.Image_11.ImageSource = 'Z11.png';
            app.Image_12 = uiimage(app.LeftPanel);
            app.Image_12.Tooltip = {'Oblique secondary astigmatism'; '(j = 11)'};
            app.Image_12.ImageSource = 'Z12.png';
            app.Image_13 = uiimage(app.LeftPanel);
            app.Image_13.Tooltip = {'Primary spherical aberration'; '(j = 12)'};
            app.Image_13.ImageSource = 'Z13.png';
            app.Image_14 = uiimage(app.LeftPanel);
            app.Image_14.Tooltip = {'Horizontal/vertical secondary astigmatism'; '(j = 13)'};
            app.Image_14.ImageSource = 'Z14.png';
            app.Image_15 = uiimage(app.LeftPanel);
            app.Image_15.Tooltip = {'Vertical quadrafoil'; '(j = 14)'};
            app.Image_15.ImageSource = 'Z15.png';
            app.Image_6 = uiimage(app.LeftPanel);
            app.Image_6.Tooltip = {'Vertical/horizontal primary astigmatism'; '(j = 5)'};
            app.Image_6.ImageSource = 'Z6.png';
            app.Image_5 = uiimage(app.LeftPanel);
            app.Image_5.Tooltip = {'Defocus'; '(j = 4)'};
            app.Image_5.ImageSource = 'Z5.png';
            app.Image_4 = uiimage(app.LeftPanel);
            app.Image_4.Tooltip = {'Oblique primary astigmatism'; '(j = 3)'};
            app.Image_4.ImageSource = 'Z4.png';
            app.Image_3 = uiimage(app.LeftPanel);
            app.Image_3.Tooltip = {'Horizontal tilt'; '(j = 2)'};
            app.Image_3.ImageSource = 'Z3.png';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.Tooltip = {'Vertical tilt'; '(j = 1)'};
            app.Image_2.ImageSource = 'Z2.png';
            app.Image_1 = uiimage(app.LeftPanel);
            app.Image_1.Tooltip = {'Piston'; '(j = 0)'};
            app.Image_1.ImageSource = 'Z1.png';
            app.PistonSlider_15 = uislider(app.LeftPanel);
            app.PistonSlider_15.Limits = [-1 1];
            app.PistonSlider_15.MajorTicks = [-1 0 1];
            app.PistonSlider_15.MajorTickLabels = {'-1', '0', '1'};
            app.PistonSlider_15.MinorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.PistonSlider_15.Tag = 'Z1';
            app.PistonSlider_14 = uislider(app.LeftPanel);
            app.PistonSlider_14.Limits = [-1 1];
            app.PistonSlider_14.MajorTicks = [-1 0 1];
            app.PistonSlider_14.MajorTickLabels = {'-1', '0', '1'};
            app.PistonSlider_14.MinorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.PistonSlider_14.Tag = 'Z1';
            app.PistonSlider_13 = uislider(app.LeftPanel);
            app.PistonSlider_13.Limits = [-1 1];
            app.PistonSlider_13.MajorTicks = [-1 0 1];
            app.PistonSlider_13.MajorTickLabels = {'-1', '0', '1'};
            app.PistonSlider_13.MinorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.PistonSlider_13.Tag = 'Z1';
            app.PistonSlider_12 = uislider(app.LeftPanel);
            app.PistonSlider_12.Limits = [-1 1];
            app.PistonSlider_12.MajorTicks = [-1 0 1];
            app.PistonSlider_12.MajorTickLabels = {'-1', '0', '1'};
            app.PistonSlider_12.MinorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.PistonSlider_12.Tag = 'Z1';
            app.PistonSlider_11 = uislider(app.LeftPanel);
            app.PistonSlider_11.Limits = [-1 1];
            app.PistonSlider_11.MajorTicks = [-1 0 1];
            app.PistonSlider_11.MajorTickLabels = {'-1', '0', '1'};
            app.PistonSlider_11.MinorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.PistonSlider_11.Tag = 'Z1';
            app.PistonSlider_10 = uislider(app.LeftPanel);
            app.PistonSlider_10.Limits = [-2 2];
            app.PistonSlider_10.MajorTicks = [-2 0 2];
            app.PistonSlider_10.MajorTickLabels = {'-2', '0', '2'};
            app.PistonSlider_10.MinorTicks = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.PistonSlider_10.Tag = 'Z1';
            app.PistonSlider_9 = uislider(app.LeftPanel);
            app.PistonSlider_9.Limits = [-2 2];
            app.PistonSlider_9.MajorTicks = [-2 0 2];
            app.PistonSlider_9.MajorTickLabels = {'-2', '0', '2'};
            app.PistonSlider_9.MinorTicks = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.PistonSlider_9.Tag = 'Z1';
            app.PistonSlider_8 = uislider(app.LeftPanel);
            app.PistonSlider_8.Limits = [-2 2];
            app.PistonSlider_8.MajorTicks = [-2 0 2];
            app.PistonSlider_8.MajorTickLabels = {'-2', '0', '2'};
            app.PistonSlider_8.MinorTicks = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.PistonSlider_8.Tag = 'Z1';
            app.PistonSlider_7 = uislider(app.LeftPanel);
            app.PistonSlider_7.Limits = [-2 2];
            app.PistonSlider_7.MajorTicks = [-2 0 2];
            app.PistonSlider_7.MajorTickLabels = {'-2', '0', '2'};
            app.PistonSlider_7.MinorTicks = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.PistonSlider_7.Tag = 'Z1';
            app.PistonSlider_1 = uislider(app.LeftPanel);
            app.PistonSlider_1.Limits = [-8 8];
            app.PistonSlider_1.MajorTicks = [-8 0 8];
            app.PistonSlider_1.MajorTickLabels = {'-8', '0', '8', ''};
            app.PistonSlider_1.MinorTicks = [-8 -6 -4 -2 0 2 4 6 8];
            app.PistonSlider_1.Tag = 'Z1';
            app.EyeDropDownLabel = uilabel(app.LeftPanel);
            app.EyeDropDownLabel.Tooltip = {'Which eye of the selected participant(s) to analyze'};
            app.EyeDropDownLabel.Text = 'Eye';
            app.EyeDropDown = uidropdown(app.LeftPanel);
            app.EyeDropDown.Items = {'Left', 'Right'};
            app.EyeDropDown.Value = 'Left';
            app.OSAjindexDropDownLabel = uilabel(app.LeftPanel);
            app.OSAjindexDropDownLabel.Tooltip = {'This represents the max OSA j-index that will be included in the calculation. For example, ''14'' means all the polynomials up to j = 14 (including 14) will be used in the calculation. '};
            app.OSAjindexDropDownLabel.Text = 'OSA j-index';
            app.OSAjindexDropDown = uidropdown(app.LeftPanel);
            app.OSAjindexDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.OSAjindexDropDown.Value = '14';
            app.RadialordernLabel = uilabel(app.LeftPanel);
            app.RadialordernLabel.Tooltip = {'This is an integer that indicates the order of the polynomial. The radial order describes the number of changes from bright to dark, or from dark to bright, along a radial line. In other words, it represents the number of radial nodes in the wavefront function. The higher the radial order, the more complex the aberration pattern is.'};
            app.RadialordernLabel.Text = 'Radial order (n):';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Tooltip = {''};
            app.Label.Text = 'Enter values to compute PSFs (click                to see instructions)';
            app.hereButton = uibutton(app.LeftPanel, 'push');
            app.hereButton.ButtonPushedFcn = createCallbackFcn(app, @ShowInstructions, true);
            app.hereButton.Text = 'here';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Point spread function')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.RunButton = uibutton(app.RightPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @ComputePSF, true);
            app.RunButton.Tooltip = {''};
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetValues, true);
            app.ResetButton.Text = 'Reset';
            app.ClearfigureButton = uibutton(app.RightPanel, 'push');
            app.ClearfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFigures, true);
            app.ClearfigureButton.Text = 'Clear figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PointSpreadFunction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end