classdef coneRectWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        coneMosaicWindow               matlab.ui.Figure
        menuFile                       matlab.ui.container.Menu
        menuFileRefresh                matlab.ui.container.Menu
        menuFileClose                  matlab.ui.container.Menu
        menuEdit                       matlab.ui.container.Menu
        menuEditName                   matlab.ui.container.Menu
        menuEditClearData              matlab.ui.container.Menu
        menuEditFontSize               matlab.ui.container.Menu
        menuPlot                       matlab.ui.container.Menu
        menuPlotMacular                matlab.ui.container.Menu
        menuPlotMacularTransmittance   matlab.ui.container.Menu
        menuPlotMacularAbsorptance     matlab.ui.container.Menu
        menuPlotMacularAbsorbance      matlab.ui.container.Menu
        menuPlotConeAbsorptance        matlab.ui.container.Menu
        menuPlotMosaic                 matlab.ui.container.Menu
        menuPlotMosaicConeMosaic       matlab.ui.container.Menu
        menuPlotMosaicMeanAbsorptions  matlab.ui.container.Menu
        menuPlotMosaicMeanCurrent      matlab.ui.container.Menu
        menuPlotEMPath                 matlab.ui.container.Menu
        menuPlotHLine                  matlab.ui.container.Menu
        menuPlotVLine                  matlab.ui.container.Menu
        menuPlotHLineLMS               matlab.ui.container.Menu
        menuPlotVLineLMS               matlab.ui.container.Menu
        menuPlotTimeSeries             matlab.ui.container.Menu
        menuCones                      matlab.ui.container.Menu
        menuConesGenerateEM            matlab.ui.container.Menu
        menuConesAbsorptions           matlab.ui.container.Menu
        menuConesPhotocurrent          matlab.ui.container.Menu
        menuConePhotocurrentNoise      matlab.ui.container.Menu
        menuHelp                       matlab.ui.container.Menu
        menuAppNotes                   matlab.ui.container.Menu
        mosaicName                     matlab.ui.control.Label
        txtOS                          matlab.ui.control.Label
        txtMosaic                      matlab.ui.control.Label
        editEccentricity               matlab.ui.control.EditField
        editKLMS                       matlab.ui.control.EditField
        txtKLMSRatio                   matlab.ui.control.Label
        txtEccentricity                matlab.ui.control.Label
        txtDisplayGamma                matlab.ui.control.Label
        editGam                        matlab.ui.control.EditField
        editMacularDensity             matlab.ui.control.EditField
        textMacularDensity             matlab.ui.control.Label
        editConePeakEfficiency         matlab.ui.control.EditField
        textConePeakEfficiency         matlab.ui.control.Label
        editConeOpticalDensity         matlab.ui.control.EditField
        textConePigmentDensity         matlab.ui.control.Label
        editConeHeight                 matlab.ui.control.EditField
        editConeWidth                  matlab.ui.control.EditField
        txtConeSIze                    matlab.ui.control.Label
        popupImageType                 matlab.ui.control.DropDown
        txtIntegration                 matlab.ui.control.Label
        txtExposureUnits               matlab.ui.control.Label
        editExpTime                    matlab.ui.control.EditField
        txtMessage                     matlab.ui.control.Label
        txtMosaicSize                  matlab.ui.control.Label
        editCols                       matlab.ui.control.EditField
        editRows                       matlab.ui.control.EditField
        btnComputeImage                matlab.ui.control.Button
        imgMain                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        cMosaic % Description
    end
    
    methods (Access = private)
        function coneMosaicGUIRefresh(app, plotType, varargin)
            % (File | Refresh) Update the Cone mosaic window interface - main pulldown
            %
            % Syntax:
            %   coneMosaicGUIRefresh
            %
            % Description:
            %    Update the cone mosaic window's GU interface, via the main pulldown.
            %
            % Inputs:            
            %    app 
            %    plotType
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            
            % History:
            %    xx/xx/16  HJ, BW  ISETBIO TEAM, 2016
            %    02/08/18  jnm     Formatting
            %    07/10/23  baw     Re-written and ported to appdesigner
            
            % If not sent in, leave it as is.
            if notDefined('plotType'), plotType = app.popupImageType.Value; end


            % get coneMosaic object
            cm = app.cMosaic;
            
            % Place name in text string box
            app.mosaicName.Text = sprintf('%s', cm.name);
            
            % set row and cols
            app.editRows.Value = num2str(cm.rows);
            app.editCols.Value = num2str(cm.cols);
            
            % set integration time
            app.editExpTime.Value = sprintf('%.1f', cm.integrationTime * 1e3);
            
            % set KLMS ratio
            str = sprintf('[%.1f, %.1f, %.1f, %.1f]', cm.spatialDensity(1), ...
                cm.spatialDensity(2), cm.spatialDensity(3), cm.spatialDensity(4));
            app.editKLMS.Value = str;
            
            % set description strings            
            app.txtMosaic.Text = cm.description('skipMacular', true, 'skipPigment', true);
            
            % set outersegment description
            app.txtOS.Text = cm.descriptionOS;
            
            % set photopigment properties
            app.editConeWidth.Value = num2str(cm.pigment.width * 1e6);
            app.editConeHeight.Value = num2str(cm.pigment.height * 1e6);
            
            str = sprintf('[%.1f, %.1f, %.1f]', cm.pigment.opticalDensity(1), ...
                cm.pigment.opticalDensity(2), cm.pigment.opticalDensity(3));
            app.editConeOpticalDensity.Value = str;
            
            str = sprintf('[%.2f, %.2f, %.2f]', cm.pigment.peakEfficiency(1), ...
                cm.pigment.peakEfficiency(2), cm.pigment.peakEfficiency(3));
            app.editConePeakEfficiency.Value = str;
            
            % set macular density
            app.editMacularDensity.Value = num2str(cm.macular.density);
            
            % Set eccentricity in the window based on the center. This is specified in
            % meters, and we convert it to deg for the window.
            ecc = sqrt(sum(cm.center .^ 2));   % Meters
            
            % Why don't we have a builtin variable for this?  Or a way to compute it?
            % I think we do ... help! (BW)
            deg2m = 3.3333e-04;
            ecc = ecc / deg2m;
            app.editEccentricity.Value = num2str(ecc, 2);
            
            % check if absorptions and current are available
            if isempty(cm.absorptions)
                app.menuPlotMosaicMeanAbsorptions.Enable = 'off';
            else
                app.menuPlotMosaicMeanAbsorptions.Enable = 'on';
            end
            
            if isempty(cm.current)
                app.menuPlotMosaicMeanCurrent.Enable = 'off';
            else
                app.menuPlotMosaicMeanCurrent.Enable ='on';
            end
            
            %% Set the strings in the popup menu for what to plot
            str = {'Cone mosaic'};
            
            % Add absorption options
            if ~isempty(cm.absorptions)
                str = [str {'Mean absorptions', 'Absorption movie'}];
            end
            
            % Add photocurrent options
            if ~isempty(cm.current)
                str = [str {'Mean photocurrent', 'Photocurrent movie'}];
            end
            
            app.popupImageType.Items = str;
            
            if ~exist('plotType','var')
                % The user did not specify the plot type.  So we get it from the
                % window.
                plotType = ieParamFormat(app.popupImageType.Value);
            else
                plotType = ieParamFormat(plotType);
                tmp = app.popupImageType.Items;
                for ii=1:numel(app.popupImageType.Items)
                    tmp{ii} = ieParamFormat(str{ii}); 
                end
                % The user did specify plotType.  Find it and use it
                [~,index] = ismember(plotType,tmp);
            end
            
            % Set the index and then string in the window popup menu
            app.popupImageType.Value = app.popupImageType.Items{index};            
            
            %% Here are the different plotting options for the main axis (app.imgMain)
            
            % Gamma is managed within plot for the mean images. For the video, we are
            % handling it this way so we can, in the future, set additional parameters
            % for the movies that get passed to ieMovie.
            g = str2double(app.editGam.Value);  % Probably not needed here.  Only in the plot routine
            
            % The image axis in the cone mosaic window is stored in app.imgMain.
            switch ieParamFormat(plotType)
                case 'conemosaic'
                    % cone mosaic image
                    % For large cone mosaics, we compute the mosaic and store it
                    % in the axis handle as userdata.
                    nCones  = size(cm.coneLocs, 1);
                    maxCones = 1e4;
                    if  nCones > maxCones
                        ieInWindowMessage('Initializing large mosaic image', handles);
                    end
                    cm.plot('cone mosaic', 'hf', app.imgMain);
                    ieInWindowMessage('', handles)
            
                case 'meanabsorptions'
                    % mean cone absorptions
                    coneRectPlot(app,'mean absorptions');
            
                case 'absorptionmovie'
                    ieInWindowMessage('Showing absorption movie', handles)
                    cm.plot('movie absorptions', 'hf', app.imgMain, ...
                        'gamma', g);
                    % Saved the movie data in the axis as userdata.data
                    ieInWindowMessage('', handles)
            
                case 'meanphotocurrent'
                    cm.plot('mean current', 'hf', app.imgMain);
            
                case 'photocurrentmovie'
                    ieInWindowMessage('Showing photocurrent movie', handles)
                    cm.plot('movie current', 'hf', app.imgMain, 'gamma', g);
                    ieInWindowMessage('', handles)
            
                otherwise
                    error('Unknown plot type');
            end
            
            % { 
            % Not sure how to deal with context menus yet.
            enable.hLine      = 'on';
            enable.vLine      = 'on';
            enable.hLineLMS   = 'on';
            enable.vLineLMS   = 'on';
            enable.timeSeries = 'on';            
            contextMenuInit(app);
            contextMenuEnable(app, enable);
            %}

        end
        
        function contextMenuEnable(app, enable)
            % Set and/or enable plot options in menu
            %
            % Syntax:
            %   menuPlotCone_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Set the enable plot options in the menu. Enable is a structure
            %    containing 'on' and 'off' values.
            %
            % Inputs:
            %    handles - Structure with handles and user data (see GUIDATA)
            %    enable  - A structure containing 'on' and 'off' values in reference to
            %              the menu options.
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            
            % enable is a structure of 'on' and 'off' values
            set(app.menuPlotHLine, 'Enable', enable.hLine);
            set(app.menuPlotVLine, 'Enable', enable.vLine);
            set(app.menuPlotHLineLMS, 'Enable', enable.hLineLMS);
            set(app.menuPlotVLineLMS, 'Enable', enable.vLineLMS);
            set(app.menuPlotTimeSeries, 'Enable', enable.timeSeries);
        end
        
        function c = contextMenuInit(app,varargin)
            % Set up the context (right click) menu.
            %
            % Syntax:
            %   c = contextMenuInit(handles)
            %
            % Description:
            %    Set up the context (right click) menu.
            %
            % Inputs:
            %    handles - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    c       - The created context menu
            %
            % Optional key/value pairs:
            %    None.
            %
            
            c = uicontextmenu;
            if ~isempty(app.imgMain.Children)
                for ichild = 1:size(app.imgMain.Children, 1)
                    % There are no children.  But maybe some day.
                    app.imgMain.Children(ichild).UIContextMenu = c;
                end
                uimenu(c, 'Label', 'hLine response', 'Callback', @app.contextMenuPlot);
                uimenu(c, 'Label', 'vLine response', 'Callback', @app.contextMenuPlot);
                uimenu(c, 'Label', 'hLine LMS', 'Callback', @app.contextMenuPlot);
                uimenu(c, 'Label', 'vLine LMS', 'Callback', @app.contextMenuPlot);
                uimenu(c, 'Label', 'time series', 'Callback', @app.contextMenuPlot);
            end
        end
        
        function contextMenuPlot(app,source,varargin)
            % Callback function for five of the context menu plots
            %
            % Syntax:
            %   contextMenuPlot(app,source)
            %
            % Description:
            %    Callback function for the following five context menu plots:
            %       hline, vline, hLineLMS, vLineLMS, timeSeries
            %
            %    Data may cover absorptions or current for any of the five
            %    aforementioned plots.
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    source       - The structure with handles and user data (see GUIDATA),
            %                   also known as handles in other functions.
            %    callbackdata - Data for callback
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            
            % The guidata return of the variable 'source' contains the gui objects
            % handles = guidata(source);
            
            % determine which the data context (absorption or current)
            plotType = ieParamFormat(app.popupImageType.Value);
            switch plotType
                case {'meanabsorptions', 'conemosaic', 'absorptionmovie'}
                    dataType = 'absorptions';
                case {'meanphotocurrent', 'photocurrentmovie'}
                    dataType = 'current';
            end
            
            % Figure out which plot was requested and build the command
            switch ieParamFormat(source.Label)
                case 'hlineresponse'
                    cmd = ['hline', dataType];
                case 'vlineresponse'
                    cmd = ['vline', dataType];
                case 'hlinelms'
                    cmd = ['hline', dataType, 'lms'];
                case 'vlinelms'
                    cmd = ['vline', dataType, 'lms'];
                case 'timeseries'
                    cmd = ['time series', dataType];
                otherwise
                    error('Unknown plot type %s\n', source.label);
            end
            
            % Call the plot command, setting the main window axis for the first place
            % to start.
            coneRectPlot(app,cmd);
        end
        
        function menuAnComputeFromOI_Callback(app, hObject, eventdata, handles)
            % (Compute | Absorptions) Compute absorptions from OI in database
            %
            % Syntax:
            %   menuAnComputeFromOI_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Press the button in the window, and compute the cone absorptions from
            %    an OI in the database.
            %
            % Inputs:
            %    hObject   - The handle to menuAnComputeFromOI (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            btnComputeImage_Callback(hObject, eventdata, handles);
        end
        
        function menuPlotCone_Callback(app, hObject, eventdata, handles)
            % (Menu | Plot | Cone) Plot the cone
            %
            % Syntax:
            %   menuPlotCone_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Plot the Cone.
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
        end
        
        function sliderMovieProgress_Callback(app)
            % (Movie | Set Progress Bar) Set slider to point in timeline of movie
            %
            % Syntax:
            %   menuPlotVLine_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Set the slider to a specific time point on the bar.
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %

            % Get the relevant data for the movie
            dataType = ieParamFormat(app.popupImageType.Value);
            if contains(dataType,'absorptions'), mov = app.cMosaic.absorptions;
            elseif contains(dataType,'current'), mov = app.cMosaic.current;
            else,                 error('Unknown data type')
            end
            
            % Gamma, and scale the data
            gam = app.editGam.Value;            
            mov = ieScale(mov, 0, 1) .^ gam;
            mind = min(mov(:)); maxd = max(mov(:));
            
            % Deal with which axis, its format and the slider counter
            cnt = round(app.sliderMovieProgress.Value);
            assert(cnt <= size(mov, ndims(mov)), 'slider choice out of range');
            axes(app.imgMain);
            imagesc(mov(:, :, cnt)); axis image;
            set(app.imgMain, 'xticklabel', '', 'yticklabel', '');
            clim([mind maxd]); drawnow;
            app.txtMovieFrame.Text = cnt;
            
            % register right click menu  
            % I do not understand why this is here (BW).
            c = uicontextmenu;
            app.imgMain.Children.UIContextMenu = c;
            uimenu(c, 'Label', 'hLine response', 'Callback', @app.contextMenuPlot);
            uimenu(c, 'Label', 'vLine response', 'Callback', @app.contextMenuPlot);
            uimenu(c, 'Label', 'hLine LMS', 'Callback', @app.contextMenuPlot);
            uimenu(c, 'Label', 'vLine LMS', 'Callback', @app.contextMenuPlot);
            uimenu(c, 'Label', 'time series', 'Callback', @app.contextMenuPlot);
        end
        
        function sliderMovieProgress_CreateFcn(app, hObject, eventdata, handles)
            % (Create | Slider Background) Set default slider background to black
            %
            % Syntax:
            %   sliderMovieProgress_CreateFcn(hObject, eventdata, handles)
            %
            % Description:
            %    Set the slider background to black after all attributes and parameters
            %    have been set.
            %
            % Inputs:
            %    hObject   - The handle to sliderMovieProgress (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Empty, handles are not created until after all of the
            %                CreateFcns have been called.
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            if isequal(get(hObject, 'BackgroundColor'), ...
                    get(0, 'defaultUicontrolBackgroundColor'))
                set(hObject, 'BackgroundColor', [.9 .9 .9]);
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.coneMosaicWindow.CloseRequestFcn = 'delete';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function coneMosaicWindow_OpeningFcn(app, varargin)
            % (Window | Open) Initialize and open the Cone Mosaic Window
            %
            % Syntax:
            %   coneMosaicWindow_OpeningFcn(app, coneMosaicRect, [varargin])
            %
            % Description:
            %    Initialize the coneMosaicRect window
            %
            % Inputs:
            %    app -  appDesigner object for coneRectWindow_app
            %    varargin{1} The cone mosaic
            %          - (Optional) Additional information for initializing the
            %                window, such as an existing cone mosaic.
            % --- Executes just before coneMosaicWindow is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.coneMosaicWindow, 'onscreen');            
            
            % Start out invisible.  We turn it on later.
            app.coneMosaicWindow.Visible = false;
            app.imgMain.Visible = false;
            
            % See if simplifying interactions makes thing faster
            if getpref('ISET', 'fastAxes', false)
                app.imgMain.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
                app.imgMain.Toolbar = [];
            end

            % Window position preference
            ISETprefs = getpref('ISET');
            if isfield(ISETprefs,'wPos')
                s = get(0);
                wPos = ISETprefs.wPos;
                if numel(wPos{4}) >= 2
                    if wPos{4}(1) < 1 % we have old-style
                        app.coneMosaicWindow.Position(1:2) = round(wPos{4}(1:2) .* s.ScreenSize(3:4));
                    else
                        app.coneMosaicWindow.Position(1:2) = wPos{4}(1:2);
                    end
                end
            end

            % Default image to show in the main axes
            plotType = 'meanabsorptions';

            % The data must be provided for the cone mosaic when
            % running coneRectWindow(xxx).
            if isempty(varargin), error('No cone mosaic (rect) provided.')
            else,                 app.cMosaic = varargin{1};
            end

            % Alternative plot type to display.
            if length(varargin) > 1, plotType = varargin{2}; end
            
            % Set the popup default image selection to mean absorptions for when the
            % window opens.
            str = app.popupImageType.Items;
            for ii=1:numel(str), str{ii} = ieParamFormat(str{ii}); end
            
            % Refresh and move on
            % coneMosaicGUIRefresh(app, hObject, eventdata, handles, plotType);
            coneMosaicGUIRefresh(app, plotType);
            
            % Set the font size based on the ISETBIO preferences
            ieFontInit(app.coneMosaicWindow);
            
            % Very important for good rendering speed
            set(app.coneMosaicWindow, 'Renderer', 'OpenGL')

            app.coneMosaicWindow.Visible = true;
            app.imgMain.Visible = true;
           
        end

        % Button pushed function: btnComputeImage
        function btnComputeImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % (Button | Absorptions) Compute image from optics data - button press
            %
            % Syntax:
            %   btnComputeImage_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Function called at press of the button at the bottom of the window,
            %    which will compute the image from the provided optics data.
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            oi = vcGetObject('OI');
            if isempty(oi) || isempty(oiGet(oi, 'photons'))
                warning('No optical image. Use ieAddObject(oi) to store.');
                return;
            end
            
            handles.cMosaic.compute(oi);
            handles.cMosaic.name = oiGet(oi, 'name');
            set(handles.popupImageType, 'Value', 2); % mean absorptions
            
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Value changed function: editCols
        function editCols_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % (Edit | Textbox) Change the value of the Columns text box
            %
            % Syntax:
            %   editCols_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Change the value of the Columns text box.
            %
            % Inputs:
            %    hObject   - The handle to editCols (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.cols = str2double(get(hObject, 'String'));
            menuEditClearData_Callback(hObject, eventdata, handles)
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Value changed function: editConeHeight
        function editConeHeight_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Cone Height) Modify the cone height
            %
            % Syntax:
            %   editConeHeight_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Modify the cone height.
            %
            % Inputs:
            %    hObject   - The handle to editConeHeight (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            newHeight = 1e-6 * str2double(get(hObject, 'String'));
            if handles.cMosaic.pigment.height ~= newHeight
                handles.cMosaic.pigment.height = newHeight;
                menuEditClearData_Callback(hObject, eventdata, handles);
            end
        end

        % Value changed function: editConeOpticalDensity
        function editConeOpticalDensity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Cone Optical Density) Edit the cone optical density
            %
            % Syntax:
            %   menuPlotConeOpticalDensity_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Plot the cone optical density.
            %
            % Inputs:
            %    hObject   - The handle to coneOpticalDensity (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            val = str2num(get(hObject, 'String'));
            assert(numel(val) == 3, 'invalid input for optical density');
            
            if any(handles.cMosaic.pigment.opticalDensity(:) ~= val(:))
                handles.cMosaic.pigment.opticalDensity = val;
                menuEditClearData_Callback(hObject, eventdata, handles);
            end
        end

        % Value changed function: editConePeakEfficiency
        function editConePeakEfficiency_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Cone Peak Efficiency) Edit the Cone peak efficiency
            %
            % Syntax:
            %   editConePeakEfficiency_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Edit the cone peak efficiency
            %
            % Inputs:
            %    hObject   - The handle to editConePeakEfficiency (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            val = str2num(get(hObject, 'String'));
            assert(numel(val) == 3, 'invalid input for peak efficiency');
            if any(handles.cMosaic.pigment.peakEfficiency(:) ~= val(:))
                handles.cMosaic.pigment.peakEfficiency = val;
                menuEditClearData_Callback(hObject, eventdata, handles);
            end
        end

        % Value changed function: editConeWidth
        function editConeWidth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Cone Width) Modify the width of an existing cone
            %
            % Syntax:
            %   editConeWidth_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Edit the cone width
            %
            % Inputs:
            %    hObject   - The handle to editConeWidth (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            newWidth = 1e-6 * str2double(get(hObject, 'String'));
            
            if handles.cMosaic.pigment.width ~= newWidth
                handles.cMosaic.pigment.width = newWidth;
                menuEditClearData_Callback(hObject, eventdata, handles);
            end
        end

        % Value changed function: editExpTime
        function editExpTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Textbox) Change the value of the integration time text box
            %
            % Syntax:
            %   editExpTime_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Change the value of the Integration Time text box.
            %
            % Inputs:
            %    hObject   - The handle to editExpTime (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.integrationTime = 1e-3 * ...
                str2double(get(hObject, 'String'));
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Value changed function: editGam
        function editGam_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | ) Text book Gamm
            %
            % Syntax:
            %   menuPlotCone_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    "Text book Gamm" - edit
            %
            % Inputs:
            %    hObject   - The handle to editGam (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            
            set(handles.editGam, 'value', str2double(get(handles.editGam, 'string')));
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Value changed function: editKLMS
        function editKLMS_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | KLMS) Modify the KLMS
            %
            % Syntax:
            %   editKLMS_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Modify the KLMS
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            density = str2num(get(handles.editKLMS, 'String'));
            assert(numel(density) == 4, 'invalid input');
            
            density = density / sum(density);
            handles.cMosaic.spatialDensity = density;
            menuEditClearData_Callback(hObject, eventdata, handles);
        end

        % Value changed function: editMacularDensity
        function editMacularDensity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Macular Density) Edit the existing macular density
            %
            % Syntax:
            %   editMacularDensity_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Edit the existing macular density
            %
            % Inputs:
            %    hObject   - The handle to editMacularDensity (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            val = str2double(get(hObject, 'String'));
            if handles.cMosaic.macular.density ~= val
                handles.cMosaic.macular.density = val;
                menuEditClearData_Callback(hObject, eventdata, handles);
            end
        end

        % Value changed function: editRows
        function editRows_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Textbox) Change the value of the Rows text box
            %
            % Syntax:
            %   editRows_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Change the value of the Rows text box.
            %
            % Inputs:
            %    hObject   - The handle to editRows (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.rows = str2double(get(hObject, 'String'));
            menuEditClearData_Callback(hObject, eventdata, handles)
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Menu selected function: menuAppNotes
        function menuAppNotes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Help | Web Documentation) Call web documentation
            %
            % Syntax:
            %   menuAppNotes_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Open the web documentation
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            web('https://github.com/isetbio/isetbio/wiki', '-browser');
        end

        % Menu selected function: menuConePhotocurrentNoise
        function menuConePhotocurrentNoise_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Cones | Photocurrent) Toggle photocurrent noise
            %
            % Syntax:
            %   menuConePhotocurrentNoise_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Toggle the photocurrent noise setting. The toggle command produces the
            %    following changes in the noise setting:
            %       Random -> None
            %       Frozen -> Random
            %       None   -> Frozen
            %
            % Inputs:
            %    hObject   - The handle to menuConePhotocurrentNoise (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            % Notes:
            %    * Also executes computeCurrent
            %
            
            % set(handles.btnPlayPause, 'Value', 0);  % Turn off any movie.
            
            % Flip from whatever state to the other
            switch handles.cMosaic.os.noiseFlag
                case 'random'
                    handles.cMosaic.os.noiseFlag = 'none';
                case 'frozen'
                    handles.cMosaic.os.noiseFlag = 'random';
                case 'none'
                    handles.cMosaic.os.noiseFlag = 'frozen';
            end
            
            % We used to use this. But now, I think it should always be off.
            handles.menuConePhotocurrentNoise.Checked = 'off';
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Menu selected function: menuConesAbsorptions
        function menuConesAbsorptions_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Cones | Absorptions) Computes absorptions
            %
            % Syntax:
            %   menuConesAbsorptions_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Load the current oi to compute the absorptions. If no oi is selected,
            %    the function will complain.
            %
            % Inputs:
            %    hObject   - The handle to menuConesAbsorptions (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            oi = vcGetObject('OI');
            if isempty(oi) || isempty(oiGet(oi, 'photons'))
                warning('No optical image. Use ieAddObject(oi) to store.');
                return;
            end
            
            fprintf('Calculating with optical image %s\n', oiGet(oi, 'name'));
            handles.cMosaic.compute(oi);
            handles.cMosaic.name = oiGet(oi, 'name');
            set(handles.popupImageType, 'Value', 2); % mean absorptions
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Menu selected function: menuConesGenerateEM
        function menuConesGenerateEM_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Cones | Eye Movement) Generates eye movements
            %
            % Syntax:
            %   menuConesGenerateEM_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Generate the cone's eye movements
            %
            % Inputs:
            %    hObject   - The handle to menuConesGenerateEM (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            str = ieReadString('Number of frames', '500');
            if ~isempty(str)
                % Changed by BW on Nov 12, 2019.  The path was not randomized when
                % called from the pulldown.  This rSeed calls shuffle.
                % Must check with others about this!
                handles.cMosaic.emGenSequence(str2double(str),'rSeed',[]);
                menuEditClearData_Callback(hObject, eventdata, handles);
                set(handles.popupImageType, 'Value', 2); % mean absorptions
                coneMosaicGUIRefresh(app, hObject, eventdata, handles);
            end
        end

        % Menu selected function: menuConesPhotocurrent
        function menuConesPhotocurrent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Cones | Photocurrent) Compute photocurrent
            %
            % Syntax:
            %   menuConePhotocurrent_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Compute the photocurrent
            %
            % Inputs:
            %    hObject   - The handle to menuConePhotocurrent (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.computeCurrent;
            set(handles.popupImageType, 'Value', 4); % mean current
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Menu selected function: menuEditClearData
        function menuEditClearData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Edit | Clear Data) Clear the data
            %
            % Syntax:
            %   menuEditClearData_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Clear the data
            %
            % Inputs:
            %    hObject   - The handle to menuEditClearData (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.clearData();  % Clear absorptions and current
            
            % mosaicImage is the colorful cone mosaic image.
            % We used to save the absorption and current movie.  But no more.  It
            % doesn't seem to save any time.
            uData = get(app.imgMain,'UserData');
            if isfield(uData,'mosaicImage')
                uData.mosaicImage = [];
                set(app.imgMain,'UserData',uData);
            end
            
            guidata(hObject, handles); % Put back the modified handles
            
            coneMosaicGUIRefresh(app, hObject, eventdata, handles); % Update the window
        end

        % Menu selected function: menuEditFontSize
        function menuEditFontSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Edit | Font Size) Modify the font size
            %
            % Syntax:
            %   menuEditFontSize_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Modify the font size
            %
            % Inputs:
            %    hObject   - The handle to menuEditFontSize (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            ieFontSizeSet(handles.coneMosaicWindow);
        end

        % Menu selected function: menuEditName
        function menuEditName_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Edit | Rename) Rename an existing object
            %
            % Syntax:
            %   menuEditName_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Rename an existing object
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            str = ieReadString('New name', handles.cMosaic.name);
            if ~isempty(str), handles.cMosaic.name = str; end
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Menu selected function: menuFileClose
        function menuFileClose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | File) Close the window
            %
            % Syntax:
            %   menuFileClose_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Close the window (delete the handle to the window)
            %
            % Inputs:
            %    hObject   - The handle to menuFileClose (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            delete(app);
        end

        % Menu selected function: menuFileRefresh
        function menuFileRefresh_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | File | Refresh) Refresh button
            %
            % Syntax:
            %   menuFileRefresh_Callback(~, ~, handles)
            %
            % Description:
            %    Refresh button in file menu
            %
            % Inputs:
            %    hObject   - The handle to menuFileRefresh (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            coneMosaicGUIRefresh(app);
        end

        % Menu selected function: menuPlotConeAbsorptance
        function menuPlotConeAbsorptance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Cone Absorptance) Plot cone absorptance
            %
            % Syntax:
            %   menuPlotConeAbsorptance_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the cone absorptance.
            %
            % Inputs:
            %    hObject   - The handle to menuPlotConeAbsorptance (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('cone fundamentals');
        end

        % Menu selected function: menuPlotEMPath
        function menuPlotEMPath_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | EMPath) Plot Eye Movement Path
            %
            % Syntax:
            %   menuPlotEMPath_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the eye movement path.
            %
            % Inputs:
            %    hObject   - The handle to menuPlotEMPath (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('eye movement path');
        end

        % Menu selected function: menuPlotHLineLMS
        function menuPlotHLineLMS_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | H Line LMS Absorptions) Plot H LMS cone absorptions
            %
            % Syntax:
            %   menuPlotHLineLMS_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    From a single point in the cone mosaic window, calculate the L, M, and
            %    S absorptions per frame by cone horizontal positions in a new window
            %    containing a vertical subplot for each cone type.
            %
            % Inputs:
            %    hObject   - The handle to the time series
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            contextMenuPlot(app);
        end

        % Menu selected function: menuPlotHLine
        function menuPlotHLine_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | H Line Response) Plot H Cone responses from a point
            %
            % Syntax:
            %   menuPlotHLine_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    From a single point in the cone mosaic window, calculate the
            %    horizontal cone responses per frame in a new window.
            %
            % Inputs:
            %    hObject   - The handle to the time series
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            contextMenuPlot(app);
        end

        % Menu selected function: menuPlotMacularAbsorbance
        function menuPlotMacularAbsorbance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Macular) Plot the macular absorbance
            %
            % Syntax:
            %   menuPlotEMPath_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the macular absorbance
            %
            % Inputs:
            %    hObject   - The handle to menuPlotMacularAbsorbance (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('macular absorbance');
        end

        % Menu selected function: menuPlotMacularAbsorptance
        function menuPlotMacularAbsorptance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Macular) Plot the macular absorptance
            %
            % Syntax:
            %   menuPlotMacularAbsorptance_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the macular absorptance
            %
            % Inputs:
            %    hObject   - The handle to menuPlotMacularAbsorptance (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('macular absorptance');
        end

        % Menu selected function: menuPlotMacularTransmittance
        function menuPlotMacularTransmittance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Macular) Plot the macular transmittance
            %
            % Syntax:
            %   menuPlotMacularTransmittance_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the macular transmittance
            %
            % Inputs:
            %    hObject   - The handle to menuPlotMacularTransmittance (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('macular transmittance');
        end

        % Menu selected function: menuPlotMosaicConeMosaic
        function menuPlotMosaicConeMosaic_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Mosaic ) Plot the Cone Mosaic from the Mosaic menu
            %
            % Syntax:
            %   menuPlotMosaicConeMosaic_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the cone mosaic from the Mosaic menu
            %
            % Inputs:
            %    hObject   - The handle to menuPlotMosaicConeMosaic (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('cone mosaic', ...
                'showCorrespondingRectangularMosaicInstead', false);
        end

        % Menu selected function: menuPlotMosaicMeanAbsorptions
        function menuPlotMosaicMeanAbsorptions_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Mosaic) Plot the mean absorptions from Mosaic
            %
            % Syntax:
            %   menuPlotMosaicMeanAbsorptions_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the mean absorptions from the Mosaic menu under Plot
            %
            % Inputs:
            %    hObject   - The handle to menuPlotMosaicMeanAbsorptions (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('mean absorptions');
        end

        % Menu selected function: menuPlotMosaicMeanCurrent
        function menuPlotMosaicMeanCurrent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % (Menu | Plot | Mosaic) Plot Mean Current from Mosaic
            %
            % Syntax:
            %   menuPlotMosaicMeanCurrent_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the mean current from the mosaic menu
            %
            % Inputs:
            %    hObject   - The handle to menuPlotMosaicMeanCurrent (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('mean current');
        end

        % Menu selected function: menuPlotTimeSeries
        function menuPlotTimeSeries_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Time Series) A red circle deposited on cone mosaic window.
            %
            % Syntax:
            %	menuPlotTimeSeries_Callback(hObject, ~, handles)
            %
            % Description:
            %    Plot a single point in the window and create a graph of absorptions
            %    per frame over time.
            %
            % Inputs:
            %    hObject - The handle to the time series
            %    handles - Structure with handles and user data
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            % set(handles.btnPlayPause, 'Value', 0);  % Pause the movie
            contextMenuPlot(app);
        end

        % Menu selected function: menuPlotVLineLMS
        function menuPlotVLineLMS_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | V Line LMS Absorptions) Plot V LMS cone absorptions
            %
            % Syntax:
            %   menuPlotVLineLMS_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    From a single point in the cone mosaic window, calculate the L, M, and
            %    S absorptions per frame by cone vertical positions in a new window
            %    containing a vertical subplot for each cone type.
            %
            % Inputs:
            %    hObject   - The handle to the time series
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            contextMenuPlot(app);
        end

        % Menu selected function: menuPlotVLine
        function menuPlotVLine_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | V Line Response) Plot V Cone responses from a point
            %
            % Syntax:
            %   menuPlotVLine_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    From a single point in the cone mosaic window, calculate the vertical
            %    cone responses per frame in a new window.
            %
            % Inputs:
            %    hObject   - The handle to the time series
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            contextMenuPlot(app);
        end

        % Value changed function: popupImageType
        function popupImageType_Callback(app, event)
            %---------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % (Image | Refresh Image) Refresh image object
            %
            % Syntax:
            %   popupImageType_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Refresh the image object
            %
            % Inputs:
            %    hObject   - The handle to popupImageType (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            
            % Stop any movies.
            % set(handles.btnPlayPause, 'Value', 0);  % Turn off the movie.
            
            % Refresh.
            coneMosaicGUIRefresh(app);
        end

        % Close request function: coneMosaicWindow
        function coneMosaicWindowCloseRequest(app, event)
            % delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.coneMosaicWindow = uifigure('Visible', 'off');
            colormap(app.coneMosaicWindow, 'parula');
            app.coneMosaicWindow.Name = 'Cone Mosaic';
            app.coneMosaicWindow.CloseRequestFcn = createCallbackFcn(app, @coneMosaicWindowCloseRequest, true);
            app.coneMosaicWindow.Tag = 'coneMosaicWindow';
            app.menuFile = uimenu(app.coneMosaicWindow);
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuFileRefresh = uimenu(app.menuFile);
            app.menuFileRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileRefresh.Text = 'Refresh';
            app.menuFileRefresh.Tag = 'menuFileRefresh';
            app.menuFileClose = uimenu(app.menuFile);
            app.menuFileClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.menuFileClose.Text = 'Close';
            app.menuFileClose.Tag = 'menuFileClose';
            app.menuEdit = uimenu(app.coneMosaicWindow);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuEditName = uimenu(app.menuEdit);
            app.menuEditName.MenuSelectedFcn = createCallbackFcn(app, @menuEditName_Callback, true);
            app.menuEditName.Text = 'Rename';
            app.menuEditName.Tag = 'menuEditName';
            app.menuEditClearData = uimenu(app.menuEdit);
            app.menuEditClearData.MenuSelectedFcn = createCallbackFcn(app, @menuEditClearData_Callback, true);
            app.menuEditClearData.Text = 'Clear data';
            app.menuEditClearData.Tag = 'menuEditClearData';
            app.menuEditFontSize = uimenu(app.menuEdit);
            app.menuEditFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditFontSize_Callback, true);
            app.menuEditFontSize.Text = 'Change font size';
            app.menuEditFontSize.Tag = 'menuEditFontSize';
            app.menuPlot = uimenu(app.coneMosaicWindow);
            app.menuPlot.Text = 'Plot';
            app.menuPlot.Tag = 'menuPlot';
            app.menuPlotMacular = uimenu(app.menuPlot);
            app.menuPlotMacular.Text = 'Macular';
            app.menuPlotMacular.Tag = 'menuPlotMacular';
            app.menuPlotMacularTransmittance = uimenu(app.menuPlotMacular);
            app.menuPlotMacularTransmittance.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMacularTransmittance_Callback, true);
            app.menuPlotMacularTransmittance.Text = 'Transmittance';
            app.menuPlotMacularTransmittance.Tag = 'menuPlotMacularTransmittance';
            app.menuPlotMacularAbsorptance = uimenu(app.menuPlotMacular);
            app.menuPlotMacularAbsorptance.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMacularAbsorptance_Callback, true);
            app.menuPlotMacularAbsorptance.Text = 'Absorptance';
            app.menuPlotMacularAbsorptance.Tag = 'menuPlotMacularAbsorptance';
            app.menuPlotMacularAbsorbance = uimenu(app.menuPlotMacular);
            app.menuPlotMacularAbsorbance.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMacularAbsorbance_Callback, true);
            app.menuPlotMacularAbsorbance.Text = 'Absorbance';
            app.menuPlotMacularAbsorbance.Tag = 'menuPlotMacularAbsorbance';
            app.menuPlotConeAbsorptance = uimenu(app.menuPlot);
            app.menuPlotConeAbsorptance.MenuSelectedFcn = createCallbackFcn(app, @menuPlotConeAbsorptance_Callback, true);
            app.menuPlotConeAbsorptance.Text = 'Cone absorptance';
            app.menuPlotConeAbsorptance.Tag = 'menuPlotConeAbsorptance';
            app.menuPlotMosaic = uimenu(app.menuPlot);
            app.menuPlotMosaic.Text = 'Mosaic';
            app.menuPlotMosaic.Tag = 'menuPlotMosaic';
            app.menuPlotMosaicConeMosaic = uimenu(app.menuPlotMosaic);
            app.menuPlotMosaicConeMosaic.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMosaicConeMosaic_Callback, true);
            app.menuPlotMosaicConeMosaic.Text = 'Cone mosaic';
            app.menuPlotMosaicConeMosaic.Tag = 'menuPlotMosaicConeMosaic';
            app.menuPlotMosaicMeanAbsorptions = uimenu(app.menuPlotMosaic);
            app.menuPlotMosaicMeanAbsorptions.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMosaicMeanAbsorptions_Callback, true);
            app.menuPlotMosaicMeanAbsorptions.Text = 'Mean absorptions';
            app.menuPlotMosaicMeanAbsorptions.Tag = 'menuPlotMosaicMeanAbsorptions';
            app.menuPlotMosaicMeanCurrent = uimenu(app.menuPlotMosaic);
            app.menuPlotMosaicMeanCurrent.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMosaicMeanCurrent_Callback, true);
            app.menuPlotMosaicMeanCurrent.Text = 'Mean current';
            app.menuPlotMosaicMeanCurrent.Tag = 'menuPlotMosaicMeanCurrent';
            app.menuPlotEMPath = uimenu(app.menuPlot);
            app.menuPlotEMPath.MenuSelectedFcn = createCallbackFcn(app, @menuPlotEMPath_Callback, true);
            app.menuPlotEMPath.Text = 'Eye movement path';
            app.menuPlotEMPath.Tag = 'menuPlotEMPath';
            app.menuPlotHLine = uimenu(app.menuPlot);
            app.menuPlotHLine.MenuSelectedFcn = createCallbackFcn(app, @menuPlotHLine_Callback, true);
            app.menuPlotHLine.Text = 'hLine response';
            app.menuPlotHLine.Tag = 'menuPlotHLine';
            app.menuPlotVLine = uimenu(app.menuPlot);
            app.menuPlotVLine.MenuSelectedFcn = createCallbackFcn(app, @menuPlotVLine_Callback, true);
            app.menuPlotVLine.Text = 'vLine response';
            app.menuPlotVLine.Tag = 'menuPlotVLine';
            app.menuPlotHLineLMS = uimenu(app.menuPlot);
            app.menuPlotHLineLMS.MenuSelectedFcn = createCallbackFcn(app, @menuPlotHLineLMS_Callback, true);
            app.menuPlotHLineLMS.Text = 'hLine LMS';
            app.menuPlotHLineLMS.Tag = 'menuPlotHLineLMS';
            app.menuPlotVLineLMS = uimenu(app.menuPlot);
            app.menuPlotVLineLMS.MenuSelectedFcn = createCallbackFcn(app, @menuPlotVLineLMS_Callback, true);
            app.menuPlotVLineLMS.Text = 'vLine LMS';
            app.menuPlotVLineLMS.Tag = 'menuPlotVLineLMS';
            app.menuPlotTimeSeries = uimenu(app.menuPlot);
            app.menuPlotTimeSeries.MenuSelectedFcn = createCallbackFcn(app, @menuPlotTimeSeries_Callback, true);
            app.menuPlotTimeSeries.Text = 'time series';
            app.menuPlotTimeSeries.Tag = 'menuPlotTimeSeries';
            app.menuCones = uimenu(app.coneMosaicWindow);
            app.menuCones.Text = 'Cones';
            app.menuCones.Tag = 'menuCones';
            app.menuConesGenerateEM = uimenu(app.menuCones);
            app.menuConesGenerateEM.MenuSelectedFcn = createCallbackFcn(app, @menuConesGenerateEM_Callback, true);
            app.menuConesGenerateEM.Text = 'Generate eye movement';
            app.menuConesGenerateEM.Tag = 'menuConesGenerateEM';
            app.menuConesAbsorptions = uimenu(app.menuCones);
            app.menuConesAbsorptions.MenuSelectedFcn = createCallbackFcn(app, @menuConesAbsorptions_Callback, true);
            app.menuConesAbsorptions.Text = 'Compute absorptions';
            app.menuConesAbsorptions.Tag = 'menuConesAbsorptions';
            app.menuConesPhotocurrent = uimenu(app.menuCones);
            app.menuConesPhotocurrent.MenuSelectedFcn = createCallbackFcn(app, @menuConesPhotocurrent_Callback, true);
            app.menuConesPhotocurrent.Text = 'Compute photocurrent';
            app.menuConesPhotocurrent.Tag = 'menuConesPhotocurrent';
            app.menuConePhotocurrentNoise = uimenu(app.menuCones);
            app.menuConePhotocurrentNoise.MenuSelectedFcn = createCallbackFcn(app, @menuConePhotocurrentNoise_Callback, true);
            app.menuConePhotocurrentNoise.Text = 'Toggle photocurrent noise';
            app.menuConePhotocurrentNoise.Tag = 'menuConePhotocurrentNoise';
            app.menuHelp = uimenu(app.coneMosaicWindow);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuAppNotes = uimenu(app.menuHelp);
            app.menuAppNotes.MenuSelectedFcn = createCallbackFcn(app, @menuAppNotes_Callback, true);
            app.menuAppNotes.Text = 'Documentation (web)';
            app.menuAppNotes.Tag = 'menuAppNotes';
            app.imgMain = uiaxes(app.coneMosaicWindow);
            app.imgMain.XLim = [0 1];
            app.imgMain.YLim = [0 1];
            app.imgMain.ZLim = [0 1];
            app.imgMain.CLim = [0 1];
            app.imgMain.ALim = [0 1];
            app.imgMain.XTickLabel = '';
            app.imgMain.YTick = zeros(1,0);
            app.imgMain.YTickLabel = '';
            app.imgMain.ZTick = [0 0.5 1];
            app.imgMain.ZTickLabel = '';
            app.imgMain.Tag = 'axes2';
            app.btnComputeImage = uibutton(app.coneMosaicWindow, 'push');
            app.btnComputeImage.ButtonPushedFcn = createCallbackFcn(app, @btnComputeImage_Callback, true);
            app.btnComputeImage.Tag = 'btnComputeImage';
            app.btnComputeImage.Tooltip = 'Compute cone absorptions';
            app.btnComputeImage.Text = 'Compute cone absorptions';
            app.editRows = uieditfield(app.coneMosaicWindow, 'text');
            app.editRows.ValueChangedFcn = createCallbackFcn(app, @editRows_Callback, true);
            app.editRows.Tag = 'editRows';
            app.editRows.Tooltip = 'set cone mosaic rows';
            app.editRows.Value = '72';
            app.editCols = uieditfield(app.coneMosaicWindow, 'text');
            app.editCols.ValueChangedFcn = createCallbackFcn(app, @editCols_Callback, true);
            app.editCols.Tag = 'editCols';
            app.editCols.Tooltip = 'set cone mosaic cols';
            app.editCols.Value = '88';
            app.txtMosaicSize = uilabel(app.coneMosaicWindow);
            app.txtMosaicSize.Tag = 'txtMosaicSize';
            app.txtMosaicSize.Tooltip = 'Adjust the number of pixels in the ISA';
            app.txtMosaicSize.Text = 'Row Col';
            app.txtMessage = uilabel(app.coneMosaicWindow);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.editExpTime = uieditfield(app.coneMosaicWindow, 'text');
            app.editExpTime.ValueChangedFcn = createCallbackFcn(app, @editExpTime_Callback, true);
            app.editExpTime.Tag = 'editExpTime';
            app.editExpTime.Tooltip = 'Set (central) exposure time ';
            app.editExpTime.Value = '0';
            app.txtExposureUnits = uilabel(app.coneMosaicWindow);
            app.txtExposureUnits.Tag = 'txtExposureUnits';
            app.txtExposureUnits.Text = '(ms)';
            app.txtIntegration = uilabel(app.coneMosaicWindow);
            app.txtIntegration.Tag = 'txtIntegration';
            app.txtIntegration.Text = 'Integration';
            app.popupImageType = uidropdown(app.coneMosaicWindow);
            app.popupImageType.Items = {'Mean absorptions', 'Cone mosaic'};
            app.popupImageType.ValueChangedFcn = createCallbackFcn(app, @popupImageType_Callback, true);
            app.popupImageType.Tag = 'popupImageType';
            app.popupImageType.Value = 'Mean absorptions';
            app.txtConeSIze = uilabel(app.coneMosaicWindow);
            app.txtConeSIze.Tag = 'text46';
            app.txtConeSIze.Text = 'Cone size (um)';
            app.editConeWidth = uieditfield(app.coneMosaicWindow, 'text');
            app.editConeWidth.ValueChangedFcn = createCallbackFcn(app, @editConeWidth_Callback, true);
            app.editConeWidth.Tag = 'editConeWidth';
            app.editConeWidth.Tooltip = 'set cone photodetector width';
            app.editConeWidth.Value = '2';
            app.editConeHeight = uieditfield(app.coneMosaicWindow, 'text');
            app.editConeHeight.ValueChangedFcn = createCallbackFcn(app, @editConeHeight_Callback, true);
            app.editConeHeight.Tag = 'editConeHeight';
            app.editConeHeight.Tooltip = 'cone photodector height';
            app.editConeHeight.Value = '2';
            app.textConePigmentDensity = uilabel(app.coneMosaicWindow);
            app.textConePigmentDensity.Tag = 'textConePigmentDensity';
            app.textConePigmentDensity.Text = 'Pigment density';
            app.editConeOpticalDensity = uieditfield(app.coneMosaicWindow, 'text');
            app.editConeOpticalDensity.ValueChangedFcn = createCallbackFcn(app, @editConeOpticalDensity_Callback, true);
            app.editConeOpticalDensity.Tag = 'editConeOpticalDensity';
            app.textConePeakEfficiency = uilabel(app.coneMosaicWindow);
            app.textConePeakEfficiency.Tag = 'textConePeakEfficiency';
            app.textConePeakEfficiency.Text = 'Peak';
            app.editConePeakEfficiency = uieditfield(app.coneMosaicWindow, 'text');
            app.editConePeakEfficiency.ValueChangedFcn = createCallbackFcn(app, @editConePeakEfficiency_Callback, true);
            app.editConePeakEfficiency.Tag = 'editConePeakEfficiency';
            app.textMacularDensity = uilabel(app.coneMosaicWindow);
            app.textMacularDensity.Tag = 'textMacularDensity';
            app.textMacularDensity.Text = 'Macular density';
            app.editMacularDensity = uieditfield(app.coneMosaicWindow, 'text');
            app.editMacularDensity.ValueChangedFcn = createCallbackFcn(app, @editMacularDensity_Callback, true);
            app.editMacularDensity.Tag = 'editMacularDensity';
            app.editGam = uieditfield(app.coneMosaicWindow, 'text');
            app.editGam.ValueChangedFcn = createCallbackFcn(app, @editGam_Callback, true);
            app.editGam.Tag = 'editGam';
            app.editGam.Tooltip = 'Set display gamma - data are not affected';
            app.editGam.Value = '1';
            app.txtDisplayGamma = uilabel(app.coneMosaicWindow);
            app.txtDisplayGamma.Tag = 'txtDisplayGamma';
            app.txtDisplayGamma.Tooltip = 'Set rendering gamma for this display, no data effect';
            app.txtDisplayGamma.Text = 'Gamma';
            app.txtEccentricity = uilabel(app.coneMosaicWindow);
            app.txtEccentricity.Tag = 'txtEccentricity';
            app.txtEccentricity.Tooltip = 'Deg of angle re: fovea';
            app.txtEccentricity.Text = 'Eccentricity';
            app.txtKLMSRatio = uilabel(app.coneMosaicWindow);
            app.txtKLMSRatio.Tag = 'txtKLMSRatio';
            app.txtKLMSRatio.Text = 'Blank-LMS';
            app.editKLMS = uieditfield(app.coneMosaicWindow, 'text');
            app.editKLMS.ValueChangedFcn = createCallbackFcn(app, @editKLMS_Callback, true);
            app.editKLMS.Tag = 'editKLMS';
            app.editKLMS.Value = '[.1 .5 .3 .1]';
            app.editEccentricity = uieditfield(app.coneMosaicWindow, 'text');
            app.editEccentricity.Tag = 'editEccentricity';
            app.editEccentricity.Value = '0';
            app.txtMosaic = uilabel(app.coneMosaicWindow);
            app.txtMosaic.Tag = 'txtMosaic';
            app.txtMosaic.Text = 'Description';
            app.txtOS = uilabel(app.coneMosaicWindow);
            app.txtOS.Tag = 'txtOS';
            app.txtOS.Text = 'Outer segment';
            app.mosaicName = uilabel(app.coneMosaicWindow);
            app.mosaicName.Tag = 'mosaicName';
            app.mosaicName.Text = 'Mosaic name';
            % Show the figure after all components are created
            app.coneMosaicWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coneRectWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.coneMosaicWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)coneMosaicWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.coneMosaicWindow)
        end
    end
end